classdef BigNumberDisplay < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout        matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        UnitsLabel        matlab.ui.control.Label
        DisplayEditField  matlab.ui.control.NumericEditField
        TitleLabel        matlab.ui.control.Label
    end

    properties (Access = public)
        IndexToShow (1,1) double {mustBeInteger(IndexToShow)} = 1;
    end
    
   
    methods (Access = public)
        

        %% SetTitle
        function SetTitle(comp, title)
            arguments
                comp;
                title {mustBeText};
            end
            
            comp.TitleLabel.Text = title;
        end

        %% SetUnits
        function SetUnits(comp, unitsStr)
            arguments
                comp;
                unitsStr {mustBeText};
            end
            
            comp.UnitsLabel.Text = unitsStr;
        end

        %% UpdateValue
        function UpdateValue(comp, value)
            arguments
                comp;
                value (1,:) double;
            end

            h = comp.DisplayEditField.Position(4);
            fontsize(comp.DisplayEditField, h, "Pixels");
            comp.DisplayEditField.Value = value(comp.IndexToShow);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
           
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 431 225];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.TitleLabel = uilabel(comp.GridLayout);
            comp.TitleLabel.Text = 'Title';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.DisplayEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.DisplayEditField.Editable = 'off';
            comp.UnitsLabel = uilabel(comp.GridLayout2);
            comp.UnitsLabel.Interpreter = 'latex';
            comp.UnitsLabel.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end