classdef MFLISweepControlPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout_StatusInfo         matlab.ui.container.GridLayout
        GridLayout_CommandButtons     matlab.ui.container.GridLayout
        StopButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        SingleButton                  matlab.ui.control.Button
        GridLayout_ParameterValues    matlab.ui.container.GridLayout
        FileNameSuffixEditField       matlab.ui.control.EditField
        FileNameSuffixEditFieldLabel  matlab.ui.control.Label
        SetRampRateButton             matlab.ui.control.Button
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        SetSetPointButton             matlab.ui.control.Button
        xxEditField                   matlab.ui.control.NumericEditField
        xxEditFieldLabel              matlab.ui.control.Label
        MFLISweepControlLabel         matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        RunSingleSweep
    end

    
    methods (Access = private)
        
        %% OnRunSingleSweepButtonPushed
        function OnRunSingleSweepButtonPushed(comp)
            notify(comp, "RunSingleSweep");
        end

    end
    
    methods (Access = public)
        
        %% SetName
        function SetName(comp, nameString)
            comp.MFLISweepControlLabel.Text = nameString;
        end

        %% UpdateDisplayedStatus
        function UpdateDisplayedStatus(comp, statusStruct)
            try
                comp.Display_CurrentAEditField.Value = statusStruct.Current_A;
            catch err
                comp.Display_CurrentAEditField.Value = [];
            end

            try
                comp.Display_FieldTEditField.Value = statusStruct.Field_T;
            catch err
                comp.Display_FieldTEditField.Value = [];
            end

            try
                comp.Display_RampRateTminEditField.Value = statusStruct.RampRate_Tmin;
            catch err
                comp.Display_RampRateTminEditField.Value = [];
            end
            try
                comp.Display_SetpointTEditField.Value = statusStruct.SetPoint_T;
            catch err
                comp.Display_SetpointTEditField.Value = [];
            end
            try
                comp.Display_StatusEditField.Value = string(statusStruct.StatusString);
            catch err
                comp.Display_StatusEditField.Value = [];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SingleButton
        function SingleButtonPushed(comp, event)
            comp.OnRunSingleSweepButtonPushed();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            comp.OnRampToZeroButtonPushed();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(comp, event)
            comp.OnHoldButtonPushed();
        end

        % Button pushed function: SetRampRateButton
        function SetRampRateButtonPushed(comp, event)
            rampRamp_Tmin = comp.xEditField.Value;
            comp.OnSetRampRateButtonPushed(rampRamp_Tmin);
        end

        % Button pushed function: SetSetPointButton
        function SetSetPointButtonPushed(comp, event)
            setPoint_T = comp.xxEditField.Value;
            comp.OnSetSetPointButtonPushed(setPoint_T);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 509 425];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.MFLISweepControlLabel = uilabel(comp.GridLayout);
            comp.MFLISweepControlLabel.Text = 'MFLI Sweep Control';
            comp.GridLayout_ParameterValues = uigridlayout(comp.GridLayout);
            comp.xxEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.xxEditFieldLabel.Text = 'xx';
            comp.xxEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.xxEditField.Limits = [-6 6];
            comp.SetSetPointButton = uibutton(comp.GridLayout_ParameterValues, 'push');
            comp.SetSetPointButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetSetPointButtonPushed, true);
            comp.SetSetPointButton.Text = 'Set';
            comp.xEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.xEditFieldLabel.Text = 'x';
            comp.xEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.xEditField.Limits = [0 10];
            comp.xEditField.Value = 0.3;
            comp.SetRampRateButton = uibutton(comp.GridLayout_ParameterValues, 'push');
            comp.SetRampRateButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetRampRateButtonPushed, true);
            comp.SetRampRateButton.Text = 'Set';
            comp.FileNameSuffixEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.FileNameSuffixEditFieldLabel.Text = 'FileName Suffix';
            comp.FileNameSuffixEditField = uieditfield(comp.GridLayout_ParameterValues, 'text');
            comp.FileNameSuffixEditField.Value = 'MFLI_Sweep';
            comp.GridLayout_CommandButtons = uigridlayout(comp.GridLayout);
            comp.SingleButton = uibutton(comp.GridLayout_CommandButtons, 'push');
            comp.SingleButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SingleButtonPushed, true);
            comp.SingleButton.Text = 'Single';
            comp.RunButton = uibutton(comp.GridLayout_CommandButtons, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Text = 'Run';
            comp.StopButton = uibutton(comp.GridLayout_CommandButtons, 'push');
            comp.StopButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StopButtonPushed, true);
            comp.StopButton.Text = 'Stop';
            comp.GridLayout_StatusInfo = uigridlayout(comp.GridLayout);
        end
    end
end