classdef MiSTChannelDisplayControl < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        Lamp                        matlab.ui.control.Lamp
        ChxLabel                    matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        VoltageSaturationEditField  matlab.ui.control.NumericEditField
        VoltageSaturationEditFieldLabel  matlab.ui.control.Label
        Gauge                       matlab.ui.control.LinearGauge
        GainEditField               matlab.ui.control.NumericEditField
        SetGainButton               matlab.ui.control.Button
        GainEditFieldLabel          matlab.ui.control.Label
        SetCurrentButton            matlab.ui.control.Button
        CurrentuAEditField          matlab.ui.control.NumericEditField
        CurrentuAEditFieldLabel     matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        CurrentValueChanged
        GainValueChanged
    end

    properties (Access = public)
        ChannelIndex (1,1) double {mustBeInteger(ChannelIndex)} = 0;
    end
    
    
    methods (Access = public)
        
        function SetCurrent(comp, current)
            comp.CurrentuAEditField.Value = current;
        end

        function SetGain(comp, gain)
            comp.GainEditField.Value = gain;
        end

        function SetTitle(comp, title)
            comp.ChxLabel.Text = title;
        end
        
        function UpdateEnabledStatus(comp, enabled)
            if enabled
                comp.Lamp.Enable = "on";
                comp.Gauge.Visible = "on";
                comp.VoltageSaturationEditField.Visible = "on";
                comp.VoltageSaturationEditFieldLabel.Visible = "on";
                comp.GainEditField.Visible = "on";
                comp.GainEditFieldLabel.Visible = "on";
                comp.SetGainButton.Visible = "on";
                comp.CurrentuAEditField.Visible = "on";
                comp.CurrentuAEditFieldLabel.Visible = "on";
                comp.SetCurrentButton.Visible = "on";
            else
                comp.Lamp.Enable = "off";
                comp.Gauge.Visible = "off";
                comp.VoltageSaturationEditField.Visible = "off";
                comp.VoltageSaturationEditFieldLabel.Visible = "off";
                comp.GainEditField.Visible = "off";
                comp.GainEditFieldLabel.Visible = "off";
                comp.SetGainButton.Visible = "off";
                comp.CurrentuAEditField.Visible = "off";
                comp.CurrentuAEditFieldLabel.Visible = "off";
                comp.SetCurrentButton.Visible = "off";
            end
        end

        function UpdateSaturationPercent(comp, percentVal)
            if isnan(percentVal)
                comp.VoltageSaturationEditField.Value = [];
            else
                comp.VoltageSaturationEditField.Value = percentVal;
                comp.Gauge.Value = percentVal;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetCurrentButton
        function SetCurrentButtonPushed(comp, event)
            current_uA = comp.CurrentuAEditField.Value;
            eventArgs = CoakView.Events.ValueChangedEventData([comp.ChannelIndex, current_uA]);
            notify(comp, "CurrentValueChanged", eventArgs); 
        end

        % Button pushed function: SetGainButton
        function SetGainButtonPushed(comp, event)
            gain = comp.GainEditField.Value;
            eventArgs = CoakView.Events.ValueChangedEventData([comp.ChannelIndex, gain]);
            notify(comp, "GainValueChanged", eventArgs);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 282 449];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.CurrentuAEditFieldLabel = uilabel(comp.GridLayout2);
            comp.CurrentuAEditFieldLabel.Text = 'Current (uA)';
            comp.CurrentuAEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.CurrentuAEditField.Limits = [0 100000];
            comp.CurrentuAEditField.RoundFractionalValues = 'on';
            comp.SetCurrentButton = uibutton(comp.GridLayout2, 'push');
            comp.SetCurrentButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetCurrentButtonPushed, true);
            comp.SetCurrentButton.Text = 'Set';
            comp.GainEditFieldLabel = uilabel(comp.GridLayout2);
            comp.GainEditFieldLabel.Text = 'Gain';
            comp.SetGainButton = uibutton(comp.GridLayout2, 'push');
            comp.SetGainButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetGainButtonPushed, true);
            comp.SetGainButton.Text = 'Set';
            comp.GainEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.GainEditField.Limits = [0 100000];
            comp.GainEditField.RoundFractionalValues = 'on';
            comp.Gauge = uigauge(comp.GridLayout2, 'linear');
            comp.VoltageSaturationEditFieldLabel = uilabel(comp.GridLayout2);
            comp.VoltageSaturationEditFieldLabel.Text = 'Voltage Saturation  %';
            comp.VoltageSaturationEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.VoltageSaturationEditField.AllowEmpty = 'on';
            comp.VoltageSaturationEditField.Editable = 'off';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.ChxLabel = uilabel(comp.GridLayout3);
            comp.ChxLabel.Text = 'Ch x';
            comp.Lamp = uilamp(comp.GridLayout3);
        end
    end
end