classdef SweepSetupControl < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout_RunButtonCentring  matlab.ui.container.GridLayout
        RunButton                     matlab.ui.control.Button
        SweepSetupLabel               matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        SettleTimesEditField          matlab.ui.control.NumericEditField
        SettleTimesEditFieldLabel     matlab.ui.control.Label
        UnitsLabel5                   matlab.ui.control.Label
        SteptimesEditField            matlab.ui.control.NumericEditField
        SteptimesEditFieldLabel       matlab.ui.control.Label
        StepSizeEditField             matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel        matlab.ui.control.Label
        NoofStepsEditField            matlab.ui.control.NumericEditField
        NoofStepsEditFieldLabel       matlab.ui.control.Label
        Ratemin1EditField             matlab.ui.control.NumericEditField
        Ratemin1Label                 matlab.ui.control.Label
        GridLayout7                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        RemainingTimeEditField        matlab.ui.control.EditField
        UnitsLabel4                   matlab.ui.control.Label
        CurrentValueLabel             matlab.ui.control.Label
        CurrentValueEditField         matlab.ui.control.NumericEditField
        ListBox                       matlab.ui.control.ListBox
        GridLayout2                   matlab.ui.container.GridLayout
        UnitsLabel3                   matlab.ui.control.Label
        UnitsLabel2                   matlab.ui.control.Label
        UnitsLabel1                   matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MinEditFieldLabel             matlab.ui.control.Label
        MidValueEditField             matlab.ui.control.NumericEditField
        MidValueEditFieldLabel        matlab.ui.control.Label
        MaxEditField                  matlab.ui.control.NumericEditField
        MaxEditFieldLabel             matlab.ui.control.Label
        SweepSectionsControl          CoakView.Instruments.Controls.SweepSectionsControl
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        Abort
        Run
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        CurrentStep double {mustBeInteger(CurrentStep)} = 1;
        MaxVal (1,1) double = 1;
        MidVal (1,1) double = 0;
        MinVal (1,1) double = -1;
        RatePerMin (1,1) double {mustBePositive(RatePerMin)} = 0.1;
        TotalSteps (1,1) double {mustBeInteger(TotalSteps)} = 1;
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        SettleTime (1,1) double = 0.5; % Settle time after changing a value but before measuring, in seconds. Up to instrument implementations how to actually use this.
    end
    
    properties (Access = private)
        EndSectionNo = 4;
        StartSectionNo = 0;
        UnitsStr (1,1) string = "";
    end
    
    properties (Access = public)
        RemainingTimeMin (1,1) double = 0;
        SweepData = [];
        TotalTimeMin (1,1) double = 0;
    end
    

    methods (Access = public)
       
        %% SetStartingValues
        function SetStartingValues(comp, min, mid, max)
            comp.MinEditField.Value = min;
            comp.MidValueEditField.Value = mid;
            comp.MaxEditField.Value = max;
            comp.MinVal = min;
            comp.MidVal = mid;
            comp.MaxVal = max;
        end

        %% SetDefaultRatePerMin
        function SetDefaultRatePerMin(comp, rate)
            comp.Ratemin1EditField.Value = rate;
            comp.RatePerMin = rate;
        end
        
        %% SetLimits
        function SetLimits(comp, min, max)
            comp.MinEditField.Limits = [min max];
            comp.MidValueEditField.Limits = [min max];
            comp.MaxEditField.Limits = [min max];
        end

        %% SetTitle
        function SetTitle(comp, title)
            comp.SweepSetupLabel.Text = title;
        end

        %% SetUnitsString
        function SetUnitsString(comp, unitsStr)
            comp.UnitsLabel1.Text = unitsStr;
            comp.UnitsLabel2.Text = unitsStr;
            comp.UnitsLabel3.Text = unitsStr;
            comp.UnitsLabel4.Text = unitsStr;
            comp.UnitsLabel5.Text = unitsStr;
            comp.UnitsStr = unitsStr;
        end

        %% SetUpdateTime
        function SetUpdateTime(comp, time_s)
            comp.SteptimesEditField.Value = time_s;
        end

        %% StepComplete
        function StepComplete(comp, currentValue)
            comp.CurrentStep = comp.CurrentStep + 1;
            comp.CurrentValueEditField.Value = currentValue;
            comp.UpdateTimeRemainingDisplay();
        end

        %% SweepComplete
        function SweepComplete(comp)
            comp.CurrentStep = comp.TotalSteps;
            %Call this from external code once the sweep is complete and we are ready to run
            %once again
            comp.SetReady();
        end

        %% OnAbortButtonPushed
        function OnAbortButtonPushed(comp)

            %Return to state ready to Run again
            comp.SetReady();
            
            %Trigger event
            eventData = CoakView.Instruments.Events.SweepSetupEventData([]);
            notify(comp, 'Abort', eventData);
        end

        %% OnRunButtonPushed
        function OnRunButtonPushed(comp)
            %Make sure we have all the right up-to-date numbers in the
            %SweepData struct
            comp.Calculate();

            %Reset and initialise some values
            comp.CurrentStep = 1;
            
            %Turn Run button into Abort button
            comp.SetRunning();

            %Trigger event
            eventData = CoakView.Instruments.Events.SweepSetupEventData(comp.SweepData);
            notify(comp, 'Run', eventData);

        end
    end

    methods (Access = private)

        %% Calculate
        function sweepData = Calculate(comp)

            dt = comp.SteptimesEditField.Value;

            targetPts = comp.CalculateExtremalPoints();


            %Calculate the total amount the value must change over during
            %this sweep
            totalMag = comp.CalculateTotalMagnitude();
            
            %Calculate how long this will take
            timeMin = totalMag / comp.RatePerMin;
            duration = minutes(timeMin);
            s = string(duration,'hh:mm:ss');
            comp.TotalTimeMin = timeMin;

            %Calculate the number of steps
            comp.TotalSteps = comp.NoofStepsEditField.Value;
            stepSize = comp.StepSizeEditField.Value;

            %Assign sweep information into the SweepData struct that
            %encodes all an instrument will need to know about this sweep
            extremalPoints = comp.TrimExtremalPoints(targetPts);

            if(isempty(extremalPoints) || length(extremalPoints) < 2)
                sweepData = [];
                return;
            end

            sweepData.ExtremalPoints = extremalPoints;
            sweepData.Points = comp.CalculatePoints(sweepData.ExtremalPoints, stepSize);
            sweepData.RampRatePerMin = comp.Ratemin1EditField.Value;
            sweepData.PauseTimeS = comp.SteptimesEditField.Value;
            sweepData.SettleTimeS = comp.SettleTime;
        end

        %% CalculateExtremalPoints
        function targetPts = CalculateExtremalPoints(comp)
            %Calculate the extremal key points the sweep should hit, based
            %on the selected sectors
            targetPts = [];
            for i = 0 : 6
                if(i >= comp.StartSectionNo && i <= comp.EndSectionNo)
                    switch(i)
                        case(0)
                            targetPts = [targetPts, comp.MidVal];
                        case(1)
                            targetPts = [targetPts, comp.MaxVal];
                        case(2)
                            targetPts = [targetPts, comp.MidVal];
                        case(3)
                            targetPts = [targetPts, comp.MinVal];
                        case(4)
                            targetPts = [targetPts, comp.MidVal];
                        case(5)
                            targetPts = [targetPts, comp.MaxVal];
                        case(6)
                            targetPts = [targetPts, comp.MidVal];
                    end
                end
            end
        end

        %% CalculateTotalMagnitude
        function totalMag = CalculateTotalMagnitude(comp)
            targetPts = comp.CalculateExtremalPoints();
            
            totalMag = 0;
            for i = 1 : length(targetPts) - 1
                totalMag = totalMag + abs(targetPts(i + 1) - targetPts(i));
            end
        end

        %% CalculatePoints
        function points = CalculatePoints(comp, extremalPts, stepSize)
            %Calculate the array of points to generate for this sweep,
            %given the max/min extremal points and the step size
            points = [];

            if length(extremalPts) < 2
                return
            end

            for i = 1 : length(extremalPts) - 1
                noOfSteps = max(2, round(abs(extremalPts(i) - extremalPts(i+1)) / stepSize) + 1);                
                points = [points, comp.GenerateSweepPoints(extremalPts(i), extremalPts(i+1), 0, noOfSteps)];

                %Remove last point if this is not the final iteration, to
                %avoid duplicates
                if(i < length(extremalPts) - 1)
                    points(end) = [];
                end
            end

        end

        %% GenerateSweepPoints
        function [points, numberOfPoints] = GenerateSweepPoints(~, startPt, endPt, step, numberOfSteps)
            %Helper function to generate an array of points between Start
            %and End values. If numberOfSteps is null, the points will be
            %spaced by Step (Step does not need to have its sign changed to
            %match the sign of end-start, that will be taken care of). If
            %numberOfSteps is not null, that will be used instead, via
            %'linspace'.
                                    
            if(isempty(numberOfSteps)) 
                %Error checking and validation
                if(abs(step) > abs(startPt - endPt))
                    warning('Step is larger than difference between start and end points in MathsUtils.GenerateSweepPoints. Only the start point will be returned.');
                end
                
                if(step == 0)
                    error('Step is set to zero when generating sweep points - this would generate an infinite number of points..');
                end
                
                %Ensure step has the correct sign
                step = abs(step);
                if(endPt < startPt)
                    step = -step;
                end
                
                %Generate points spaced by step. May not neccessarily end
                %neatly on endPt - depends on user's choice of step
                points = startPt : step : endPt;
            else %else, set number of steps
                
                %Generate numberOfSteps points evenly spaced between start
                %and end. Will include the exact start and end point, but
                %may be 0.3333333333333 or so on in between.
                points = linspace(startPt, endPt, numberOfSteps);
            end
            
            numberOfPoints = length(points);
        end

        %% TrimExtremalPoints
        function points = TrimExtremalPoints(comp, extremalPts)
            %Remove any duplicate extremal points, and any unneeded ones -
            %ie if going from +1 to 0 to -1, we can safely remove the zero
            %there
            if isempty(extremalPts)
                points = [];
                return;
            end

            if length(extremalPts) < 3
                points = extremalPts;
                return;
            end

            %Remove any duplicates
            for i = length(extremalPts) : -1 : 2
                if(extremalPts(i) == extremalPts(i-1))
                    extremalPts(i) = [];
                end
            end

            %Remove any intermediate values
             for i = length(extremalPts) - 1 : -1 : 2
                if(extremalPts(i + 1) > extremalPts(i) && extremalPts(i) > extremalPts(i-1))
                    extremalPts(i) = [];
                elseif(extremalPts(i + 1) < extremalPts(i) && extremalPts(i) < extremalPts(i-1))
                    extremalPts(i) = [];
                end
             end

             points = extremalPts;
        end

        %% SetReady
        function SetReady(comp)
            comp.RemainingTimeEditField.Value = "--:--:--";
            comp.RunButton.Text = "Run";
            comp.RunButton.BackgroundColor = [0.89,0.94,0.80];

            comp.MaxEditField.Enable = "on";
            comp.MinEditField.Enable = "on";
            comp.MidValueEditField.Enable = "on";
            comp.StepSizeEditField.Enable = "on";
            comp.NoofStepsEditField.Enable = "on";
            comp.SettleTimesEditField.Enable = "on";

            comp.SweepSectionsControl.Enable = "on";

            comp.Ratemin1EditField.Enable = "on";
        end

        %% SetRunning
        function SetRunning(comp)

            comp.RunButton.Text = "Abort";
            comp.RunButton.BackgroundColor = [0.82,0.66,0.66];

            comp.MaxEditField.Enable = "off";
            comp.MinEditField.Enable = "off";
            comp.MidValueEditField.Enable = "off";

            comp.SweepSectionsControl.Enable = "off";

            comp.Ratemin1EditField.Enable = "off";
            comp.StepSizeEditField.Enable = "off";
            comp.NoofStepsEditField.Enable = "off";
            comp.SettleTimesEditField.Enable = "off";

            comp.RemainingTimeMin = comp.TotalTimeMin;
            comp.UpdateTimeRemainingDisplay();
        end

        %% ShowSweepPoints
        function ShowSweepPoints(comp, sweepData, units)
            
            %If sweep is empty, just clear the GUI and return
            if isempty(sweepData)
                comp.ListBox.Items = {};
                return;
            end

            values = sweepData.Points;

            for i = 1 : length(values)
                itemStrs(i) = string(num2str(values(i))) + " " + string(units);
            end

            %Show these values in the list box which displays all the
            %points to be measured, in order
            comp.ListBox.Items = itemStrs;
        end

        %% SweepSectionsControlSelectionChanged
        function SweepSectionsControlSelectionChanged(comp, ~, eventData)
            comp.StartSectionNo = eventData.Start;
            comp.EndSectionNo = eventData.Stop;
            comp.update();
        end

        %% UpdateTimeStep
        function UpdateTimeStep(comp)
            numSteps = comp.NoofStepsEditField.Value;
            totalMag = comp.CalculateTotalMagnitude();
            timeMin = totalMag / comp.RatePerMin;
            timeSec = timeMin * 60;

            secondsPerStep = timeSec / numSteps;
            comp.SteptimesEditField.Value = secondsPerStep;
        end

        %% UpdateNumSteps
        function UpdateNumSteps(comp)
            value = comp.StepSizeEditField.Value;
            mag = comp.CalculateTotalMagnitude();

            numSteps = round(mag/value);
            comp.NoofStepsEditField.Value = numSteps;
        end

        %% UpdateTimeRemainingDisplay
        function UpdateTimeRemainingDisplay(comp)
            comp.RemainingTimeMin = comp.TotalTimeMin * (1 - comp.CurrentStep / comp.TotalSteps);
            duration = minutes(comp.RemainingTimeMin);
            s = string(duration,'hh:mm:ss');
            comp.RemainingTimeEditField.Value = s;            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.update();
            %Subscribe to events
            addlistener(comp.SweepSectionsControl, 'SelectionChanged', @(src,evnt)comp.SweepSectionsControlSelectionChanged(src,evnt));
        end

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            if(strcmp(comp.RunButton.Text, "Run"))
                comp.OnRunButtonPushed();
            else
                comp.OnAbortButtonPushed();
            end
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(comp, event)
             %Make sure values are small -> big, in the right order
            if(comp.MaxEditField.Value < comp.MidValueEditField.Value)
                comp.MaxEditField.Value = comp.MidValueEditField.Value;
            end
            value = comp.MaxEditField.Value;
            comp.MaxVal = value;
        end

        % Value changed function: MidValueEditField
        function MidValueEditFieldValueChanged(comp, event)
            %Make sure values are small -> big, in the right order
            if(comp.MidValueEditField.Value > comp.MaxEditField.Value)
                comp.MidValueEditField.Value = comp.MaxEditField.Value;
            end
            if(comp.MidValueEditField.Value < comp.MinEditField.Value)
                comp.MidValueEditField.Value = comp.MinEditField.Value;
            end
            
            value = comp.MidValueEditField.Value;
            comp.MidVal = value;            
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(comp, event)
            %Make sure values are small -> big, in the right order
            if(comp.MinEditField.Value > comp.MidValueEditField.Value)
                comp.MinEditField.Value = comp.MidValueEditField.Value;
            end
            
            value = comp.MinEditField.Value;
            comp.MinVal = value;            
        end

        % Value changed function: Ratemin1EditField
        function Ratemin1EditFieldValueChanged(comp, event)
            value = comp.Ratemin1EditField.Value;
            comp.RatePerMin = value;
            comp.update();
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(comp, event)
            comp.UpdateNumSteps();

            comp.update();                        
        end

        % Value changed function: NoofStepsEditField
        function NoofStepsEditFieldValueChanged(comp, event)
            value = comp.NoofStepsEditField.Value;
            mag = comp.CalculateTotalMagnitude();

            numSteps = mag/value;
            comp.StepSizeEditField.Value = numSteps;

            comp.update();            
        end

        % Value changed function: SettleTimesEditField
        function SettleTimesEditFieldValueChanged(comp, event)
            value = comp.SettleTimesEditField.Value;
            comp.SettleTime = value;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.UpdateNumSteps();
            comp.UpdateTimeStep();
            comp.SweepData = comp.Calculate();
            comp.ShowSweepPoints(comp.SweepData, comp.UnitsStr);
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 521 613];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.SweepSectionsControl = CoakView.Instruments.Controls.SweepSectionsControl(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.MaxEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MaxEditFieldLabel.Text = 'Max';
            comp.MaxEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MaxEditFieldValueChanged, true);
            comp.MaxEditField.Value = 1;
            comp.MidValueEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MidValueEditFieldLabel.Text = 'Mid Value';
            comp.MidValueEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MidValueEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MidValueEditFieldValueChanged, true);
            comp.MinEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MinEditFieldLabel.Text = 'Min';
            comp.MinEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MinEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MinEditFieldValueChanged, true);
            comp.MinEditField.Value = -1;
            comp.UnitsLabel1 = uilabel(comp.GridLayout2);
            comp.UnitsLabel1.Text = '';
            comp.UnitsLabel2 = uilabel(comp.GridLayout2);
            comp.UnitsLabel2.Text = '';
            comp.UnitsLabel3 = uilabel(comp.GridLayout2);
            comp.UnitsLabel3.Text = '';
            comp.GridLayout4 = uigridlayout(comp.GridLayout);
            comp.ListBox = uilistbox(comp.GridLayout4);
            comp.ListBox.Items = {};
            comp.ListBox.Enable = 'off';
            comp.ListBox.Value = {};
            comp.GridLayout5 = uigridlayout(comp.GridLayout4);
            comp.GridLayout7 = uigridlayout(comp.GridLayout5);
            comp.GridLayout9 = uigridlayout(comp.GridLayout7);
            comp.CurrentValueEditField = uieditfield(comp.GridLayout9, 'numeric');
            comp.CurrentValueEditField.Editable = 'off';
            comp.CurrentValueLabel = uilabel(comp.GridLayout9);
            comp.CurrentValueLabel.Text = 'Current Value:';
            comp.UnitsLabel4 = uilabel(comp.GridLayout9);
            comp.UnitsLabel4.Text = '';
            comp.RemainingTimeEditField = uieditfield(comp.GridLayout9, 'text');
            comp.RemainingTimeEditField.Editable = 'off';
            comp.RemainingTimeEditField.Value = '--:--:--';
            comp.GridLayout10 = uigridlayout(comp.GridLayout5);
            comp.Ratemin1Label = uilabel(comp.GridLayout10);
            comp.Ratemin1Label.Text = 'Rate (min$^{-1} )';
            comp.Ratemin1EditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.Ratemin1EditField.Limits = [0 Inf];
            comp.Ratemin1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Ratemin1EditFieldValueChanged, true);
            comp.Ratemin1EditField.Value = 10;
            comp.NoofStepsEditFieldLabel = uilabel(comp.GridLayout10);
            comp.NoofStepsEditFieldLabel.Text = 'No. of Steps';
            comp.NoofStepsEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.NoofStepsEditField.Limits = [1 Inf];
            comp.NoofStepsEditField.RoundFractionalValues = 'on';
            comp.NoofStepsEditField.ValueDisplayFormat = '%d';
            comp.NoofStepsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NoofStepsEditFieldValueChanged, true);
            comp.NoofStepsEditField.Value = 1;
            comp.StepSizeEditFieldLabel = uilabel(comp.GridLayout10);
            comp.StepSizeEditFieldLabel.Text = 'Step Size';
            comp.StepSizeEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.StepSizeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @StepSizeEditFieldValueChanged, true);
            comp.StepSizeEditField.Value = 0.1;
            comp.SteptimesEditFieldLabel = uilabel(comp.GridLayout10);
            comp.SteptimesEditFieldLabel.Text = 'Step time (s)';
            comp.SteptimesEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.SteptimesEditField.Editable = 'off';
            comp.SteptimesEditField.Value = 0.1;
            comp.UnitsLabel5 = uilabel(comp.GridLayout10);
            comp.UnitsLabel5.Text = '';
            comp.SettleTimesEditFieldLabel = uilabel(comp.GridLayout10);
            comp.SettleTimesEditFieldLabel.Text = 'Settle Time (s)';
            comp.SettleTimesEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.SettleTimesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SettleTimesEditFieldValueChanged, true);
            comp.SettleTimesEditField.Value = 0.5;
            comp.SweepSetupLabel = uilabel(comp.GridLayout);
            comp.SweepSetupLabel.Text = 'Sweep Setup';
            comp.GridLayout_RunButtonCentring = uigridlayout(comp.GridLayout);
            comp.RunButton = uibutton(comp.GridLayout_RunButtonCentring, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Text = 'Run';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end