classdef ConfigInputWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigEntryUIFigure            matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DefaultSequenceDirectoryLabel  matlab.ui.control.Label
        DefaultSeqDirBrowseButton      matlab.ui.control.Button
        RelativePathCheckBox_4         matlab.ui.control.CheckBox
        DefaultSequenceDirectoryEditField  matlab.ui.control.EditField
        RelativePathCheckBox_3         matlab.ui.control.CheckBox
        UserFilesDirBrowseButton       matlab.ui.control.Button
        UserFilesDirectoryEditField    matlab.ui.control.EditField
        UserFilesDirectoryLabel        matlab.ui.control.Label
        DefaultLogDirBrowseButton      matlab.ui.control.Button
        DefaultDataDirBrowseButton     matlab.ui.control.Button
        FileNameEditField              matlab.ui.control.EditField
        DefaultFileNameLabel           matlab.ui.control.Label
        WindowHeightEditField          matlab.ui.control.NumericEditField
        WindowHeightEditFieldLabel     matlab.ui.control.Label
        WindowWidthEditField           matlab.ui.control.NumericEditField
        WindowWidthEditFieldLabel      matlab.ui.control.Label
        StartMaximisedCheckBox         matlab.ui.control.CheckBox
        WindowSettingsLabel            matlab.ui.control.Label
        PathSettingsLabel              matlab.ui.control.Label
        RelativePathCheckBox_2         matlab.ui.control.CheckBox
        RelativePathCheckBox           matlab.ui.control.CheckBox
        DefaultLogDirectoryEditField   matlab.ui.control.EditField
        DefaultLogDirectoryLabel       matlab.ui.control.Label
        DefaultDataDirectoryLabel      matlab.ui.control.Label
        DefaultDataDirectoryEditField  matlab.ui.control.EditField
        DoneButton                     matlab.ui.control.Button
        ConfigurationoptionsLabel      matlab.ui.control.Label
    end

    
    % Events with associated public callbacks
    events (NotifyAccess = private)
        ConfigEntryComplete;
    end
    
    methods (Access = public)
        
        %% SetInitialValues
        function SetInitialValues(app, Settings)
            arguments
                app;
                Settings.DefaultDataDirectory                   {mustBeTextScalar} = "Default Data Directory";
                Settings.DefaultDataDirectoryIsRelativePath     (1,1) logical = false;
                Settings.DefaultLogFileDirectory                {mustBeTextScalar} = "Default Log File Directory";
                Settings.DefaultLogFileDirectoryIsRelativePath  (1,1) logical = false;
                Settings.DefaultSequenceDirectory                {mustBeTextScalar} = "Default Log File Directory";
                Settings.DefaultSequenceDirectoryIsRelativePath  (1,1) logical = false;
                Settings.DefaultFileName                        {mustBeTextScalar} = "<DATE>_Filename";
                Settings.UserFilesDirectory                     {mustBeTextScalar} = "Default Data Directory";
                Settings.UserFilesDirectoryIsRelativePath       (1,1) logical = false;
                Settings.WindowWidth                            (1,1) {mustBeInteger} = 1600;
                Settings.WindowHeight                           (1,1) {mustBeInteger} = 900;
                Settings.WindowStartsMaximised                  (1,1) logical = true;
            end

            %Path settings
            app.UserFilesDirectoryEditField.Value = Settings.UserFilesDirectory;
            app.DefaultDataDirectoryEditField.Value = Settings.DefaultDataDirectory;
            app.DefaultLogDirectoryEditField.Value = Settings.DefaultLogFileDirectory;
            app.DefaultSequenceDirectoryEditField.Value = Settings.DefaultSequenceDirectory;
            app.RelativePathCheckBox.Value = Settings.DefaultDataDirectoryIsRelativePath;
            app.RelativePathCheckBox_2.Value = Settings.DefaultLogFileDirectoryIsRelativePath;
            app.RelativePathCheckBox_3.Value = Settings.UserFilesDirectoryIsRelativePath;
            app.RelativePathCheckBox_4.Value = Settings.DefaultSequenceDirectoryIsRelativePath;
            app.FileNameEditField.Value = Settings.DefaultFileName;

            %Window settings
            app.WindowWidthEditField.Value = Settings.WindowWidth;
            app.WindowHeightEditField.Value = Settings.WindowHeight;
            app.StartMaximisedCheckBox.Value = Settings.WindowStartsMaximised;
        end
    end
    
    methods (Access = private)

        %% GatherSettings
        function configStruct = GatherSettings(app)
            %Path settings
            configStruct.UserFilesDirectory = app.UserFilesDirectoryEditField.Value;
            configStruct.DefaultDirectory = app.DefaultDataDirectoryEditField.Value;
            configStruct.LogFileDirectory = app.DefaultLogDirectoryEditField.Value;
            configStruct.DefaultSequenceDirectory = app.DefaultSequenceDirectoryEditField.Value;
            configStruct.DataDirectoryIsRelativePath = app.RelativePathCheckBox.Value;
            configStruct.LogFileDirectoryIsRelativePath = app.RelativePathCheckBox_2.Value;
            configStruct.UserFilesDirectoryIsRelativePath = app.RelativePathCheckBox_3.Value;
            configStruct.SequenceDirectoryIsRelativePath = app.RelativePathCheckBox_4.Value;
            configStruct.DefaultFileName = app.FileNameEditField.Value;

            %Window settings
            configStruct.DefaultSize = [app.WindowWidthEditField.Value, app.WindowHeightEditField.Value];
            configStruct.WindowStartsMaximised = app.StartMaximisedCheckBox.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DefaultDataDirBrowseButton
        function DefaultDataDirBrowseButtonPushed(app, event)
            try
                %Open a file dialog window to choose folder and path
                directory = app.DefaultDataDirectoryEditField.Value;
                selpath = Palladium.Utilities.GUIUtils.ChooseFile("folder", Title="Select directory", Default=directory, Reference=app);

                %Abort if operation cancelled
                if selpath == ""
                    return;
                end

                %Enter these values straight into the GUI
                app.DefaultDataDirectoryEditField.Value = selpath;
            catch err
               Palladium.Core.ErrorGuard.HandleError("Error in DefaultDataDirBrowseButtonPushed", err, Standalone = true, Figure = app.ConfigEntryUIFigure);
            end
        end

        % Button pushed function: DefaultLogDirBrowseButton
        function DefaultLogDirBrowseButtonPushed(app, event)
            try
                %Open a file dialog window to choose folder and path
                directory = app.DefaultLogDirectoryEditField.Value;
                selpath = Palladium.Utilities.GUIUtils.ChooseFile("folder", Title="Select directory", Default=directory, Reference=app);

                %Abort if operation cancelled
                if selpath == ""
                    return;
                end

                %Enter these values straight into the GUI
                app.DefaultLogDirectoryEditField.Value = selpath;
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in DefaultLogDirBrowseButtonPushed", err, Standalone=true, Figure = app.ConfigEntryUIFigure);
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            try
                %Gather all the entered data
                settings = app.GatherSettings();

                %Send data into event args and trigger event
                eventArgs = Palladium.Events.ValueChangedEventData(settings);
                notify(app, 'ConfigEntryComplete', eventArgs);

                close(app.ConfigEntryUIFigure);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in DoneButtonPushed", err, Standalone=true, Figure = app.ConfigEntryUIFigure);
            end
        end

        % Button pushed function: UserFilesDirBrowseButton
        function UserFilesDirBrowseButtonPushed(app, event)
            try
                %Open a file dialog window to choose folder and path
                directory = app.UserFilesDirectoryEditField.Value;
                selpath = Palladium.Utilities.GUIUtils.ChooseFile("folder", Title="Select directory", Default=directory, Reference=app);

                %Abort if operation cancelled
                if selpath == ""
                    return;
                end

                %Enter these values straight into the GUI
                app.UserFilesDirectoryEditField.Value = selpath;
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in UserFilesDirBrowseButtonPushed", err, Standalone=true, Figure = app.ConfigEntryUIFigure);
            end
        end

        % Button pushed function: DefaultSeqDirBrowseButton
        function DefaultSeqDirBrowseButtonPushed(app, event)
            try
                %Open a file dialog window to choose folder and path
                directory = app.DefaultSequenceDirectoryEditField.Value;
                selpath = Palladium.Utilities.GUIUtils.ChooseFile("folder", Title="Select directory", Default=directory, Reference=app);

                %Abort if operation cancelled
                if selpath == ""
                    return;
                end

                %Enter these values straight into the GUI
                app.DefaultSequenceDirectoryEditField.Value = selpath;
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in DefaultSeqDirBrowseButtonPushed", err, Standalone=true, Figure = app.ConfigEntryUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigEntryUIFigure = uifigure('Visible', 'off');
            app.ConfigEntryUIFigure.Name = 'Config Entry';
            app.ConfigEntryUIFigure.Icon = 'PalladiumDAQIcon.png';
            app.GridLayout = uigridlayout(app.ConfigEntryUIFigure);
            app.ConfigurationoptionsLabel = uilabel(app.GridLayout);
            app.ConfigurationoptionsLabel.Text = 'Configuration options';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DefaultDataDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.DefaultDataDirectoryEditField.Tooltip = {'Default data directory to appear in the GUI when loaded'};
            app.DefaultDataDirectoryEditField.Value = 'Value';
            app.DefaultDataDirectoryLabel = uilabel(app.GridLayout);
            app.DefaultDataDirectoryLabel.Text = 'Default Data Directory';
            app.DefaultLogDirectoryLabel = uilabel(app.GridLayout);
            app.DefaultLogDirectoryLabel.Text = 'Default Log Directory';
            app.DefaultLogDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.DefaultLogDirectoryEditField.Tooltip = {'Default Log File directory'};
            app.DefaultLogDirectoryEditField.Value = 'Value';
            app.RelativePathCheckBox = uicheckbox(app.GridLayout);
            app.RelativePathCheckBox.Tooltip = {'If this is set to true, the path will be stored as relative to the main code directory, ie "..\\..\\Palladium DAQ - Testing" rather than a full hard coded path. Use this if e.g. running the code from a OneDrive folder on different machines where absolute paths will break.'};
            app.RelativePathCheckBox.Text = 'Relative Path';
            app.RelativePathCheckBox_2 = uicheckbox(app.GridLayout);
            app.RelativePathCheckBox_2.Tooltip = {'If this is set to true, the path will be stored as relative to the main code directory, ie "..\\..\\Palladium DAQ - Testing" rather than a full hard coded path. Use this if e.g. running the code from a OneDrive folder on different machines where absolute paths will break.'};
            app.RelativePathCheckBox_2.Text = 'Relative Path';
            app.PathSettingsLabel = uilabel(app.GridLayout);
            app.PathSettingsLabel.Text = 'Path Settings';
            app.WindowSettingsLabel = uilabel(app.GridLayout);
            app.WindowSettingsLabel.Text = 'Window Settings';
            app.StartMaximisedCheckBox = uicheckbox(app.GridLayout);
            app.StartMaximisedCheckBox.Text = 'Start Maximised';
            app.StartMaximisedCheckBox.Value = true;
            app.WindowWidthEditFieldLabel = uilabel(app.GridLayout);
            app.WindowWidthEditFieldLabel.Text = 'Window Width';
            app.WindowWidthEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowWidthEditField.Limits = [300 10000];
            app.WindowWidthEditField.RoundFractionalValues = 'on';
            app.WindowWidthEditField.Value = 1600;
            app.WindowHeightEditFieldLabel = uilabel(app.GridLayout);
            app.WindowHeightEditFieldLabel.Text = 'Window Height';
            app.WindowHeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowHeightEditField.Limits = [300 10000];
            app.WindowHeightEditField.RoundFractionalValues = 'on';
            app.WindowHeightEditField.Value = 900;
            app.DefaultFileNameLabel = uilabel(app.GridLayout);
            app.DefaultFileNameLabel.Text = 'Default File Name';
            app.FileNameEditField = uieditfield(app.GridLayout, 'text');
            app.FileNameEditField.Tooltip = {'Default data file name that GUI will be populated with on launch. The special string <DATE> will get converted to the current date.'};
            app.FileNameEditField.Value = '<DATE>_Filename';
            app.DefaultDataDirBrowseButton = uibutton(app.GridLayout, 'push');
            app.DefaultDataDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultDataDirBrowseButtonPushed, true);
            app.DefaultDataDirBrowseButton.Text = 'Browse';
            app.DefaultLogDirBrowseButton = uibutton(app.GridLayout, 'push');
            app.DefaultLogDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultLogDirBrowseButtonPushed, true);
            app.DefaultLogDirBrowseButton.Text = 'Browse';
            app.UserFilesDirectoryLabel = uilabel(app.GridLayout);
            app.UserFilesDirectoryLabel.Text = 'User Files Directory';
            app.UserFilesDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.UserFilesDirectoryEditField.Tooltip = {'Location of the User Files directory, where custom Instruments and Presets can be created.'};
            app.UserFilesDirectoryEditField.Value = 'Value';
            app.UserFilesDirBrowseButton = uibutton(app.GridLayout, 'push');
            app.UserFilesDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @UserFilesDirBrowseButtonPushed, true);
            app.UserFilesDirBrowseButton.Text = 'Browse';
            app.RelativePathCheckBox_3 = uicheckbox(app.GridLayout);
            app.RelativePathCheckBox_3.Tooltip = {'If this is set to true, the path will be stored as relative to the main code directory, ie "..\\..\\Palladium DAQ - Testing" rather than a full hard coded path. Use this if e.g. running the code from a OneDrive folder on different machines where absolute paths will break.'};
            app.RelativePathCheckBox_3.Text = 'Relative Path';
            app.DefaultSequenceDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.DefaultSequenceDirectoryEditField.Tooltip = {'Default Log File directory'};
            app.DefaultSequenceDirectoryEditField.Value = 'Value';
            app.RelativePathCheckBox_4 = uicheckbox(app.GridLayout);
            app.RelativePathCheckBox_4.Tooltip = {'If this is set to true, the path will be stored as relative to the main code directory, ie "..\\..\\Palladium DAQ - Testing" rather than a full hard coded path. Use this if e.g. running the code from a OneDrive folder on different machines where absolute paths will break.'};
            app.RelativePathCheckBox_4.Text = 'Relative Path';
            app.DefaultSeqDirBrowseButton = uibutton(app.GridLayout, 'push');
            app.DefaultSeqDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSeqDirBrowseButtonPushed, true);
            app.DefaultSeqDirBrowseButton.Text = 'Browse';
            app.DefaultSequenceDirectoryLabel = uilabel(app.GridLayout);
            app.DefaultSequenceDirectoryLabel.Text = 'Default Sequence Directory';
            % Show the figure after all components are created
            app.ConfigEntryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigInputWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigEntryUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigEntryUIFigure)
        end
    end
end