classdef FilePathsPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout           matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        DescriptionTextArea  matlab.ui.control.TextArea
        DescriptionLabel     matlab.ui.control.Label
        FileNameLabel        matlab.ui.control.Label
        DirectoryLabel       matlab.ui.control.Label
        FileNameEditField    matlab.ui.control.EditField
        DirectoryEditField   matlab.ui.control.EditField
        GridLayout2          matlab.ui.container.GridLayout
        WriteModeDropDown    matlab.ui.control.DropDown
        WritetoFileCheckBox  matlab.ui.control.CheckBox
        BrowseButton         matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        DescriptionChanged
        DirectoryChanged
        FileNameChanged
        FileWriteModeChanged
        SaveFileBoolChanged
    end

    properties (Access = public)
        FileExtension = ".dat";
    end
    
    
    methods (Access = private)
        
        %% PathValid
        function value = PathValid(comp)
            %Check if entered path is valid
            dir = comp.DirectoryEditField.Value;

            if(~isfolder(dir))
                value = false;
                return;
            end

            value = true;
        end
        
    end
    
    methods (Access = public)
        
        %% LockInput
        %Disable all elements of this component while the parent code is
        %running - only allow changes when the measurements are Stopped
        function LockInput(comp)
            comp.FileNameEditField.Enable = false;
            comp.FileNameEditField.Editable = false;

            comp.DirectoryEditField.Enable = false;
            comp.DirectoryEditField.Editable = false;

            comp.DescriptionTextArea.Enable = false;
            comp.DescriptionTextArea.Editable = false;

            comp.BrowseButton.Enable = false;
            comp.WritetoFileCheckBox.Enable = false;
            comp.WriteModeDropDown.Enable = false;
        end

        %% OnFileWriteOptionsChanged
        function OnFileWriteOptionsChanged(comp, fileWriteDetails)
            %Update all GUI elements to match settings in the
            %fileWriteDetails struct - will be called after the settings in
            %the Model's Controller change
            comp.DirectoryEditField.Value = fileWriteDetails.Directory;
            comp.FileNameEditField.Value = fileWriteDetails.FileName;
            comp.DescriptionTextArea.Value = fileWriteDetails.DescriptionText;
            comp.WritetoFileCheckBox.Value = fileWriteDetails.SaveFile;
            comp.WriteModeDropDown.Value = fileWriteDetails.WriteMode;

            comp.FileExtension = fileWriteDetails.FileExtension;
        end
        
        %% UnlockInput
        %Allow editing of component values again - presumably the main
        %window has been Stopped so we can set a new path for the next run
        function UnlockInput(comp)
            comp.FileNameEditField.Enable = true;
            comp.FileNameEditField.Editable = true;

            comp.DirectoryEditField.Enable = true;
            comp.DirectoryEditField.Editable = true;

            comp.DescriptionTextArea.Enable = true;
            comp.DescriptionTextArea.Editable = true;

            comp.BrowseButton.Enable = true;
            comp.WritetoFileCheckBox.Enable = true;
            comp.WriteModeDropDown.Enable = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
                               
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(comp, event)
            try
                %Open a file dialog window to choose folder and file paths
                directory = comp.DirectoryEditField.Value;
                fileName = comp.FileNameEditField.Value;
                filePath = Palladium.Utilities.GUIUtils.ChooseFile("save", Filter={char("*" + comp.FileExtension), 'Data files'}, ...
                    Title="Select file path", Default=fullfile(directory, fileName), Reference=comp);

                %Abort if operation cancelled
                if filePath == ""
                    return;
                end

                %Split path into components and assign
                [path, fileNameWithoutExt, ~] = fileparts(filePath);

                %Enter these values straight into the GUI
                comp.DirectoryEditField.Value = path;
                comp.FileNameEditField.Value = fileNameWithoutExt;

                %Trigger event
                eventArgs = Palladium.Events.ValueChangedEventData(path);
                notify(comp, 'DirectoryChanged', eventArgs);

                %Trigger event
                eventArgs = Palladium.Events.ValueChangedEventData(fileNameWithoutExt);
                notify(comp, 'FileNameChanged', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in BrowseButtonPushed", err);
            end
        end

        % Value changed function: WritetoFileCheckBox
        function WritetoFileCheckBoxValueChanged(comp, event)
            try
                value = comp.WritetoFileCheckBox.Value;

                %Trigger event
                eventArgs = Palladium.Events.ValueChangedEventData(value);
                notify(comp, 'SaveFileBoolChanged', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in WritetoFileCheckBoxValueChanged", err);
            end
        end

        % Value changed function: WriteModeDropDown
        function WriteModeDropDownValueChanged(comp, event)
            try
                value = comp.WriteModeDropDown.Value;

                %Trigger event
                eventArgs = Palladium.Events.ValueChangedEventData(value);
                notify(comp, 'FileWriteModeChanged', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in WriteModeDropDownValueChanged", err);
            end
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(comp, event)
            try
                value = comp.DirectoryEditField.Value;

                %Trigger event
                eventArgs = Palladium.Events.ValueChangedEventData(value);
                notify(comp, 'DirectoryChanged', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in DirectoryEditFieldValueChanged", err);
            end
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(comp, event)
            try
                value = comp.FileNameEditField.Value;

                %Trigger event
                eventArgs = Palladium.Events.ValueChangedEventData(value);
                notify(comp, 'FileNameChanged', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in FileNameEditFieldValueChanged", err);
            end
        end

        % Value changed function: DescriptionTextArea
        function DescriptionEditFieldValueChanged(comp, event)
            try
                value = comp.DescriptionTextArea.Value;

                %Trigger event
                eventArgs = Palladium.Events.ValueChangedEventData(value);
                notify(comp, 'DescriptionChanged', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in DescriptionEditFieldValueChanged", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
          
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 730 159];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.BrowseButton = uibutton(comp.GridLayout2, 'push');
            comp.BrowseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @BrowseButtonPushed, true);
            comp.BrowseButton.Enable = 'off';
            comp.BrowseButton.Text = 'Browse';
            comp.WritetoFileCheckBox = uicheckbox(comp.GridLayout2);
            comp.WritetoFileCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @WritetoFileCheckBoxValueChanged, true);
            comp.WritetoFileCheckBox.Tooltip = {'Untick this box to disable writing of data to files entirely'};
            comp.WritetoFileCheckBox.Enable = 'off';
            comp.WritetoFileCheckBox.Text = '  Write to File';
            comp.WritetoFileCheckBox.Value = true;
            comp.WriteModeDropDown = uidropdown(comp.GridLayout2);
            comp.WriteModeDropDown.Items = {'Increment File No.', 'Append To File', 'Overwrite File'};
            comp.WriteModeDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @WriteModeDropDownValueChanged, true);
            comp.WriteModeDropDown.Enable = 'off';
            comp.WriteModeDropDown.Tooltip = {'Select behaviour for multiple data file write to the same file name'};
            comp.WriteModeDropDown.Value = 'Increment File No.';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.DirectoryEditField = uieditfield(comp.GridLayout3, 'text');
            comp.DirectoryEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DirectoryEditFieldValueChanged, true);
            comp.DirectoryEditField.Editable = 'off';
            comp.DirectoryEditField.Enable = 'off';
            comp.FileNameEditField = uieditfield(comp.GridLayout3, 'text');
            comp.FileNameEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FileNameEditFieldValueChanged, true);
            comp.FileNameEditField.Editable = 'off';
            comp.FileNameEditField.Enable = 'off';
            comp.DirectoryLabel = uilabel(comp.GridLayout3);
            comp.DirectoryLabel.Text = 'Directory';
            comp.FileNameLabel = uilabel(comp.GridLayout3);
            comp.FileNameLabel.Text = 'File Name';
            comp.DescriptionLabel = uilabel(comp.GridLayout3);
            comp.DescriptionLabel.Text = 'Description';
            comp.DescriptionTextArea = uitextarea(comp.GridLayout3);
            comp.DescriptionTextArea.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DescriptionEditFieldValueChanged, true);
            comp.DescriptionTextArea.Editable = 'off';
            comp.DescriptionTextArea.Enable = 'off';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end