classdef InstrumentBrowserPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        RemoveButton                matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        GridLayout3                 matlab.ui.container.GridLayout
        SelectedInstrumentsListBox  matlab.ui.control.ListBox
        SelectedInstrumentsLabel    matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        InstrumentsLabel            matlab.ui.control.Label
        InstrumentsListBox          matlab.ui.control.ListBox
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        InstrumentAdded
        InstrumentRemoved
        SelectedListChange
        SelectionChange
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        HighlightedInstrumentEntry = []; % String (or null) of Instrument in the left hand box that is currently focussed - this is the thing that will get added if we click Add
        HighlightedSelectedInstrumentEntry = [];
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Enabled (1,1) logical = true;
    end
    
    properties (Access = private, Constant)
        Namespace string = "Palladium.Instruments";
    end
    
    
    methods (Access = public)
        
        function instRefs = GetSelectedInstruments(comp)
            instRefs = comp.SelectedInstrumentsListBox.ItemsData;
        end

        function LockInput(comp)
            comp.SelectedInstrumentsListBox.Enable = false;
            comp.InstrumentsListBox.Enable = false;
            comp.RemoveButton.Enable = false;
            comp.AddButton.Enable = false;
        end

        function OnInstrumentAdded(comp, instrClassName, instance)

            %Add the currently selected available datasource to the end of the selected datasources list
            if(isempty(comp.SelectedInstrumentsListBox.Items))
                comp.SelectedInstrumentsListBox.Items = string(instrClassName);
                comp.SelectedInstrumentsListBox.ItemsData = {instance};
                
            else
                comp.SelectedInstrumentsListBox.Items = [comp.SelectedInstrumentsListBox.Items, instrClassName];
                comp.SelectedInstrumentsListBox.ItemsData = [comp.SelectedInstrumentsListBox.ItemsData, {instance}];
            end
            
            %Trigger event to notify that the selection has changed
            notify(comp, 'SelectedListChange');
            
            %Treat adding an item as selecting that item too
            comp.SelectedInstrumentsListBox.Value = instance;
            comp.HighlightedSelectedInstrumentEntry = instance;
            comp.OnSelectionChanged(); 
        end

        function OnInstrumentRemoved(comp, instrumentRef)

            if isempty(instrumentRef)
                return;
            end

            idx = comp.FindIndexOfSelectedInstrument(instrumentRef);
            comp.SelectedInstrumentsListBox.Items(idx) = [];
            comp.SelectedInstrumentsListBox.ItemsData(idx) = [];

            %Update selected entry to be the next one selected now the
            %previous one is gone
            comp.HighlightedSelectedInstrumentEntry = comp.SelectedInstrumentsListBox.Value;
            
            %Trigger SelectionChanged event to update other parts of the
            %GUI
            comp.OnSelectionChanged();
        end

        function PopulateInstrumentList(comp, cellArrayOfInstrumentNameStrings)
            %Handle case of getting an empty list
            if isempty(cellArrayOfInstrumentNameStrings)
                comp.InstrumentsListBox.Items = {};
            else
                comp.InstrumentsListBox.Items = cellArrayOfInstrumentNameStrings;
            end

            comp.HighlightedInstrumentEntry = [];
        end

        function UnlockInput(comp)
            comp.SelectedInstrumentsListBox.Enable = true;
            comp.InstrumentsListBox.Enable = true;
            comp.RemoveButton.Enable = true;
            comp.AddButton.Enable = true;
        end
    end
    
    methods (Access = private)
           
        %% AddInstrumentClicked
        function AddInstrumentClicked(comp)
            %Cache value
            instrStringToAdd = comp.HighlightedInstrumentEntry;
            eventArgs = Palladium.Events.ValueChangedEventData(instrStringToAdd);
            
            %Fire event
            notify(comp, "InstrumentAdded", eventArgs);
        end

        %% FindIndexOfSelectedInstrument
        function idx = FindIndexOfSelectedInstrument(comp, instrumentRef)
            for i = 1 : length(comp.SelectedInstrumentsListBox.Items)
                if(comp.SelectedInstrumentsListBox.ItemsData{i} == instrumentRef)
                    idx = i;
                    return;
                end
            end

            %If we got here it's bad news, as we didn't find the reference
            %in any of the data items - error
            error("FindIndexOfSelectedInstrumentError:InstrumentNotFound", "Could not find Instrument reference to delete");
        end

        %% OnSelectionChanged
        function OnSelectionChanged(comp)
            eventData = Palladium.Events.InstrumentEventData(comp.HighlightedSelectedInstrumentEntry);
            notify(comp, 'SelectionChange', eventData);    
        end
      
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(comp, event)
            try
                comp.AddInstrumentClicked();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in AddButtonPushed", err);
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(comp, event)
            try
                instStringToRemove = comp.HighlightedSelectedInstrumentEntry;
                eventArgs = Palladium.Events.ValueChangedEventData(instStringToRemove);

                %Fire event
                notify(comp, "InstrumentRemoved", eventArgs)
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in RemoveButtonPushed", err);
            end
        end

        % Value changed function: SelectedInstrumentsListBox
        function SelectedInstrumentsListBoxValueChanged(comp, event)
            try
                value = comp.SelectedInstrumentsListBox.Value;
                comp.HighlightedSelectedInstrumentEntry = value;
                comp.OnSelectionChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in SelectedInstrumentsListBoxValueChanged", err);
            end
        end

        % Value changed function: InstrumentsListBox
        function InstrumentsListBoxValueChanged(comp, event)
            try
                value = comp.InstrumentsListBox.Value;
                comp.HighlightedInstrumentEntry = value;
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in InstrumentsListBoxValueChanged", err);
            end
        end

        % Double-clicked callback: InstrumentsListBox
        function InstrumentsListBoxDoubleClicked(comp, event)
            try
                value = comp.InstrumentsListBox.Value;
                comp.HighlightedInstrumentEntry = value;

                comp.AddInstrumentClicked();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in InstrumentsListBoxDoubleClicked", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 964 500];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.InstrumentsListBox = uilistbox(comp.GridLayout2);
            comp.InstrumentsListBox.Items = {};
            comp.InstrumentsListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @InstrumentsListBoxValueChanged, true);
            comp.InstrumentsListBox.Enable = 'off';
            comp.InstrumentsListBox.DoubleClickedFcn = matlab.apps.createCallbackFcn(comp, @InstrumentsListBoxDoubleClicked, true);
            comp.InstrumentsListBox.Value = {};
            comp.InstrumentsLabel = uilabel(comp.GridLayout2);
            comp.InstrumentsLabel.Text = 'Instruments';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.SelectedInstrumentsLabel = uilabel(comp.GridLayout3);
            comp.SelectedInstrumentsLabel.Text = 'Selected Instruments';
            comp.SelectedInstrumentsListBox = uilistbox(comp.GridLayout3);
            comp.SelectedInstrumentsListBox.Items = {};
            comp.SelectedInstrumentsListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SelectedInstrumentsListBoxValueChanged, true);
            comp.SelectedInstrumentsListBox.Enable = 'off';
            comp.SelectedInstrumentsListBox.Value = {};
            comp.GridLayout4 = uigridlayout(comp.GridLayout);
            comp.AddButton = uibutton(comp.GridLayout4, 'push');
            comp.AddButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AddButtonPushed, true);
            comp.AddButton.Enable = 'off';
            comp.AddButton.Text = 'Add >';
            comp.RemoveButton = uibutton(comp.GridLayout4, 'push');
            comp.RemoveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RemoveButtonPushed, true);
            comp.RemoveButton.Enable = 'off';
            comp.RemoveButton.Text = '< Remove';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end