classdef InstrumentOptionsPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        LowerPanel                  matlab.ui.container.Panel
        LowerLayout                 matlab.ui.container.GridLayout
        AddInstrumentControlsLabel  matlab.ui.control.Label
        MainPanel                   matlab.ui.container.Panel
        MainLayout                  matlab.ui.container.GridLayout
        InstrumentSettingsLabel     matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        InstrumentControlDisabled
        InstrumentControlEnabled
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        ExposeSubclassProperties (1,1) logical = true;
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Columns (1,1) double {mustBePositive(Columns), mustBeNonzero(Columns), mustBeInteger(Columns), mustBeFinite(Columns)} = 2;
        Enabled (1,1) logical = false;
    end
    
    properties (Access = private)
        ActionsPanel = [];
        ControlDetailsStructs = [];
        ControlDetailsStructsWithGUIRefs
        Instrument = [];
        InstrumentEventListener = [];
        PropertyList = [];
        Widgets = [];
    end
    
    properties (Access = private, Constant)
        ButtonColour_DarkGrey double = [0.7 0.7 0.7];
        ButtonColour_Green double = [0.79,0.91,0.64];
        ButtonColour_Red double = [0.85,0.3,0.3];
    end
    

    methods (Access = private)

        %% ConnectionTypeChanged
        function ConnectionTypeChanged(comp)
            comp.RefreshControls();
        end

        %% BuildSetupControls
        function BuildSetupControls(comp)
            %Let the Instrument add its own controls (CreateSetupControls) to an area between the settings and the Instrument Controls - the area is only made, and the settings shrink for it, if the Instrument adds some
            %The area is made here rather than designed in, so the designed layout is the one without it
            delete(comp.ActionsPanel);
            comp.ActionsPanel = [];

            height = 0;
            if ~isempty(comp.Instrument)
                panel = uipanel(comp.GridLayout);
                panel.Layout.Row = 3;
                panel.Layout.Column = 1;
                try
                    height = comp.Instrument.CreateSetupControls(panel);
                catch err
                    height = 0;
                    Palladium.Logging.Logger.Log("Warning", "Error creating the setup controls of " + string(comp.Instrument.Name) + ": " + string(err.message));
                end
                if height > 0
                    panel.Enable = comp.MainPanel.Enable;
                    comp.ActionsPanel = panel;
                else
                    delete(panel);
                end
            end

            if height > 0
                comp.GridLayout.RowHeight = {30, '2x', height, 30, '1x'};
                comp.AddInstrumentControlsLabel.Layout.Row = 4;
                comp.LowerPanel.Layout.Row = 5;
            else
                comp.AddInstrumentControlsLabel.Layout.Row = 3;
                comp.LowerPanel.Layout.Row = 4;
                comp.GridLayout.RowHeight = {30, '2x', 30, '1x'};
            end
        end

        %% CreateWidgets
        function CreateWidgets(comp)
            %Clean up any existing MainLayout
            if(~isempty(comp.MainLayout))
                delete(comp.MainLayout);
            end

            %Clean up any existing LowerLayout
            if(~isempty(comp.LowerLayout))
                delete(comp.LowerLayout);
            end

            %Clear existing properties
            comp.Widgets = [];

            %The Instrument's own setup controls
            comp.BuildSetupControls();

            %Handle case of null reference by just clearing all then
            %returning
            if isempty(comp.Instrument)
                return;
            end

            %Set some settings
            noProps = length(comp.PropertyList);
            noColumns = comp.Columns;
            noRows = noProps / noColumns + 1;

            rowHeight = 30;
            labelWdth = 150;
            spacingWdth = 30;

            rowsStringCell = Palladium.Components.InstrumentOptionsPanel.GetRowGridFormat(noRows, rowHeight);
            colsStringCell = Palladium.Components.InstrumentOptionsPanel.GetColumnGridFormat(noColumns, labelWdth, spacingWdth);

            %Make a gridlayout to arrange everything. twice as many columns
            %as noColumns as there are labels and then edit boxes
            comp.MainLayout = uigridlayout(comp.MainPanel, 'RowHeight', rowsStringCell, 'ColumnWidth', colsStringCell, 'Padding', [10 10 10 10], 'Scrollable', true);

            %Add the Instrument's FullName as a title across the top
            titleLabel = uilabel(comp.MainLayout, "Text", comp.Instrument.FullName, "FontName", "Georgia", "FontSize", 16, "FontWeight", "Bold", "HorizontalAlignment", "Center");
            titleLabel.Layout.Column = [1 length(colsStringCell)];

            % Loop over all properties and add controls for them to the
            % gridlayout
            for i=1 : noProps
                %Grab some data about the property
                propertyName = string(comp.PropertyList(i));
                propertyValue = comp.Instrument.(propertyName);
                if isdeployed
                    tooltipString = "TODO - Tooltip string in deployed code";
                else
                    %Tell the compiler not to worry, deployed version of
                    %code will not touch the 'help' function, which is
                    %unavailable in compiled exe
                    %#exclude help
                    tooltipString = strtrim(string(help(string(class(comp.Instrument)) +  "." + propertyName)));
                end

                %Add label
                comp.Widgets.(propertyName).Label = uilabel(comp.MainLayout, 'Text', comp.PropertyList(i), "FontName", "Georgia", "FontSize", 14);

                % create the right type of widget to render a given property.
                comp.Widgets.(propertyName).Edit = Palladium.Components.InstrumentOptionsPanel.CreateWidgetByPropertyType(propertyValue, comp.MainLayout, comp, propertyName, tooltipString);

                %Empty spacer
                uilabel(comp.MainLayout, 'Text', '', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'center');
            end

            %Now for the lower panel
            %Set some settings
            noProps = length(comp.ControlDetailsStructs);
            noColumns = comp.Columns;
            noRows = noProps / noColumns + 1;
            btnWdth = 40;

            rowsStringCell = Palladium.Components.InstrumentOptionsPanel.GetRowGridFormat(noRows, rowHeight);
            colsStringCell = Palladium.Components.InstrumentOptionsPanel.GetLowerColumnGridFormat(noColumns, btnWdth, spacingWdth);

            %Make a lower gridlayout to arrange everything.
            comp.LowerLayout = uigridlayout(comp.LowerPanel, 'RowHeight', rowsStringCell, 'ColumnWidth', colsStringCell, 'Padding', [10 10 10 10], 'Scrollable', true);

            % Loop over all Controls to add for more advanced instrument control and add label-button pairs for them to the
            % gridlayout
            for i=1 : length(comp.ControlDetailsStructs)
                cds = comp.ControlDetailsStructs(i);
                cds.instrRef = comp.Instrument;

                %Add text label stating the control's name
                uilabel(comp.LowerLayout, 'Text', cds.Name, "FontName", "Georgia", "FontSize", 16);

                %Two buttons, on and off
                btnOn = uibutton(comp.LowerLayout, "Text", "On", "ButtonPushedFcn", @comp.ControlOnBtnPushed, "UserData", cds, "FontName", "Georgia", "FontSize", 16);
                btnOff = uibutton(comp.LowerLayout, "Text", "Off", "ButtonPushedFcn", @comp.ControlOffBtnPushed, "UserData", cds, "FontName", "Georgia", "FontSize", 16);

                %Save some data into the buttons' UserData so they can
                %refer to each other when pressed etc
                cds.btnOn = btnOn;
                cds.btnOff = btnOff;
                comp.ControlDetailsStructsWithGUIRefs{i} = cds;    %Need to do this as struct is a value type...

                %Empty spacer
                uilabel(comp.LowerLayout, 'Text', '', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'center');

                if comp.IsInstrumentControlEnabled(comp.Instrument, cds)
                    btnOn.BackgroundColor = comp.ButtonColour_Green;   %Green
                    btnOff.BackgroundColor = comp.ButtonColour_DarkGrey; %Dark grey
                else
                    btnOn.BackgroundColor = comp.ButtonColour_DarkGrey; %Dark grey
                    btnOff.BackgroundColor = comp.ButtonColour_Red;    %red
                end
            end
        end

        %% ControlOnBtnPushed
        function ControlOnBtnPushed(comp, sender, ~)
            %Abort if the button is already pushed
            if sender.BackgroundColor ~= comp.ButtonColour_DarkGrey
                return
            end

            %Fire event
            eventArgs = Palladium.Events.ValueChangedEventData(sender.UserData);
            notify(comp, 'InstrumentControlEnabled', eventArgs);
        end

        %% ControlOffBtnPushed
        function ControlOffBtnPushed(comp, sender, ~)
            %Abort if the button is already pushed
            if sender.BackgroundColor ~= comp.ButtonColour_DarkGrey
                return
            end

            %Fire event
            eventArgs = Palladium.Events.ValueChangedEventData(sender.UserData);
            notify(comp, 'InstrumentControlDisabled', eventArgs);
        end


        %% GetControlDetailsStructFromName
        function cds = GetControlDetailsStructFromName(comp, name)
            cds = [];

            for i=1 : length(comp.ControlDetailsStructs)
                c = comp.ControlDetailsStructsWithGUIRefs{i};
                if strcmp(c.Name, name)
                    cds = c;
                    return;
                end
            end
        end

        %% IsInstrumentControlEnabled
        function tf = IsInstrumentControlEnabled(~, instrRef, cds)
            objsList = instrRef.GetRegisteredControlObjectsFromName(cds.Name);
            tf = ~isempty(objsList);
        end

        %% ValueChanged events
        function boolValueChanged(comp, varName,val)
            comp.Instrument.(varName) = val;
        end

        function categoricalValueChanged(comp, varName, val, cats)
            catsStr = string(cats)';
            valStr = string(val);
            comp.Instrument.(varName) = categorical(valStr, catsStr);
        end

        function enumValueChanged(comp, varName, val)
            %Get the name of the enum already in the object's property. If
            %we don't do this stuff below, it'll become a string
            %corresponding to the enum value rather than a proper enum once
            %we set it
            enumClassName = class(comp.Instrument.(varName));

            %Create an enum of matching type
            namespace = [];
            enumval = Palladium.Utilities.PluginLoading.InstantiateEnum(namespace, string(enumClassName), char(val));

            %Set property
            comp.Instrument.(varName) = enumval;

            %Handle special case - if we change connection type we want to
            %hide/show other components
            if(strcmp(varName, "Connection_Type"))
                comp.ConnectionTypeChanged();
            end
        end

        function doubleArrayValueChanged(comp, varName, val)
            [arrayVal, success] = Palladium.Utilities.GUIUtils.ToDoubleArrayFromScalarString(val);

            if ~success
                error("doubleArrayValueChangedError:ConversionFailed", "%s", "Conversion of text to double array failed: " + string(val));
            end

            comp.Instrument.(varName) = arrayVal;
        end

        function textValueChanged(comp, varName,val)
            comp.Instrument.(varName) = val;
        end

        function numValueChanged(comp, varName,val)
            comp.Instrument.(varName) = val;
        end

        function funcValueChanged(comp, varName, val)
            comp.Instrument.(varName) = val;
        end
    end


    methods (Static, Access = private)

        %% CreateWidgetByPropertyType
        function widget = CreateWidgetByPropertyType(value, parent, obj, propertyName, tooltipString)

            fontName = "Georgia";
            fontSize = 14;

            % Choose a type of widget to use based on the class of the object's
            % property value.
            if isenum(value)
                %Enumerations
                [~,s] = enumeration(value);
                
                %Special case for Connection_Type
                if strcmp(propertyName, "Connection_Type")
                    s = string(obj.Instrument.GetSupportedConnectionTypes());
                end

                widget = uidropdown(parent, 'Value', Palladium.Utilities.GUIUtils.ToScalarString(value), 'Items', s, 'Tooltip', tooltipString, 'ValueChangedFcn',@(wdgt,event) obj.enumValueChanged(propertyName,wdgt.Value), "FontName", fontName, "FontSize", fontSize);
            elseif iscategorical(value)
                %Categoricals
                s = categories(value);
                widget = uidropdown(parent, 'Value', string(value), 'Items', s, 'Tooltip', tooltipString, 'ValueChangedFcn',@(wdgt,event) obj.categoricalValueChanged(propertyName, wdgt.Value, s), "FontName", fontName, "FontSize", fontSize);
            elseif isscalar(value) && isnumeric(value) && rem(value, 1) == 0
                % Integer values. Note we want to avoid using actual integer types, as
                % these are incompatible with smooth().
                widget = uispinner(parent, 'Value', double(value), 'Tooltip', tooltipString, 'ValueChangedFcn',@(wdgt,event) obj.numValueChanged(propertyName,wdgt.Value), "FontName", fontName, "FontSize", fontSize);
                widget.HorizontalAlignment = 'center';
            elseif isnumeric(value) && isscalar(value)
                widget = uieditfield(parent, 'numeric', 'Value', value, 'Tooltip', tooltipString, 'ValueChangedFcn',@(wdgt,event) obj.numValueChanged(propertyName,wdgt.Value), "FontName", fontName, "FontSize", fontSize);
                widget.HorizontalAlignment = 'center';
            elseif isnumeric(value)
                %Numeric array. turn into string, but give event to
                %un-string it later
                widget = uieditfield(parent, 'Value', Palladium.Utilities.GUIUtils.ToScalarString(value), 'Tooltip', tooltipString, 'ValueChangedFcn',@(wdgt,event) obj.doubleArrayValueChanged(propertyName,wdgt.Value), "FontName", fontName, "FontSize", fontSize);
            elseif islogical(value)
                widget = uicheckbox(parent, 'Value', value, 'Tooltip', tooltipString, 'Text', '', 'ValueChangedFcn',@(wdgt,event) obj.boolValueChanged(propertyName,wdgt.Value), "FontName", fontName, "FontSize", fontSize);
            elseif class(value) == "function_handle"
                value = func2str(value);
                widget = uieditfield(parent, 'Value', string(value), 'Tooltip', tooltipString, 'ValueChangedFcn',@(wdgt,event) obj.funcValueChanged(propertyName,wdgt.Value), "FontName", fontName, "FontSize", fontSize);
                widget.HorizontalAlignment = 'center';
            else
                widget = uieditfield(parent, 'Value', Palladium.Utilities.GUIUtils.ToScalarString(value), 'Tooltip', tooltipString, 'ValueChangedFcn',@(wdgt,event) obj.textValueChanged(propertyName,wdgt.Value), "FontName", fontName, "FontSize", fontSize);
                widget.HorizontalAlignment = 'center';
            end
        end

        %% GetRowGridFormat
        function rowsStringCell = GetRowGridFormat(noRows, rowHgt)
            rowsStringCell = {rowHgt, rowHgt};
            for i = 2 : noRows
                rowsStringCell = [rowsStringCell, rowHgt]; %#ok<AGROW>
            end
            rowsStringCell = [rowsStringCell, '1x'];
        end

        %% GetColumnGridFormat
        function colsStringCell = GetColumnGridFormat(noCols, labelWdth, spacingWdth)
            colsStringCell = {labelWdth, '1x'};
            for i = 2 : noCols
                colsStringCell = [colsStringCell, spacingWdth, labelWdth, '1x']; %#ok<AGROW>
            end

            colsStringCell = [colsStringCell, 0];
        end

        %% GetLowerColumnGridFormat
        function colsStringCell = GetLowerColumnGridFormat(noCols, btnWdth, spacingWdth)
            colsStringCell = {'1x', btnWdth, btnWdth};
            for i = 2 : noCols
                colsStringCell = [colsStringCell, spacingWdth, colsStringCell]; %#ok<AGROW>
            end

            colsStringCell = [colsStringCell, 0];
        end
    end

    methods (Access = public)

        %% LockInput
        function LockInput(comp)
            comp.MainPanel.Enable = false;
            if isgraphics(comp.ActionsPanel)
                comp.ActionsPanel.Enable = false;
            end
            comp.LowerPanel.Enable = false;
        end

        %% OnControlDisabled
        function OnControlDisabled(comp, controlName, instRef)

            %A check first - we could be adding Controls programmatically
            %to Instruments other than the one currently displayed. Don't
            %do anything if that is the case
            if instRef ~= comp.Instrument
                return;
            end


            %Retrieve the ControlDetailsStruct of this name
            cds = comp.GetControlDetailsStructFromName(controlName);

            if isempty(cds)
                return;
            end

            %Set the button colours to show change
            cds.btnOn.BackgroundColor = comp.ButtonColour_DarkGrey;
            cds.btnOff.BackgroundColor = comp.ButtonColour_Red;
        end

        %% OnControlEnabled
        function OnControlEnabled(comp, controlName, instRef)

            %A check first - we could be adding Controls programmatically
            %to Instruments other than the one currently displayed. Don't
            %do anything if that is the case
            if instRef ~= comp.Instrument
                return;
            end


            %Retrieve the ControlDetailsStruct of this name
            cds = comp.GetControlDetailsStructFromName(controlName);

            if isempty(cds)
                return;
            end

            %Set the button colours to show change
            cds.btnOn.BackgroundColor = comp.ButtonColour_Green;
            cds.btnOff.BackgroundColor = comp.ButtonColour_DarkGrey;
        end

        %% OpenInstrument
        function OpenInstrument(comp, instClassRef)
            %Unsubscribe from events of the previous Instrument, if it
            %exists
            try
                if ~isempty(comp.InstrumentEventListener)
                    delete(comp.InstrumentEventListener);
                    comp.InstrumentEventListener = [];
                end
            catch err
                Palladium.Logging.Logger.Log("Warning", "Error in InstrumentOptionsPanel.OpenInstrument: " + string(err.message));
            end

            %Set the properties
            comp.Instrument = instClassRef;

            %Subscribe to event for instrument properties changing
            %(programmtically presumably - update GUI in response)
            if ~isempty(instClassRef)%Will be empty if we just removed the last instrument, this line will error
                comp.InstrumentEventListener = Palladium.Core.ErrorGuard.AddListener(comp.Instrument, "PropertyChanged", @(s,e)comp.OnInstrumentPropertyChanged(e));
            end

            %Update GUI to show this new Instrument
            comp.RefreshControls();
        end

        %% OnInstrumentPropertyChanged
        function OnInstrumentPropertyChanged(comp, evnt)
            if comp.Instrument == evnt.AffectedObject
                comp.RefreshControls();
            else
                Palladium.Logging.Logger.Log("Warning", "Instrument mismatch in InstrumentOptionsPanel");
            end
        end

        %% RefreshControls
        function RefreshControls(comp)
            if ~comp.MainPanel.Enable   %This prevents loads of costly redraws as the instrument is being first created, with its properties changing repeatedly
                return;
            end

            if isempty(comp.Instrument)
                return;
            end

            %Grab the list of all simple properties to create widgets for
            comp.PropertyList = Palladium.Utilities.GUIUtils.ComputePropertyList(comp.Instrument, comp.ExposeSubclassProperties);

            %And get all the details we need to create buttons for the
            %optional controls we can also add
            comp.ControlDetailsStructs = comp.Instrument.GetAvailableControlOptions();

            %Build the GUI
            comp.CreateWidgets();
        end

        %% UnlockInput
        function UnlockInput(comp)
            comp.MainPanel.Enable = true;
            if isgraphics(comp.ActionsPanel)
                comp.ActionsPanel.Enable = true;
            end
            comp.LowerPanel.Enable = true;
            comp.RefreshControls(); %This, and a 'don't draw controls if they are locked' check, prevents loads of costly redraws as the instrument is being first created, with its properties changing repeatedly
        end
    end


    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 547 802];
            comp.GridLayout = uigridlayout(comp);
            comp.InstrumentSettingsLabel = uilabel(comp.GridLayout);
            comp.InstrumentSettingsLabel.Text = 'Instrument Settings';
            comp.MainPanel = uipanel(comp.GridLayout);
            comp.MainPanel.Enable = 'off';
            comp.MainLayout = uigridlayout(comp.MainPanel);
            comp.AddInstrumentControlsLabel = uilabel(comp.GridLayout);
            comp.AddInstrumentControlsLabel.Text = 'Add Instrument Controls';
            comp.LowerPanel = uipanel(comp.GridLayout);
            comp.LowerLayout = uigridlayout(comp.LowerPanel);
        end
    end
end