classdef NewPlotButtonsPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        NewDisplayLabel       matlab.ui.control.Label
        NewDisplayButton      matlab.ui.control.Button
        NewWindow_1_2_Button  matlab.ui.control.Button
        NewTab_1_2_Button     matlab.ui.control.Button
        NewWindow_2_2_Button  matlab.ui.control.Button
        NewWindow_2_1_Button  matlab.ui.control.Button
        NewWindow_1_1_Button  matlab.ui.control.Button
        NewTab_2_2_Button     matlab.ui.control.Button
        NewTab_2_1_Button     matlab.ui.control.Button
        NewTab_1_1_Button     matlab.ui.control.Button
        NewWindowLabel        matlab.ui.control.Label
        NewTabLabel           matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        NewBigNumberDisplayClicked
        NewTabButtonClicked
        NewWindowButtonClicked
    end

    
    methods (Access = public)
        
        % Called when Measurement Start is called in the main loop 
        function OnStart(comp)
            comp.NewDisplayButton.Enable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewWindow_2_2_Button
        function NewWindow_2_2_ButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(2, 2);
                notify(comp, 'NewWindowButtonClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end

        % Button pushed function: NewWindow_2_1_Button
        function NewWindow_2_1_ButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(2, 1);
                notify(comp, 'NewWindowButtonClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end

        % Button pushed function: NewWindow_1_1_Button
        function NewWindow_1_1_ButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(1, 1);
                notify(comp, 'NewWindowButtonClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end

        % Button pushed function: NewTab_2_2_Button
        function NewTab_2_2_ButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(2, 2);
                notify(comp, 'NewTabButtonClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end

        % Button pushed function: NewTab_2_1_Button
        function NewTab_2_1_ButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(2, 1);
                notify(comp, 'NewTabButtonClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end

        % Button pushed function: NewTab_1_1_Button
        function NewTab_1_1_ButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(1, 1);
                notify(comp, 'NewTabButtonClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end

        % Button pushed function: NewTab_1_2_Button
        function NewTab_1_2_ButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(1, 2);
                notify(comp, 'NewTabButtonClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end

        % Button pushed function: NewWindow_1_2_Button
        function NewWindow_1_2_ButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(1, 2);
                notify(comp, 'NewWindowButtonClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end

        % Button pushed function: NewDisplayButton
        function NewDisplayButtonPushed(comp, event)
            try
                eventData = Palladium.Events.AddPlottersEventData(1, 2);
                notify(comp, 'NewBigNumberDisplayClicked', eventData);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in New Plot Panel Button Pushed Callback", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 686 32];
            comp.GridLayout = uigridlayout(comp);
            comp.NewTabLabel = uilabel(comp.GridLayout);
            comp.NewTabLabel.Text = 'New 📈 Tab:';
            comp.NewWindowLabel = uilabel(comp.GridLayout);
            comp.NewWindowLabel.Text = ' New 📈 Window:';
            comp.NewTab_1_1_Button = uibutton(comp.GridLayout, 'push');
            comp.NewTab_1_1_Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewTab_1_1_ButtonPushed, true);
            comp.NewTab_1_1_Button.Icon = fullfile(pathToMLAPP, 'Graphics', '1x1Icon.png');
            comp.NewTab_1_1_Button.Tooltip = {'Add new 1x1 Plotting Tab'};
            comp.NewTab_1_1_Button.Text = '';
            comp.NewTab_2_1_Button = uibutton(comp.GridLayout, 'push');
            comp.NewTab_2_1_Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewTab_2_1_ButtonPushed, true);
            comp.NewTab_2_1_Button.Icon = fullfile(pathToMLAPP, 'Graphics', '2x1Icon.png');
            comp.NewTab_2_1_Button.Tooltip = {'Add new 2x1 Plotting Tab'};
            comp.NewTab_2_1_Button.Text = '';
            comp.NewTab_2_2_Button = uibutton(comp.GridLayout, 'push');
            comp.NewTab_2_2_Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewTab_2_2_ButtonPushed, true);
            comp.NewTab_2_2_Button.Icon = fullfile(pathToMLAPP, 'Graphics', '2x2Icon.png');
            comp.NewTab_2_2_Button.Tooltip = {'Add new 2x2 Plotting Tab'};
            comp.NewTab_2_2_Button.Text = '';
            comp.NewWindow_1_1_Button = uibutton(comp.GridLayout, 'push');
            comp.NewWindow_1_1_Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewWindow_1_1_ButtonPushed, true);
            comp.NewWindow_1_1_Button.Icon = fullfile(pathToMLAPP, 'Graphics', '1x1Icon.png');
            comp.NewWindow_1_1_Button.Tooltip = {'Add new 1x1 Plotting Window'};
            comp.NewWindow_1_1_Button.Text = '';
            comp.NewWindow_2_1_Button = uibutton(comp.GridLayout, 'push');
            comp.NewWindow_2_1_Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewWindow_2_1_ButtonPushed, true);
            comp.NewWindow_2_1_Button.Icon = fullfile(pathToMLAPP, 'Graphics', '2x1Icon.png');
            comp.NewWindow_2_1_Button.Tooltip = {'Add new 2x1 Plotting Window'};
            comp.NewWindow_2_1_Button.Text = '';
            comp.NewWindow_2_2_Button = uibutton(comp.GridLayout, 'push');
            comp.NewWindow_2_2_Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewWindow_2_2_ButtonPushed, true);
            comp.NewWindow_2_2_Button.Icon = fullfile(pathToMLAPP, 'Graphics', '2x2Icon.png');
            comp.NewWindow_2_2_Button.Tooltip = {'Add new 2x2 Plotting Window'};
            comp.NewWindow_2_2_Button.Text = '';
            comp.NewTab_1_2_Button = uibutton(comp.GridLayout, 'push');
            comp.NewTab_1_2_Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewTab_1_2_ButtonPushed, true);
            comp.NewTab_1_2_Button.Icon = fullfile(pathToMLAPP, 'Graphics', '1x2Icon.png');
            comp.NewTab_1_2_Button.Tooltip = {'Add new 1x2 Plotting Tab'};
            comp.NewTab_1_2_Button.Text = '';
            comp.NewWindow_1_2_Button = uibutton(comp.GridLayout, 'push');
            comp.NewWindow_1_2_Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewWindow_1_2_ButtonPushed, true);
            comp.NewWindow_1_2_Button.Icon = fullfile(pathToMLAPP, 'Graphics', '1x2Icon.png');
            comp.NewWindow_1_2_Button.Tooltip = {'Add new 1x2 Plotting Window'};
            comp.NewWindow_1_2_Button.Text = '';
            comp.NewDisplayButton = uibutton(comp.GridLayout, 'push');
            comp.NewDisplayButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewDisplayButtonPushed, true);
            comp.NewDisplayButton.Icon = fullfile(pathToMLAPP, 'Graphics', 'BigNumDisplayIcon.png');
            comp.NewDisplayButton.Enable = 'off';
            comp.NewDisplayButton.Tooltip = {'Create a new Big Number Display window'};
            comp.NewDisplayButton.Text = '';
            comp.NewDisplayLabel = uilabel(comp.GridLayout);
            comp.NewDisplayLabel.Text = 'New Display:';
        end
    end
end