classdef PlotterPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        yMaxEditField       matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        yMinEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel     matlab.ui.control.Label
        GridLayout6         matlab.ui.container.GridLayout
        LogyCheckBox        matlab.ui.control.CheckBox
        LogxCheckBox        matlab.ui.control.CheckBox
        xMaxEditField       matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        xMinEditField       matlab.ui.control.NumericEditField
        xEditFieldLabel     matlab.ui.control.Label
        GridLayout4         matlab.ui.container.GridLayout
        SavePlotButton      matlab.ui.control.Button
        CopyPlotButton      matlab.ui.control.Button
        GridLayout3         matlab.ui.container.GridLayout
        AxesToggleButton_4  matlab.ui.control.Button
        AxesToggleButton_3  matlab.ui.control.Button
        AxesToggleButton_2  matlab.ui.control.Button
        AxesToggleButton_1  matlab.ui.control.Button
        YAxisDropDown_4     matlab.ui.control.DropDown
        YAxisDropDown_3     matlab.ui.control.DropDown
        YAxisDropDown_2     matlab.ui.control.DropDown
        YAxisDropDown_1     matlab.ui.control.DropDown
        YAxesLabel          matlab.ui.control.Label
        XAxisLabel          matlab.ui.control.Label
        XAxisDropDown       matlab.ui.control.DropDown
        UIAxes              matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        AxesSelectionChange
        SavePlot
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        PlotRefreshNeeded (1,1) logical = false;
        SelectedXAxisIndex (1,1) double = 0;
        SelectedXAxisString (1,1) string
        SelectedYAxisIndices (4,1) double = [0;0;0;0;];
        SelectedYAxisStrings (4,1) string
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Colours (4,3) double {mustBeLessThanOrEqual(Colours, 1), mustBeGreaterThanOrEqual(Colours, 0)} = [1 0 0; 0 0 1; 0 0.6 0; 1 0 1];
        FontSize (1,1) double {mustBePositive(FontSize), mustBeInteger(FontSize)} = 20;
        LineStyles (4,1) string = ["None", "None", "None", "None"];
        LineWidth (1,1) double {mustBePositive(LineWidth)} = 1;
        MarkerSize (1,1) double {mustBePositive(MarkerSize), mustBeInteger(MarkerSize), mustBeFinite(MarkerSize)} = 6;
        Markers (4,1) string = ["o"; "o"; "+"; "*"];
        ShowLegends (1,1) logical = true;
    end
    
    properties (Access = private)
        DataLines = gobjects(4);
        DefaultXAxis = [];
        DefaultYAxis1 = [];
        DefaultYAxis2 = [];
        DefaultYAxis3 = [];
        DefaultYAxis4 = [];
        PlotOnRHS (4,1) logical = [false false false false]; % Array of bools specifying which, if any, of the curves should be plotted on the right hand y axes
        PlotSettings = [];
        StartTimeMins (1,1) double = 0;
        XAxisIsTime (1,1) logical = false;
    end
    
    properties (Access = private, Constant)
        EmptyAxisOptionString string = "<None>";
        LHSText = "◀";
        RHSText = "▶";
    end
    
    properties (Access = public)
        TitleForCopiedPlots = []; % Set to non-empty value (string) to cause figures generated by hitting Copy Plot or Save Plot to have this title. Can be a cell array of strings for mutli-line
    end
    

    methods (Access = private)

        %% AxesSelectionChanged
        function AxesSelectionChanged(comp)
            %Trigger event
            notify(comp, 'AxesSelectionChange');
            comp.OnAxesSelectionChanged();
        end

        %% CleanDisplayName
        function newName = CleanDisplayName(~, name)
            %Make a column header show as typed in the LaTeX-interpreted
            %labels and legend - escapes _ % & etc, and Greek letters (e.g. Ω)
            newName = Palladium.Utilities.GUIUtils.EscapeLaTeX(name);
        end

        %% CopyPlot
        function [figRef, newAx] = CopyPlot(comp)
            preserveZoomLevel = true;

            %Grab reference
            ax = comp.UIAxes;
            xlimits = ax.XLim;
            ylimits = ax.YLim;

            %Create a new figure
            figRef = figure('Position', [100 100 1000 700]);
            newAx = axes(figRef, 'Box', 'on');

            %Add a 2nd y axis on the right
            yyaxis(newAx, "right");
            newAx.YAxis(1).Color = 'k'; % change color of LHS y-axis to black
            newAx.YAxis(2).Color = 'k'; % change color of RHS y-axis to black
            newAx.YAxis(2).MinorTick = 'on';
            newAx.YAxis(1).TickValues = [];
            newAx.YAxis(2).TickValues = [];

            %Hide the figure so it doesnt jump around horribly as it
            %updates
            figRef.Visible = 'off';

            hold on;
            dataLines = comp.DataLines;

            for i = 1 : size(dataLines, 1)
                for j = 1 : size(dataLines, 2) %This second loop will normally only run once - DataLines is only a 2D array if we did a multiplot from the DataViewer, several datafiles at once
                    %Only consider data sets which are not empty, i.e. are
                    %proper populated LineDatas, not GraphicsPlaceholders
                    if(isgraphics(dataLines(i, j)))

                        %Select which axes, right or left, to plot the Y data
                        %on
                        if(comp.PlotOnRHS(i))
                            yyaxis(newAx, "right");
                            newAx.YAxis(2).TickValuesMode = "auto";
                        else
                            yyaxis(newAx, "left");
                            newAx.YAxis(1).TickValuesMode = "auto";
                        end

                        %Extract data from axes and plot in new figure
                        [xdata, ydata] = getpoints(dataLines(i, j));
                        line = plot(newAx, xdata, ydata, 'Marker', dataLines(i, j).Marker, 'LineStyle', dataLines(i, j).LineStyle, 'Color', dataLines(i, j).Color);
                        line.DisplayName = dataLines(i, j).DisplayName;
                    end
                end
            end

            %Add a legend - plots have names now
            lgd = legend;
            lgd.Box = 'off';
            lgd.Interpreter = "latex";

            %Copy axes labels
            figRef.CurrentAxes.XLabel.String = comp.CleanDisplayName(comp.SelectedXAxisString);
            figRef.CurrentAxes.XLabel.FontSize = ax.XLabel.FontSize;
            figRef.CurrentAxes.YLabel.FontSize = ax.YLabel.FontSize;

            figRef.CurrentAxes.FontSize = comp.FontSize;

            %Copy if axes are log or linear
            newAx.XScale = ax.XScale;
            newAx.YScale = ax.YScale;

            figRef.CurrentAxes.XLabel.Interpreter = "latex";

            %Set zoom / axes limits
            if(preserveZoomLevel)
                figRef.CurrentAxes.XLimMode = "Manual";
                figRef.CurrentAxes.YLimMode = "Manual";
                figRef.CurrentAxes.XLim = xlimits;
                figRef.CurrentAxes.YLim = ylimits;
            end

            %If we are only plotting one y axis value (ie just looking at
            %Temperature vs Time) then we can actually label the y axis..
            [numSeries, displayName] = comp.GetNumberOfSeriesDisplayed();
            if numSeries == 1
                ylabString = comp.CleanDisplayName(string(displayName));
                figRef.CurrentAxes.YLabel.String = ylabString;
                figRef.CurrentAxes.YLabel.Interpreter = "latex";
            end

            %Add a title, if one has been set
            if ~isempty(comp.TitleForCopiedPlots)
                title(comp.TitleForCopiedPlots, "Interpreter", "none");
            end

            %Set to visible again now its done
            figRef.Visible = 'on';
            hold off;
        end

        %% GetNumberOfSeriesDisplayed
        function [numSeries, displayName] = GetNumberOfSeriesDisplayed(comp)
            %Returns how many of the y axes dropdowns are set to a
            %value other than <None>. Note this is seperate from
            %the DataViewer Overplotting functionality - three
            %overplotted SampleTemperature vs Time plots, all in
            %red, would give a value of 1 here.
            %Additionally returns the last non-empty y axis name - handy if
            %we are handling cases where exactly one axis is shown..

            %Start with zero
            numSeries = 0;
            displayName = [];

            %Loop over the 4 y axis dropdowns
            for i = 1 : 4
                if comp.SelectedYAxisIndices(i) ~= 0
                    %Add one to the count each time we find one which is
                    %non-<None>
                    numSeries = numSeries + 1;
                    displayName = comp.SelectedYAxisStrings(i);
                end
            end
        end

        %% UpdateAxesOptionsDropDowns
        function UpdateAxesOptionsDropDowns(comp, xAxisOptions, yAxisOptions)

            %Make sure the options arrays are 1 column, multirow, rather
            %than the other way round
            xAxisOptions = reshape(xAxisOptions, [], 1);
            yAxisOptions = reshape(yAxisOptions, [], 1);

            nullOption = comp.EmptyAxisOptionString;
            comp.XAxisDropDown.Items = [nullOption; xAxisOptions];

            yAxOptions = yAxisOptions;
            comp.YAxisDropDown_1.Items = [nullOption; yAxOptions];
            comp.YAxisDropDown_2.Items = [nullOption; yAxOptions];
            comp.YAxisDropDown_3.Items = [nullOption; yAxOptions];
            comp.YAxisDropDown_4.Items = [nullOption; yAxOptions];
        end

        %% GenerateMultiPlotColour
        function colour = GenerateMultiPlotColour(~, originalColour, index, totalNumber)
            % When overplotting multiple data series using
            % PlotMultipleData, we want to automatically generate a set of
            % marker colours obviously derived from the original colour but
            % distinguishable from each other

            d = [1 1 1] - originalColour;

            colour = originalColour + (index-1) * d / totalNumber;  %the (index-1) is to make sure we stop one step before getting to [1 1 1] - white. White will be invisible!
            colour = min(colour, 1);
        end

        %% YAxisSelectionChanged
        function YAxisSelectionChanged(comp, value, yAxisIndex, indexOfOption)
            arguments
                comp;
                value           {mustBeTextScalar};
                yAxisIndex      (1,1) {mustBeInteger};
                indexOfOption   (1,1) {mustBeInteger};
            end

            if(strcmp(value, comp.EmptyAxisOptionString))
                comp.SelectedYAxisStrings(yAxisIndex) = "";
                comp.SelectedYAxisIndices(yAxisIndex) = 0;
            else
                comp.SelectedYAxisStrings(yAxisIndex) = value;
                comp.SelectedYAxisIndices(yAxisIndex) = indexOfOption - 1;
            end

            %Notify that something has changed and we need to update the
            %GUI
            comp.AxesSelectionChanged();
        end

        %% UpdateXLimits
        function UpdateXLimits(comp)
            minValue = comp.xMinEditField.Value;
            maxValue = comp.xMaxEditField.Value;

            comp.XLimChanged(minValue, maxValue);
        end

        %% UpdateYLimits
        function UpdateYLimits(comp)
            minValue = comp.yMinEditField.Value;
            maxValue = comp.yMaxEditField.Value;

            comp.YLimChanged(minValue, maxValue);
        end

        %% XLimChanged
        function XLimChanged(comp, xMin, xMax)
            xlim(comp.UIAxes, [xMin, xMax]);
        end

        %% YLimChanged
        function YLimChanged(comp, yMin, yMax)
            ylim(comp.UIAxes, [yMin, yMax]);
        end
    end

    methods (Access = public)

        %% Apply Settings
        function ApplySettings(comp, plotSettings)
            %Apply settings loaded in from config file, immediately after
            %component creation
            comp.PlotSettings = plotSettings;

            %Only show Legends on Large (1x1) plotters
            if(plotSettings.Size ~= "Large")
                plotSettings.ShowLegends = false;
            end

            %Settings file could not be saved with 2D array of colours as desired,
            %reshape the colours array from 1D to 2D to match the Property here
            plotSettings.Colours = reshape(plotSettings.Colours, [3,4])';

            comp.Colours = plotSettings.Colours;
            comp.FontSize = plotSettings.FontSize;
            comp.LineStyles = plotSettings.LineStyles;
            comp.LineWidth = plotSettings.LineWidth;
            comp.MarkerSize = plotSettings.MarkerSize;
            comp.Markers = plotSettings.Markers;
            comp.ShowLegends = plotSettings.ShowLegends;
        end

        %% AttachContextMenu
        function AttachContextMenu(comp, fig)

            axesContextMenu = uicontextmenu(fig);
            comp.UIAxes.ContextMenu = axesContextMenu;
            m1 = uimenu(axesContextMenu, "Text", "test");
        end

        %% ClearData
        function ClearData(comp)
            %Reinitialise DataLines array (deleting will happen with the
            %cla commands below)
            comp.DataLines = gobjects(4,1);

            %Clear both right and left axes
            yyaxis(comp.UIAxes, "right");
            cla(comp.UIAxes);
            comp.UIAxes.YAxis(2).TickValues = [];
            yyaxis(comp.UIAxes, "left");
            cla(comp.UIAxes);
            comp.UIAxes.YAxis(1).TickValues = [];

            %Also reset previously set x and y limits (via manual zooming)
            %- experimental!
            xlim(comp.UIAxes, "auto");
            ylim(comp.UIAxes, "auto");

            %Force a GUI update
            drawnow();
        end

        %% GetDefaultAxes
        function [xAxis, yAxesArray] = GetDefaultAxes(comp)
            if ~isempty(comp.DefaultXAxis)
                xAxis = string(comp.DefaultXAxis);
            else
                if comp.SelectedXAxisIndex ~= 0
                    xAxis = string(comp.XAxisDropDown.Value);
                else
                    xAxis = string.empty;
                end
            end

            if ~isempty(comp.DefaultYAxis1)
                yAxesArray = string(comp.DefaultYAxis1);
            else
                if comp.SelectedYAxisIndices(1) ~= 0
                    yAxesArray = string(comp.YAxisDropDown_1.Value);
                else
                    yAxesArray = "";
                end
            end
            if ~isempty(comp.DefaultYAxis2)
                yAxesArray = [yAxesArray, string(comp.DefaultYAxis2)];
            else
                if comp.SelectedYAxisIndices(2) ~= 0
                    yAxesArray = [yAxesArray, string(comp.YAxisDropDown_2.Value)];
                else
                    yAxesArray = [yAxesArray, ""];
                end
            end
            if ~isempty(comp.DefaultYAxis3)
                yAxesArray = [yAxesArray, string(comp.DefaultYAxis3)];
            else
                if comp.SelectedYAxisIndices(3) ~= 0
                    yAxesArray = [yAxesArray, string(comp.YAxisDropDown_3.Value)];
                else
                    yAxesArray = [yAxesArray, ""];
                end
            end
            if ~isempty(comp.DefaultYAxis4)
                yAxesArray = [yAxesArray, string(comp.DefaultYAxis4)];
            else
                if comp.SelectedYAxisIndices(4) ~= 0
                    yAxesArray = [yAxesArray, string(comp.YAxisDropDown_4.Value)];
                else
                    yAxesArray = [yAxesArray, ""];
                end
            end
        end

        %% OnAxesSelectionChanged
        function OnAxesSelectionChanged(comp)
            comp.PlotRefreshNeeded = true;
        end

        %% PlotData
        function PlotData(comp, dataArray)
            %DataArray expected to be any number of rows, but a number of
            %columns equal to the number of axis options. A column of data
            %will be selected based on the indices selected for each axis
            %option

            %We are performing the refresh here, can clear this flag now
            comp.PlotRefreshNeeded = false;

            %Start by clearing everything
            comp.ClearData();

            %Return if the data are empty
            if isempty(dataArray)
                return;
            end

            %Flag for faster rendering
            comp.UIAxes.SortMethod = "childorder";
            comp.UIAxes.FontSize = comp.FontSize;

            %No plotting if no x axis selected
            if(comp.SelectedXAxisIndex == 0); return; end

            %Check - this handles the case where we make a plot with the
            %data columns of an instrument, then remove it and run the
            %programme with the y axis dropdowns unchanged - getting an
            %index out of range error as that selection is now a larger
            %index than the number of columns. Reset the selection in that
            %case
            noCols = size(dataArray,2);
            for i = 1 : 4
                if comp.SelectedYAxisIndices(i) > noCols
                    comp.SelectedYAxisIndices(i) = 0;
                end
            end

            %Special case if x axis is Time(mins) - plot as relative rather
            %than absolute values
            comp.XAxisIsTime = strcmp(comp.SelectedXAxisString, "Time (mins)");
            if(comp.XAxisIsTime)
                comp.StartTimeMins = min(dataArray(:, comp.SelectedXAxisIndex));
                startT = comp.StartTimeMins;
            end

            %Plot each y series in turn, if one is selected for each
            %dropdown
            hold(comp.UIAxes, "on");
            xIndex = comp.SelectedXAxisIndex;
            for i = 1 : 4
                yIndex = comp.SelectedYAxisIndices(i);
                if(yIndex ~= 0)
                    displayName = comp.CleanDisplayName(comp.SelectedYAxisStrings(i));

                    %Select which axes, right or left, to plot the Y data
                    %on
                    if(comp.PlotOnRHS(i))
                        yyaxis(comp.UIAxes, "right");
                        comp.UIAxes.YAxis(2).TickValuesMode = "auto";
                    else
                        yyaxis(comp.UIAxes, "left");
                        comp.UIAxes.YAxis(1).TickValuesMode = "auto";
                    end

                    %Note - we use animatedline objects instead of the
                    %normal 'plot' command for performance when adding
                    %points to a graph with a large number of points on
                    if(comp.XAxisIsTime)
                        comp.DataLines(i) = animatedline(comp.UIAxes, dataArray(:, xIndex) - startT, dataArray(:, yIndex), 'Color', comp.Colours(i, :),...
                            'Marker', comp.Markers(i), 'LineStyle', comp.LineStyles(i), 'MarkerSize', comp.MarkerSize, 'LineWidth', comp.LineWidth,...
                            'PickableParts', 'none', 'DisplayName', displayName);
                    else
                        comp.DataLines(i) = animatedline(comp.UIAxes, dataArray(:, xIndex), dataArray(:, yIndex), 'Color', comp.Colours(i, :),...
                            'Marker', comp.Markers(i), 'LineStyle', comp.LineStyles(i), 'MarkerSize', comp.MarkerSize, 'LineWidth', comp.LineWidth,...
                            'PickableParts', 'none', 'DisplayName', displayName);
                    end
                end
            end
            hold(comp.UIAxes, "off");

            %Label the x axis (we can't label the y axis as it can have
            %several different things plotted together on it)
            xlabel(comp.UIAxes, comp.CleanDisplayName(comp.SelectedXAxisString), "Interpreter", "latex");

            %Show Legend, if desired
            if comp.ShowLegends
                legend(comp.UIAxes, 'FontSize', 12, 'Color', 'none', 'Box', 'off');
            end

            %Update axis limits
            comp.UpdateXLimits();
            comp.UpdateYLimits();
        end

        %% PlotMultipleData
        function PlotMultipleData(comp, dataArraysInACell, displayNamesStrArray)
            %This rare call is used by the DataViewer when plotting data from several files on top of each other.
            %For performance reasons, PlotData is seperate and not just a special case of this call. Normal plotting does not call this.
            %Input is a cell array of DataArrays, each expected to be any number of rows, but a number of
            %columns equal to the number of axis options. A column of data
            %will be selected based on the indices selected for each axis
            %option

            %We are performing the refresh here, can clear this flag now
            comp.PlotRefreshNeeded = false;

            %Start by clearing everything
            comp.ClearData();

            %Flag for faster rendering
            comp.UIAxes.SortMethod = "childorder";
            comp.UIAxes.FontSize = comp.FontSize;

            %No plotting if no x axis selected
            if(comp.SelectedXAxisIndex == 0); return; end

            %Prefetch some things
            numOfDataSeries = length(dataArraysInACell);

            %Special case if x axis is Time(mins) - plot as relative rather
            %than absolute values
            comp.XAxisIsTime = strcmp(comp.SelectedXAxisString, "Time (mins)");
            if(comp.XAxisIsTime)
                %Preallocate for speed
                startT = nan(numOfDataSeries,1);
                for j = 1 : numOfDataSeries
                    dataArray = dataArraysInACell{j};
                    comp.StartTimeMins = min(dataArray(:, comp.SelectedXAxisIndex));
                    startT(j) = comp.StartTimeMins;
                end
            end

            %Clean each display name - remove Latex escape characters
            for j = 1 : numOfDataSeries
                displayNamesStrArray(j) = comp.CleanDisplayName(displayNamesStrArray(j));
            end

            %Plot each y series in turn, if one is selected for each
            %dropdown
            hold(comp.UIAxes, "on");
            xIndex = comp.SelectedXAxisIndex;
            for i = 1 : 4
                yIndex = comp.SelectedYAxisIndices(i);
                if(yIndex ~= 0)
                    %Select which axes, right or left, to plot the Y data
                    %on
                    if(comp.PlotOnRHS(i))
                        yyaxis(comp.UIAxes, "right");
                        comp.UIAxes.YAxis(2).TickValuesMode = "auto";
                    else
                        yyaxis(comp.UIAxes, "left");
                        comp.UIAxes.YAxis(1).TickValuesMode = "auto";
                    end

                    for j = 1 : numOfDataSeries
                        %Pull out array to plot, store in temporary
                        %variable (this might be slow? Could optimise this
                        %by avoiding this copy?)
                        dataArray = dataArraysInACell{j};

                        %Auto generate the marker colour to use
                        markerColour = comp.GenerateMultiPlotColour(comp.Colours(i, :), j, numOfDataSeries);

                        %Note - we use animatedline objects instead of the
                        %normal 'plot' command for performance when adding
                        %points to a graph with a large number of points on
                        if(comp.XAxisIsTime)
                            comp.DataLines(i, j) = animatedline(comp.UIAxes, dataArray(:, xIndex) - startT(j), dataArray(:, yIndex), 'Color', markerColour,...
                                'Marker', comp.Markers(i), 'LineStyle', comp.LineStyles(i), 'MarkerSize', comp.MarkerSize, 'LineWidth', comp.LineWidth,...
                                'PickableParts', 'none', 'DisplayName', displayNamesStrArray(j));
                        else
                            comp.DataLines(i, j) = animatedline(comp.UIAxes, dataArray(:, xIndex), dataArray(:, yIndex), 'Color', markerColour,...
                                'Marker', comp.Markers(i), 'LineStyle', comp.LineStyles(i), 'MarkerSize', comp.MarkerSize, 'LineWidth', comp.LineWidth,...
                                'PickableParts', 'none', 'DisplayName', displayNamesStrArray(j));
                        end
                    end
                end
            end
            hold(comp.UIAxes, "off");

            %Label the x axis (we can't label the y axis as it can have
            %several different things plotted together on it)
            xlabel(comp.UIAxes,  comp.CleanDisplayName(comp.SelectedXAxisString), "Interpreter", "latex");

            %Show Legend, if desired
            if comp.ShowLegends
                legend(comp.UIAxes, 'FontSize', 12, 'Color', 'none', 'Box', 'off');
            end

            %Update axis limits
            comp.UpdateXLimits();
            comp.UpdateYLimits();
        end

        %% ResetPlotter
        function ResetPlotter(comp)
            %Clear the axes, but also reset all options for y and x axes
            %variables etc - used in e.g. DataViewer when de-selecting a
            %file type before selecting a new one

            %X axis variable selections
            comp.SelectedXAxisIndex = 0;
            comp.XAxisDropDown.Value = comp.EmptyAxisOptionString;

            %Y axis variable selections
            for i = 1 : 4
                comp.SelectedYAxisStrings(i) = "";
                comp.SelectedYAxisIndices(i) = 0;
            end
            comp.YAxisDropDown_1.Value = comp.EmptyAxisOptionString;
            comp.YAxisDropDown_2.Value = comp.EmptyAxisOptionString;
            comp.YAxisDropDown_3.Value = comp.EmptyAxisOptionString;
            comp.YAxisDropDown_4.Value = comp.EmptyAxisOptionString;

            %Axes limits - set to auto
            comp.XLimChanged(-inf, inf);
            comp.YLimChanged(-inf, inf);

            %And clear data too
            comp.ClearData();
        end

        %% SetDefaultXAxis
        function SetDefaultXAxis(comp, xAxisString)
            comp.DefaultXAxis = xAxisString;
        end

        %% SetDefaultYAxis
        function SetDefaultYAxis(comp, index, yAxisString)
            arguments
                comp;
                index {mustBeInteger, mustBeBetween(index, 1, 4)};
                yAxisString {mustBeTextScalar};
            end

            switch(index)
                case(1)
                    comp.DefaultYAxis1 = yAxisString;
                case(2)
                    comp.DefaultYAxis2 = yAxisString;
                case(3)
                    comp.DefaultYAxis3 = yAxisString;
                case(4)
                    comp.DefaultYAxis4 = yAxisString;
                otherwise
                    error("SetDefaultYAxisError:SettingFailed", "Error setting default y axis in Plotter");
            end
        end

        %% SetDefaultYAxes
        function SetDefaultYAxes(comp, y1, y2, y3, y4)
            comp.DefaultYAxis1 = y1;
            comp.DefaultYAxis2 = y2;
            comp.DefaultYAxis3 = y3;
            comp.DefaultYAxis4 = y4;
        end

        %% SetAxisSide
        function SetAxisSide(comp, index, side)
            %Set whether a plot (1-4) should be plotted ont he right or
            %left hand side axes on the yyaxis plot of the axes
            arguments
                comp;
                index {mustBeInteger, mustBeMember(index, [1,2,3,4])};
                side {mustBeText, mustBeMember(side, ["Right", "Left"])};
            end

            switch(side)
                case("Left")
                    comp.PlotOnRHS(index) = false;
                    text = comp.LHSText;
                case("Right")
                    comp.PlotOnRHS(index) = true;
                    text = comp.RHSText;
            end

            %Update the corresponding little button in the GUI to match the newly set
            %value
            switch(index)
                case(1)
                    comp.AxesToggleButton_1.Text = text;
                case(2)
                    comp.AxesToggleButton_2.Text = text;
                case(3)
                    comp.AxesToggleButton_3.Text = text;
                case(4)
                    comp.AxesToggleButton_4.Text = text;
            end

            %Notify that something has changed and we need to update the
            %GUI
            comp.AxesSelectionChanged();
        end

        %% SetXAxisVariable
        function SetXAxisVariable(comp, xAxisString)
            %Select which variable will be plotted on the x axis
            comp.XAxisDropDown.Value = xAxisString;
            comp.XAxisDropDownValueChanged([]);

            %Notify that something has changed and we need to update the
            %GUI
            comp.AxesSelectionChanged();
        end

        %% SetYAxisVariable
        function SetYAxisVariable(comp, index, yAxisString)
            %First, check that the variable we are asking for is in the
            %list of options - we could probably make this a warning
            %further down the line, as it doesn't really prevent execution,
            %but let's do defensive coding and error now - in theory it
            %shouldn't be possible to have issues here so it shows us
            %something else has gone awry
            assert(any(contains(comp.YAxisDropDown_1.Items, yAxisString)), "SetYAxisVariableError:OptionNotInDropdown", "%s", "Tried to set a Y Axis variable in Plotter, but " + string(yAxisString) + " is not one of the options in the dropdown. They are: " + join(string(comp.YAxisDropDown_1.Items), ", "));

            %Select which variable will be plotted on the y axis - note
            %that between 0 and 4 things are plotted on the y axis, so an
            %index is required to say which of those we are referring to.
            switch(index)
                case(1)
                    comp.YAxisDropDown_1.Value = char(yAxisString);
                    comp.YAxisDropDown_1ValueChanged([]);
                case(2)
                    comp.YAxisDropDown_2.Value = char(yAxisString);
                    comp.YAxisDropDown_2ValueChanged([]);
                case(3)
                    comp.YAxisDropDown_3.Value = char(yAxisString);
                    comp.YAxisDropDown_3ValueChanged([]);
                case(4)
                    comp.YAxisDropDown_4.Value = char(yAxisString);
                    comp.YAxisDropDown_4ValueChanged([]);
                otherwise
                    error("SetYAxisVariableError:InvalidIndex", "Index must be 1-4");
            end

            %Notify that something has changed and we need to update the
            %GUI
            comp.AxesSelectionChanged();
        end

        %% TryAppendData
        function result = TryAppendData(comp, dataLine)
            %This is intended to be much faster than the brute force
            %PlotData and should be called most update loops in its stead.
            %PlotData needs to be called if e.g. we have selected a
            %different x axis.. in this case the 'result' output parameter
            %will return false, and the code that called this should know
            %to now call PlotData (with the whole data array instead of
            %just one line) instead.

            %If we have changed e.g. axes selections, then clear the axes
            %and plot afresh instead of just appending new data to them
            if(comp.PlotRefreshNeeded)
                result = false;
                return;
            else
                result = true;
            end

            %No plotting if no x axis selected
            if(comp.SelectedXAxisIndex == 0); return; end

            %Append a value to each y series in turn, if one is selected for each
            %dropdown
            hold(comp.UIAxes, "on");
            xIndex = comp.SelectedXAxisIndex;
            xData = dataLine(xIndex);

            %Subtract start time for special case of plotting time (mins)
            %on x axis
            if(comp.XAxisIsTime)
                xData = xData - comp.StartTimeMins;
            end

            %Update each plot
            for i = 1 : 4
                yIndex = comp.SelectedYAxisIndices(i);
                if(yIndex ~= 0)
                    %Some more checks for issues
                    if (isempty(comp.DataLines(i)) || ~isgraphics(comp.DataLines(i))) %Is this line a graphics placeholder - don't add to it if it is, we should abort this attempt to addpoints and do a full replot
                        result = false;
                        return;
                    end

                    %If we got here, all is well, an animatedline exists,
                    %and we can add some points to it
                    addpoints(comp.DataLines(i), xData, dataLine(yIndex))
                end
            end

        end

        %% UpdateVariables
        function UpdateVariables(comp, listOfAxesOptionStrings)
            comp.UpdateAxesOptionsDropDowns(listOfAxesOptionStrings, listOfAxesOptionStrings);

            %Set selections to their defaults, if set - Update variables
            %should only really happen once, at Measurement start. Clear
            %the settings so it doesn't happen again, anyway
            if ~isempty(comp.DefaultXAxis)
                comp.SetXAxisVariable(comp.DefaultXAxis);
                comp.DefaultXAxis = [];
            end
            if ~isempty(comp.DefaultYAxis1)
                comp.SetYAxisVariable(1, comp.DefaultYAxis1);
                comp.DefaultYAxis1 = [];
            end
            if ~isempty(comp.DefaultYAxis2)
                comp.SetYAxisVariable(2, comp.DefaultYAxis2);
                comp.DefaultYAxis1 = [];
            end
            if ~isempty(comp.DefaultYAxis3)
                comp.SetYAxisVariable(3, comp.DefaultYAxis3);
                comp.DefaultYAxis1 = [];
            end
            if ~isempty(comp.DefaultYAxis4)
                comp.SetYAxisVariable(4, comp.DefaultYAxis4);
                comp.DefaultYAxis1 = [];
            end

            %Notify that something has changed and we need to update the
            %GUI
            comp.AxesSelectionChanged();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)

            %Set the colours of the text in the dropdowns
            comp.YAxisDropDown_1.FontColor = comp.Colours(1,:);
            comp.YAxisDropDown_2.FontColor = comp.Colours(2,:);
            comp.YAxisDropDown_3.FontColor = comp.Colours(3,:);
            comp.YAxisDropDown_4.FontColor = comp.Colours(4,:);

            %Add a 2nd y axis on the right
            yyaxis(comp.UIAxes, "right");
            comp.UIAxes.YAxis(1).Color = 'k'; % change color of LHS y-axis to black
            comp.UIAxes.YAxis(2).Color = 'k'; % change color of RHS y-axis to black
            comp.UIAxes.YAxis(2).MinorTick = 'on';

            %By default, hide axes ticks until there is actually something
            %to plot
            comp.UIAxes.YAxis(1).TickValues = [];
            comp.UIAxes.YAxis(2).TickValues = [];
        end

        % Button pushed function: CopyPlotButton
        function CopyPlotButtonPushed(comp, event)
            try
                comp.CopyPlot();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in CopyPlotButtonPushed", err);
            end
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(comp, event)
            try
                %Make a copy of the plot in a new figure, then pass that in for
                %saving
                [fig, ax] = comp.CopyPlot();
                eventData = Palladium.Events.PlotterEventData(ax, fig);

                %Call event - Controller will be passed the reference to the
                %figure, to handle how to save it.
                notify(comp, 'SavePlot',  eventData);

                %Delete the figure now it's done with
                delete(fig);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in SavePlotButtonPushed", err);
            end
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(comp, event)
            try
                value = comp.XAxisDropDown.Value;
                if(strcmp(value, comp.EmptyAxisOptionString))
                    comp.SelectedXAxisString = "";
                    comp.SelectedXAxisIndex = 0;
                else
                    comp.SelectedXAxisString = value;
                    idx = find(strcmp(comp.XAxisDropDown.Items, value));
                    comp.SelectedXAxisIndex = idx - 1;
                end

                %Notify that something has changed and we need to update the
                %GUI
                comp.AxesSelectionChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in XAxisDropDownValueChanged", err);
            end
        end

        % Value changed function: YAxisDropDown_1
        function YAxisDropDown_1ValueChanged(comp, event)
            try
                value = comp.YAxisDropDown_1.Value;
                indexOfOption = find(strcmp(comp.YAxisDropDown_1.Items, value));

                %Some error checking and validation, make sure we have exactly
                %1 selection
                assert(~isempty(indexOfOption),"YAxisDropDown_1_ValueChangedError:VariableNotFound", "%s", "Could not find y axis variable " + string(value) + " when updating plotter y axis selection");
                if length(indexOfOption) ~= 1
                    Palladium.Logging.Logger.Log("Warning", "Duplicate variable names detected, cannot update Plotter y axis selection");
                    return;
                end

                comp.YAxisSelectionChanged(value, 1, indexOfOption);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in YAxisDropDown_1ValueChanged", err);
            end
        end

        % Value changed function: YAxisDropDown_2
        function YAxisDropDown_2ValueChanged(comp, event)
            try
                value = comp.YAxisDropDown_2.Value;
                indexOfOption = find(strcmp(comp.YAxisDropDown_2.Items, value));

                %Some error checking and validation, make sure we have exactly
                %1 selection
                assert(~isempty(indexOfOption), "YAxisDropDown_2_ValueChangedError:VariableNotFound", "%s", "Could not find y axis variable " + string(value) + " when updating plotter y axis selection");
                if length(indexOfOption) ~= 1
                    Palladium.Logging.Logger.Log("Warning", "Duplicate variable names detected, cannot update Plotter y axis selection");
                    return;
                end

                comp.YAxisSelectionChanged(value, 2, indexOfOption);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in YAxisDropDown_2ValueChanged", err);
            end
        end

        % Value changed function: YAxisDropDown_3
        function YAxisDropDown_3ValueChanged(comp, event)
            try
                value = comp.YAxisDropDown_3.Value;
                indexOfOption = find(strcmp(comp.YAxisDropDown_3.Items, value));

                %Some error checking and validation, make sure we have exactly
                %1 selection
                assert(~isempty(indexOfOption), "YAxisDropDown_3_ValueChangedError:VariableNotFound", "%s", "Could not find y axis variable " + string(value) + " when updating plotter y axis selection");
                if length(indexOfOption) ~= 1
                    Palladium.Logging.Logger.Log("Warning", "Duplicate variable names detected, cannot update Plotter y axis selection");
                    return;
                end

                comp.YAxisSelectionChanged(value, 3, indexOfOption);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in YAxisDropDown_3ValueChanged", err);
            end
        end

        % Value changed function: YAxisDropDown_4
        function YAxisDropDown_4ValueChanged(comp, event)
            try
                value = comp.YAxisDropDown_4.Value;
                indexOfOption = find(strcmp(comp.YAxisDropDown_4.Items, value));

                %Some error checking and validation, make sure we have exactly
                %1 selection
                assert(~isempty(indexOfOption), "YAxisDropDown_4_ValueChangedError:VariableNotFound", "%s", "Could not find y axis variable " + string(value) + " when updating plotter y axis selection");
                if length(indexOfOption) ~= 1
                    Palladium.Logging.Logger.Log("Warning", "Duplicate variable names detected, cannot update Plotter y axis selection");
                    return;
                end

                comp.YAxisSelectionChanged(value, 4, indexOfOption);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in YAxisDropDown_4ValueChanged", err);
            end
        end

        % Button pushed function: AxesToggleButton_1
        function AxesToggleButton_1Pushed(comp, event)
            try
                if(comp.PlotOnRHS(1))
                    comp.PlotOnRHS(1) = false;
                    comp.AxesToggleButton_1.Text = comp.LHSText;
                else
                    comp.PlotOnRHS(1) = true;
                    comp.AxesToggleButton_1.Text = comp.RHSText;
                end

                %Notify that something has changed and we need to update the
                %GUI
                comp.AxesSelectionChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in AxesToggleButton_1Pushed", err);
            end
        end

        % Button pushed function: AxesToggleButton_2
        function AxesToggleButton_2Pushed(comp, event)
            try
                if(comp.PlotOnRHS(2))
                    comp.PlotOnRHS(2) = false;
                    comp.AxesToggleButton_2.Text = comp.LHSText;
                else
                    comp.PlotOnRHS(2) = true;
                    comp.AxesToggleButton_2.Text = comp.RHSText;
                end

                %Notify that something has changed and we need to update the
                %GUI
                comp.AxesSelectionChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in AxesToggleButton_2Pushed", err);
            end
        end

        % Button pushed function: AxesToggleButton_3
        function AxesToggleButton_3Pushed(comp, event)
            try
                if(comp.PlotOnRHS(3))
                    comp.PlotOnRHS(3) = false;
                    comp.AxesToggleButton_3.Text = comp.LHSText;
                else
                    comp.PlotOnRHS(3) = true;
                    comp.AxesToggleButton_3.Text = comp.RHSText;
                end

                %Notify that something has changed and we need to update the
                %GUI
                comp.AxesSelectionChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in AxesToggleButton_3Pushed", err);
            end
        end

        % Button pushed function: AxesToggleButton_4
        function AxesToggleButton_4Pushed(comp, event)
            try
                if(comp.PlotOnRHS(4))
                    comp.PlotOnRHS(4) = false;
                    comp.AxesToggleButton_4.Text = comp.LHSText;
                else
                    comp.PlotOnRHS(4) = true;
                    comp.AxesToggleButton_4.Text = comp.RHSText;
                end

                %Notify that something has changed and we need to update the
                %GUI
                comp.AxesSelectionChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in AxesToggleButton_4Pushed", err);
            end
        end

        % Value changed function: LogxCheckBox
        function LogxCheckBoxValueChanged(comp, event)
            try
                value = comp.LogxCheckBox.Value;

                if value
                    comp.UIAxes.XScale = "log";
                else
                    comp.UIAxes.XScale = "linear";
                end
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in LogxCheckBoxValueChanged", err);
            end
        end

        % Value changed function: LogyCheckBox
        function LogyCheckBoxValueChanged(comp, event)
            try
                value = comp.LogyCheckBox.Value;

                if value
                    comp.UIAxes.YScale = "log";
                else
                    comp.UIAxes.YScale = "linear";
                end
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in LogyCheckBoxValueChanged", err);
            end
        end

        % Value changed function: xMaxEditField
        function xMaxEditFieldValueChanged(comp, event)
            try
                comp.UpdateXLimits();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in xMaxEditFieldValueChanged", err);
            end
        end

        % Value changed function: xMinEditField
        function xMinEditFieldValueChanged(comp, event)
            try
                comp.UpdateXLimits();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in xMinEditFieldValueChanged", err);
            end
        end

        % Value changed function: yMaxEditField
        function yMaxEditFieldValueChanged(comp, event)
            try
                comp.UpdateYLimits();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in yMaxEditFieldValueChanged", err);
            end
        end

        % Value changed function: yMinEditField
        function yMinEditFieldValueChanged(comp, event)
            try
                comp.UpdateYLimits();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in yMinEditFieldValueChanged", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 936 625];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.XAxisDropDown = uidropdown(comp.GridLayout3);
            comp.XAxisDropDown.Items = {};
            comp.XAxisDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @XAxisDropDownValueChanged, true);
            comp.XAxisDropDown.Value = {};
            comp.XAxisLabel = uilabel(comp.GridLayout3);
            comp.XAxisLabel.Text = 'X Axis';
            comp.YAxesLabel = uilabel(comp.GridLayout3);
            comp.YAxesLabel.Text = 'Y Axes';
            comp.YAxisDropDown_1 = uidropdown(comp.GridLayout3);
            comp.YAxisDropDown_1.Items = {};
            comp.YAxisDropDown_1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisDropDown_1ValueChanged, true);
            comp.YAxisDropDown_1.Value = {};
            comp.YAxisDropDown_2 = uidropdown(comp.GridLayout3);
            comp.YAxisDropDown_2.Items = {};
            comp.YAxisDropDown_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisDropDown_2ValueChanged, true);
            comp.YAxisDropDown_2.Value = {};
            comp.YAxisDropDown_3 = uidropdown(comp.GridLayout3);
            comp.YAxisDropDown_3.Items = {};
            comp.YAxisDropDown_3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisDropDown_3ValueChanged, true);
            comp.YAxisDropDown_3.Value = {};
            comp.YAxisDropDown_4 = uidropdown(comp.GridLayout3);
            comp.YAxisDropDown_4.Items = {};
            comp.YAxisDropDown_4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisDropDown_4ValueChanged, true);
            comp.YAxisDropDown_4.Value = {};
            comp.AxesToggleButton_1 = uibutton(comp.GridLayout3, 'push');
            comp.AxesToggleButton_1.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AxesToggleButton_1Pushed, true);
            comp.AxesToggleButton_1.Tooltip = {'Click to toggle between left and right y axes for this dataset'};
            comp.AxesToggleButton_1.Text = '◀';
            comp.AxesToggleButton_2 = uibutton(comp.GridLayout3, 'push');
            comp.AxesToggleButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AxesToggleButton_2Pushed, true);
            comp.AxesToggleButton_2.Tooltip = {'Click to toggle between left and right y axes for this dataset'};
            comp.AxesToggleButton_2.Text = '◀';
            comp.AxesToggleButton_3 = uibutton(comp.GridLayout3, 'push');
            comp.AxesToggleButton_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AxesToggleButton_3Pushed, true);
            comp.AxesToggleButton_3.Tooltip = {'Click to toggle between left and right y axes for this dataset'};
            comp.AxesToggleButton_3.Text = '◀';
            comp.AxesToggleButton_4 = uibutton(comp.GridLayout3, 'push');
            comp.AxesToggleButton_4.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AxesToggleButton_4Pushed, true);
            comp.AxesToggleButton_4.Tooltip = {'Click to toggle between left and right y axes for this dataset'};
            comp.AxesToggleButton_4.Text = '◀';
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.CopyPlotButton = uibutton(comp.GridLayout4, 'push');
            comp.CopyPlotButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CopyPlotButtonPushed, true);
            comp.CopyPlotButton.Text = 'Copy Plot';
            comp.SavePlotButton = uibutton(comp.GridLayout4, 'push');
            comp.SavePlotButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SavePlotButtonPushed, true);
            comp.SavePlotButton.Text = 'Save Plot';
            comp.GridLayout5 = uigridlayout(comp.GridLayout2);
            comp.xEditFieldLabel = uilabel(comp.GridLayout5);
            comp.xEditFieldLabel.Text = 'x';
            comp.xMinEditField = uieditfield(comp.GridLayout5, 'numeric');
            comp.xMinEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @xMinEditFieldValueChanged, true);
            comp.xMinEditField.Value = -Inf;
            comp.EditFieldLabel = uilabel(comp.GridLayout5);
            comp.EditFieldLabel.Text = '-';
            comp.xMaxEditField = uieditfield(comp.GridLayout5, 'numeric');
            comp.xMaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @xMaxEditFieldValueChanged, true);
            comp.xMaxEditField.Value = Inf;
            comp.GridLayout6 = uigridlayout(comp.GridLayout5);
            comp.LogxCheckBox = uicheckbox(comp.GridLayout6);
            comp.LogxCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LogxCheckBoxValueChanged, true);
            comp.LogxCheckBox.Text = 'Log x ';
            comp.LogyCheckBox = uicheckbox(comp.GridLayout6);
            comp.LogyCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LogyCheckBoxValueChanged, true);
            comp.LogyCheckBox.Text = 'Log y ';
            comp.yEditFieldLabel = uilabel(comp.GridLayout5);
            comp.yEditFieldLabel.Text = 'y';
            comp.yMinEditField = uieditfield(comp.GridLayout5, 'numeric');
            comp.yMinEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @yMinEditFieldValueChanged, true);
            comp.yMinEditField.Value = -Inf;
            comp.EditField_2Label = uilabel(comp.GridLayout5);
            comp.EditField_2Label.Text = '-';
            comp.yMaxEditField = uieditfield(comp.GridLayout5, 'numeric');
            comp.yMaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @yMaxEditFieldValueChanged, true);
            comp.yMaxEditField.Value = Inf;
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end