classdef SimplePlotterPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout        matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        yMaxEditField     matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        yMinEditField     matlab.ui.control.NumericEditField
        yEditFieldLabel   matlab.ui.control.Label
        xMaxEditField     matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        xMinEditField     matlab.ui.control.NumericEditField
        xEditFieldLabel   matlab.ui.control.Label
        LogyCheckBox      matlab.ui.control.CheckBox
        LogxCheckBox      matlab.ui.control.CheckBox
        SavePlotButton    matlab.ui.control.Button
        CopyPlotButton    matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        AxesSelectionChange
        SavePlot
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        PlotRefreshNeeded (1,1) logical = false;
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Colours (4,3) double {mustBeLessThanOrEqual(Colours, 1), mustBeGreaterThanOrEqual(Colours, 0)} = [1 0 0; 0 0 1; 0 0.6 0; 1 0 1];
        FontSize (1,1) double {mustBePositive(FontSize), mustBeInteger(FontSize)} = 20;
        LineStyles (4,1) string = ["None", "None", "None", "None"];
        LineWidth (1,1) double {mustBePositive(LineWidth)} = 1;
        MarkerSize (1,1) double {mustBePositive(MarkerSize), mustBeInteger(MarkerSize), mustBeFinite(MarkerSize)} = 6;
        Markers (4,1) string = ["o"; "o"; "+"; "*"];
        ShowLegends (1,1) logical = true;
    end
    
    properties (Access = private)
        DataLines = gobjects(4);
        PlotSettings = [];
        StartTimeMins (1,1) double = 0;
        SmithPanel = [];        %Panel over the axes holding the Smith plot, while the plot kind is Smith
        SmithPlot = [];         %The RF Toolbox smithplot in it
    end
    
    properties (Access = public)
        TitleForCopiedPlots = [];
        XAxisIsTime (1,1) logical = false;
    end

    properties (GetAccess = public, SetAccess = private)
        PlotKind (1,1) string {mustBeMember(PlotKind, ["Scatter", "Smith"])} = "Scatter";   %How PlotData draws: Scatter (y against x) or Smith (a Smith chart: x is the real part and y the imaginary part of the reflection coefficient). Change with SetPlotKind
    end
    

    methods (Access = private)

        %% AxesSelectionChanged
        function AxesSelectionChanged(comp)
            %Trigger event
            notify(comp, 'AxesSelectionChange');
            comp.OnAxesSelectionChanged();
        end

        %% CleanDisplayName
        function newName = CleanDisplayName(~, name)
            %Make a column header show as typed in the LaTeX-interpreted
            %labels and legend - escapes _ % & etc, and Greek letters (e.g. Ω)
            newName = Palladium.Utilities.GUIUtils.EscapeLaTeX(name);
        end

        %% CopyPlot
        function figRef = CopySmithPlot(comp)
            %A new figure with the Smith chart and the trace plotted, like the plotter's
            figRef = figure('Position', [100 100 800 800], 'Visible', 'off');
            if ~isempty(comp.SmithPlot) && isvalid(comp.SmithPlot)    %Not isgraphics: a smithplot is a handle object, not a graphics object
                data = comp.SmithPlot.Data;
            else
                data = complex(NaN, NaN);   %An empty chart
            end
            copy = smithplot(figRef, data);
            copy.ColorOrder = comp.Colours(1, :);
            copy.LineWidth = max(comp.LineWidth, 1.5);
            copy.LegendVisible = false;
            if ~isempty(comp.TitleForCopiedPlots)
                copy.TitleTopTextInterpreter = "none";
                copy.TitleTop = comp.TitleForCopiedPlots;
            end
            figRef.Visible = 'on';
        end

        %% CopyPlot
        function figRef = CopyPlot(comp)
            if comp.PlotKind == "Smith"
                figRef = comp.CopySmithPlot();
                return;
            end

            preserveZoomLevel = true;

            %Grab reference
            ax = comp.UIAxes;
            xlimits = ax.XLim;
            ylimits = ax.YLim;

            %Create a new figure
            figRef = figure('Position', [100 100 1000 700]);
            newAx = axes(figRef, 'Box', 'on');

            %Add a 2nd y axis on the right
            yyaxis(newAx, "right");
            newAx.YAxis(1).Color = 'k'; % change color of LHS y-axis to black
            newAx.YAxis(2).Color = 'k'; % change color of RHS y-axis to black
            newAx.YAxis(2).MinorTick = 'on';
            newAx.YAxis(1).TickValues = [];
            newAx.YAxis(2).TickValues = [];

            %Hide the figure so it doesnt jump around horribly as it
            %updates
            figRef.Visible = 'off';


            hold on;
            dataLines = comp.DataLines;

            for i = 1 : size(dataLines, 1)
                for j = 1 : size(dataLines, 2) %This second loop will normally only run once - DataLines is only a 2D array if we did a multiplot from the DataViewer, several datafiles at once
                    %Only consider data sets which are not empty, i.e. are
                    %proper populated LineDatas, not GraphicsPlaceholders
                    if(isgraphics(dataLines(i, j)))

                        %Check this - quickly modified from general
                        %plotter, two y axes do not exist in this plotter
                        yyaxis(newAx, "left");
                        newAx.YAxis(1).TickValuesMode = "auto";

                        %Extract data from axes and plot in new figure
                        [xdata, ydata] = getpoints(dataLines(i, j));
                        line = plot(newAx, xdata, ydata, 'Marker', dataLines(i, j).Marker, 'LineStyle', dataLines(i, j).LineStyle, 'Color', dataLines(i, j).Color);
                        line.DisplayName = dataLines(i, j).DisplayName;
                    end
                end
            end

            %Add a legend - plots have names now
            lgd = legend;
            lgd.Box = 'off';
            lgd.Interpreter = "latex";

            %Copy axes labels
            figRef.CurrentAxes.XLabel.FontSize = ax.XLabel.FontSize;
            figRef.CurrentAxes.YLabel.FontSize = ax.YLabel.FontSize;

            figRef.CurrentAxes.FontSize = comp.FontSize;

            %Copy if axes are log or linear
            newAx.XScale = ax.XScale;
            newAx.YScale = ax.YScale;

            figRef.CurrentAxes.XLabel.Interpreter = "latex";
            
            %Add a title, if one has been set
            if ~isempty(comp.TitleForCopiedPlots)
                title(comp.TitleForCopiedPlots, "Interpreter", "none");
            end

            %Set zoom / axes limits
            if(preserveZoomLevel)
                figRef.CurrentAxes.XLimMode = "Manual";
                figRef.CurrentAxes.YLimMode = "Manual";
                figRef.CurrentAxes.XLim = xlimits;
                figRef.CurrentAxes.YLim = ylimits;
            end

            figRef.CurrentAxes.YLabel.Interpreter = "latex";

            %Set to visible again now its done
            figRef.Visible = 'on';
            hold off;
        end

        %% DeleteSmithPlot
        function DeleteSmithPlot(comp)
            if isgraphics(comp.SmithPanel)
                delete(comp.SmithPanel);
            end
            comp.SmithPanel = [];
            comp.SmithPlot = [];
        end

        %% ShowSmithPlot
        function ShowSmithPlot(comp, realPart, imaginaryPart)
            %A Smith chart (RF Toolbox's smithplot, in a panel over the axes) of the reflection coefficients with these real and imaginary parts
            comp.DeleteSmithPlot();
            if isempty(realPart)
                return;
            end
            comp.SmithPanel = uipanel(comp.GridLayout, "BorderType", "none");
            comp.SmithPanel.Layout.Row = 2;
            comp.SmithPanel.Layout.Column = 1;
            comp.SmithPlot = smithplot(comp.SmithPanel, complex(realPart(:), imaginaryPart(:, 1)));
            comp.SmithPlot.ColorOrder = comp.Colours(1, :);
            comp.SmithPlot.LineWidth = max(comp.LineWidth, 1.5);
            comp.SmithPlot.LegendVisible = false;
            comp.SmithPlot.FontSize = 12;
        end

        %% ScaleName
        function name = ScaleName(~, isLog)
            if isLog
                name = "log";
            else
                name = "linear";
            end
        end

        %% UpdateXLimits
        function UpdateXLimits(comp)
            if comp.PlotKind == "Smith"
                return;     %The chart's size is fixed
            end
            minValue = comp.xMinEditField.Value;
            maxValue = comp.xMaxEditField.Value;

            comp.XLimChanged(minValue, maxValue);
        end

        %% UpdateYLimits
        function UpdateYLimits(comp)
            if comp.PlotKind == "Smith"
                return;     %The chart's size is fixed
            end
            minValue = comp.yMinEditField.Value;
            maxValue = comp.yMaxEditField.Value;

            comp.YLimChanged(minValue, maxValue);
        end

        %% XLimChanged
        function XLimChanged(comp, xMin, xMax)
            xlim(comp.UIAxes, [xMin, xMax]);
        end

        %% YLimChanged
        function YLimChanged(comp, yMin, yMax)
            ylim(comp.UIAxes, [yMin, yMax]);
        end

    end

    methods (Access = public)

        %% Apply Settings
        function ApplySettings(comp, plotSettings)
            %Apply settings loaded in from config file, immediately after
            %component creation
            comp.PlotSettings = plotSettings;

            %Only show Legends on Large (1x1) plotters
            if(plotSettings.Size ~= "Large")
                plotSettings.ShowLegends = false;
            end

            %Settings file could not be saved with 2D array of colours as desired,
            %reshape the colours array from 1D to 2D to match the Property here
            plotSettings.Colours = reshape(plotSettings.Colours, [3,4])';

            comp.Colours = plotSettings.Colours;
            comp.FontSize = plotSettings.FontSize;
            comp.LineStyles = plotSettings.LineStyles;
            comp.LineWidth = plotSettings.LineWidth;
            comp.MarkerSize = plotSettings.MarkerSize;
            comp.Markers = plotSettings.Markers;
            comp.ShowLegends = plotSettings.ShowLegends;
        end

        %% AttachContextMenu
        function AttachContextMenu(comp, fig)

            axesContextMenu = uicontextmenu(fig);
            comp.UIAxes.ContextMenu = axesContextMenu;
            m1 = uimenu(axesContextMenu, "Text", "test");
        end

        %% ClearData
        function ClearData(comp)
            %Reinitialise DataLines array (deleting will happen with the
            %cla commands below)
            comp.DataLines = gobjects(4);
            comp.DeleteSmithPlot();

            %Clear axes
            cla(comp.UIAxes);

            %Also reset previously set x and y limits (via manual zooming)
            %- experimental!
            xlim(comp.UIAxes, "auto");
            ylim(comp.UIAxes, "auto");

            %Set the axes up for the kind of plot
            comp.PrepareAxes();
        end

        %% PrepareAxes
        function PrepareAxes(comp)
            %The empty axes as the plot kind needs them: a Smith chart's grid, or the usual axes with their ticks, grid and limits automatic
            ax = comp.UIAxes;
            if comp.PlotKind == "Smith"
                ax.Visible = 'off';     %The Smith plot is in a panel over it
            else
                ax.Visible = 'on';
                axis(ax, "normal");
                ax.XTickMode = "auto";
                ax.YTickMode = "auto";
                ax.XGrid = "on";
                ax.YGrid = "on";
                ax.XMinorTick = "on";
                ax.YMinorTick = "on";
                ax.Box = "on";
                ax.XColor = [0.15 0.15 0.15];
                ax.YColor = [0.15 0.15 0.15];
            end
        end

        %% SetPlotKind
        function SetPlotKind(comp, kind)
            %Change how the plotter draws (Scatter or Smith), clearing what is plotted - plot the data again with PlotData.
            %The log and limit controls only apply to a Scatter plot, so
            %are disabled for the others
            %
            %Inputs:
            %   kind - "Scatter" or "Smith"
            if string(kind) == "Smith" && ~Palladium.Utilities.GUIUtils.IsSmithPlotAvailable()
                error("SimplePlotterPanel:SmithPlotUnavailable", "%s", "The Smith chart needs RF Toolbox, which is not installed or licensed.");
            end
            comp.PlotKind = kind;
            scatter = comp.PlotKind == "Scatter";
            comp.LogxCheckBox.Enable = scatter;
            comp.LogyCheckBox.Enable = scatter;
            comp.xMinEditField.Enable = scatter;
            comp.xMaxEditField.Enable = scatter;
            comp.yMinEditField.Enable = scatter;
            comp.yMaxEditField.Enable = scatter;
            if ~scatter
                comp.UIAxes.XScale = "linear";
                comp.UIAxes.YScale = "linear";
            else
                comp.UIAxes.XScale = comp.ScaleName(comp.LogxCheckBox.Value);
                comp.UIAxes.YScale = comp.ScaleName(comp.LogyCheckBox.Value);
            end
            comp.ClearData();
            comp.PlotRefreshNeeded = true;
        end

        %% LabelAxes
        function LabelAxes(comp, xlab, ylab)
            if comp.PlotKind == "Smith"
                return;     %The chart has no axes to label
            end
            xlabel(comp.UIAxes,  comp.CleanDisplayName(xlab), "Interpreter", "latex");
            ylabel(comp.UIAxes,  comp.CleanDisplayName(ylab), "Interpreter", "latex");
        end

        %% LabelDataSeries
        function LabelDataSeries(comp, namesCellArray)
            %For the legend
            for i = 1 : length(namesCellArray)
                comp.DataLines(i).DisplayName = string(namesCellArray{i});
            end
        end

        %% OnAxesSelectionChanged
        function OnAxesSelectionChanged(comp)
            comp.PlotRefreshNeeded = true;
        end

        %% PlotData
        function PlotData(comp, xData, yData)
            %xData and yData expected to be any number of rows, but yData can have a number of
            %columns from 1 to 4. For a Smith plot, xData is the real part and yData (one
            %column) the imaginary part of the reflection coefficient of each point

            %Start by clearing everything
            comp.ClearData();

            if comp.PlotKind == "Smith"
                comp.PlotRefreshNeeded = false;
                comp.ShowSmithPlot(xData, yData);
                return;
            end

            %We are performing the refresh here, can clear this flag now
            comp.PlotRefreshNeeded = false;

            %Cache number of rows and columns in the data, and do some
            %error checking
            numcols = size(yData, 2);
            numrows = size(xData, 1);
            assert(numrows == size(yData, 1), "PlotDataError:RowCountMismatch", "Number of rows must be the same across x and y data in SimplePlotterPanel.PlotData");

            %Return if data are empty
            if numcols < 1
                return;
            end

            %Flag for faster rendering
            comp.UIAxes.SortMethod = "childorder";
            comp.UIAxes.FontSize = comp.FontSize;

            %Special case if x axis is Time(mins) - plot as relative rather
            %than absolute values. For SimplePlotterPanel, this is just a
            %public property that other code can set, it's not auto-set
            if(comp.XAxisIsTime)
                comp.StartTimeMins = min(xData);
                startT = comp.StartTimeMins;
            end

            %Plot each y series in turn
            hold(comp.UIAxes, "on");
            for i = 1 : numcols
                %Note - we use animatedline objects instead of the
                %normal 'plot' command for performance when adding
                %points to a graph with a large number of points on
                if(comp.XAxisIsTime)
                    comp.DataLines(i) = animatedline(comp.UIAxes, xData - startT, yData(:,i), 'Color', comp.Colours(i, :),...
                        'Marker', comp.Markers(i), 'LineStyle', comp.LineStyles(i), 'MarkerSize', comp.MarkerSize, 'LineWidth', comp.LineWidth,...
                        'PickableParts', 'none');
                else
                    comp.DataLines(i) = animatedline(comp.UIAxes, xData, yData(:,i), 'Color', comp.Colours(i, :),...
                        'Marker', comp.Markers(i), 'LineStyle', comp.LineStyles(i), 'MarkerSize', comp.MarkerSize, 'LineWidth', comp.LineWidth,...
                        'PickableParts', 'none');
                end

            end
            hold(comp.UIAxes, "off");

            %Show Legend, if desired
            if comp.ShowLegends
                legend(comp.UIAxes, 'FontSize', 12, 'Color', 'none', 'Box', 'off');
            end

            %Update axis limits
            comp.UpdateXLimits();
            comp.UpdateYLimits();
        end

        %% TryAppendData
        function result = TryAppendData(comp, xData, yData)
            %This is intended to be much faster than the brute force
            %PlotData and should be called most update loops in its stead.
            %PlotData needs to be called if e.g. we have selected a
            %different x axis.. in this case the 'result' output parameter
            %will return false, and the code that called this should know
            %to now call PlotData (with the whole data array instead of
            %just one line) instead.

            %If we have changed e.g. axes selections, then clear the axes
            %and plot afresh instead of just appending new data to them.
            %A Smith plot is always plotted afresh
            if(comp.PlotRefreshNeeded || comp.PlotKind == "Smith")
                result = false;
                return;
            else
                result = true;
            end

            %Append a value to each y series in turn
            hold(comp.UIAxes, "on");

            %Subtract start time for special case of plotting time (mins)
            %on x axis
            if(comp.XAxisIsTime)
                xData = xData - comp.StartTimeMins;
            end

            %Update each plot
            for i = 1 : size(yData,2)
                %Some more checks for issues
                if (isempty(comp.DataLines(i)) || ~isgraphics(comp.DataLines(i))) %Is this line a graphics placeholder - don't add to it if it is, we should abort this attempt to addpoints and do a full replot
                    result = false;
                    return;
                end

                %If we got here, all is well, an animatedline exists,
                %and we can add some points to it
                addpoints(comp.DataLines(i), xData, yData(:,i));
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)

        end

        % Button pushed function: CopyPlotButton
        function CopyPlotButtonPushed(comp, event)
            try
                comp.CopyPlot();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in CopyPlotButtonPushed", err);
            end
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(comp, event)
            try
                %Make a copy of the plot in a new figure, then pass that in for
                %saving
                fig = comp.CopyPlot();
                eventData = Palladium.Events.PlotterEventData(comp.UIAxes, fig);

                %Call event - Controller will be passed the reference to the
                %figure, to handle how to save it.
                notify(comp, 'SavePlot',  eventData);

                %Delete the figure now it's done with
                delete(fig);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in SavePlotButtonPushed", err);
            end
        end

        % Value changed function: LogxCheckBox
        function LogxCheckBoxValueChanged(comp, event)
            try
                value = comp.LogxCheckBox.Value;

                if value
                    comp.UIAxes.XScale = "log";
                else
                    comp.UIAxes.XScale = "linear";
                end
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in LogxCheckBoxValueChanged", err);
            end
        end

        % Value changed function: LogyCheckBox
        function LogyCheckBoxValueChanged(comp, event)
            try
                value = comp.LogyCheckBox.Value;

                if value
                    comp.UIAxes.YScale = "log";
                else
                    comp.UIAxes.YScale = "linear";
                end
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in LogyCheckBoxValueChanged", err);
            end
        end

        % Value changed function: yMaxEditField
        function yMaxEditFieldValueChanged(comp, event)
            try
                comp.UpdateYLimits();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in yMaxEditFieldValueChanged", err);
            end
        end

        % Value changed function: yMinEditField
        function yMinEditFieldValueChanged(comp, event)
            try
                comp.UpdateYLimits();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in yMinEditFieldValueChanged", err);
            end
        end

        % Value changed function: xMaxEditField
        function xMaxEditFieldValueChanged(comp, event)
            try
                comp.UpdateXLimits();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in xMaxEditFieldValueChanged", err);
            end
        end

        % Value changed function: xMinEditField
        function xMinEditFieldValueChanged(comp, event)
            try
                comp.UpdateXLimits();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in xMinEditFieldValueChanged", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 935 625];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.CopyPlotButton = uibutton(comp.GridLayout2, 'push');
            comp.CopyPlotButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CopyPlotButtonPushed, true);
            comp.CopyPlotButton.Text = 'Copy Plot';
            comp.SavePlotButton = uibutton(comp.GridLayout2, 'push');
            comp.SavePlotButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SavePlotButtonPushed, true);
            comp.SavePlotButton.Text = 'Save Plot';
            comp.LogxCheckBox = uicheckbox(comp.GridLayout2);
            comp.LogxCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LogxCheckBoxValueChanged, true);
            comp.LogxCheckBox.Text = 'Log x';
            comp.LogyCheckBox = uicheckbox(comp.GridLayout2);
            comp.LogyCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LogyCheckBoxValueChanged, true);
            comp.LogyCheckBox.Text = 'Log y';
            comp.xEditFieldLabel = uilabel(comp.GridLayout2);
            comp.xEditFieldLabel.Text = 'x';
            comp.xMinEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.xMinEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @xMinEditFieldValueChanged, true);
            comp.xMinEditField.Value = -Inf;
            comp.EditFieldLabel = uilabel(comp.GridLayout2);
            comp.EditFieldLabel.Text = '-';
            comp.xMaxEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.xMaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @xMaxEditFieldValueChanged, true);
            comp.xMaxEditField.Value = Inf;
            comp.yEditFieldLabel = uilabel(comp.GridLayout2);
            comp.yEditFieldLabel.Text = 'y';
            comp.yMinEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.yMinEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @yMinEditFieldValueChanged, true);
            comp.yMinEditField.Value = -Inf;
            comp.EditField_2Label = uilabel(comp.GridLayout2);
            comp.EditField_2Label.Text = '-';
            comp.yMaxEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.yMaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @yMaxEditFieldValueChanged, true);
            comp.yMaxEditField.Value = Inf;
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end