classdef StateControlPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        ActualupdatetimesEditField  matlab.ui.control.NumericEditField
        ActualupdatetimesEditFieldLabel  matlab.ui.control.Label
        TargetupdatetimesEditField  matlab.ui.control.NumericEditField
        TargetupdatetimesEditFieldLabel  matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        StopButton                  matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        Paused % Called when the Pause button is pushed
        Resumed
        Started % Triggered when the Start button is called
        Stopped % Called when the Stop button is pushed
        TargetUpdateTimeChanged
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        IsPaused (1,1) logical = false;
        TargetUpdateTime_s double = 0.75; % Time for one iteration of the measurement loop that the programme should aim for, in seconds
    end
    
    properties (Access = public)
        UpdateTime_s (1,1) double = 0; % The measured real update time, to be displayed, in seconds
    end
    
    methods (Access = public)

        %% OnStarted
        function OnStarted(comp)
            comp.StartButton.Enable = false;
            comp.PauseButton.Enable = true;
            comp.StopButton.Enable = true;

            comp.PauseButton.Text = "Pause";
            comp.IsPaused = false;
        end

        %% OnPaused
        function OnPaused(comp)
            comp.StartButton.Enable = false;
            comp.PauseButton.Enable = true;
            comp.StopButton.Enable = true;

            comp.PauseButton.Text = "Resume";
            comp.IsPaused = true;
        end

        %% OnResumed
        function OnResumed(comp)
            comp.StartButton.Enable = false;
            comp.PauseButton.Enable = true;
            comp.StopButton.Enable = true;

            comp.PauseButton.Text = "Pause";
            comp.IsPaused = false;
        end

        %% OnStopped
        function OnStopped(comp)
            comp.StartButton.Enable = true;
            comp.PauseButton.Enable = false;
            comp.StopButton.Enable = false;

            comp.PauseButton.Text = "Pause";
            comp.IsPaused = false;
        end

    end

    methods (Access = private)

        %% StartButtonPushed
        function StartButtonPushed(comp, ~, ~)
            comp.OnStarted();

            %Make sure GUI updates even with Async code
            drawnow();

            %Trigger event
            notify(comp, 'Started');
        end

        %% PauseButtonPushed
        function PauseButtonPushed(comp, ~, ~)
            %Either pause or resume, based on current state
            if(comp.IsPaused)
                comp.OnResumed();


                %Make sure GUI updates even with Async code
                drawnow();

                %Trigger event
                notify(comp, 'Resumed');
            else
                comp.OnPaused();


                %Make sure GUI updates even with Async code
                drawnow();

                %Trigger event
                notify(comp, 'Paused');
            end
        end

        %% StopButtonPushed
        function StopButtonPushed(comp, ~, ~)
            comp.OnStopped();

            %Make sure GUI updates even with Async code
            drawnow();

            %Trigger event
            notify(comp, 'Stopped');
        end

    end

    methods (Access = public)

        %% Stop
        function Stop(comp)
            %Can be called externally - will not fire event
            comp.OnStopped();
        end

        %% UnlockInput
        function UnlockInput(comp)
            comp.StartButton.Enable = 'on';
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            %Assign these callbacks manually, or we get errors on closing the window, as the measurement loop is running inside the Started event of this component, and Matlab wants to do post-event cleanup stuff if the callback is set via the GUI - crashing because the control is at that point deleted
            comp.StartButton.ButtonPushedFcn = Palladium.Core.ErrorGuard.Wrap(@comp.StartButtonPushed, Context = "Error in StartButtonPushed");;
            comp.PauseButton.ButtonPushedFcn = Palladium.Core.ErrorGuard.Wrap(@comp.PauseButtonPushed, Context = "Error in PauseButtonPushed");
            comp.StopButton.ButtonPushedFcn  = Palladium.Core.ErrorGuard.Wrap(@comp.StopButtonPushed,  Context = "Error in StopButtonPushed");
        end

        % Value changed function: TargetupdatetimesEditField
        function TargetupdatetimesEditFieldValueChanged(comp, event)
            try
                value = comp.TargetupdatetimesEditField.Value;
                comp.TargetUpdateTime_s = value;
                notify(comp, 'TargetUpdateTimeChanged');
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in TargetupdatetimesEditFieldValueChanged", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.TargetupdatetimesEditField.Value = comp.TargetUpdateTime_s;
            comp.ActualupdatetimesEditField.Value = comp.UpdateTime_s;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 636 111];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.StartButton = uibutton(comp.GridLayout2, 'push');
            comp.StartButton.Enable = 'off';
            comp.StartButton.Text = 'Start';
            comp.PauseButton = uibutton(comp.GridLayout2, 'push');
            comp.PauseButton.Enable = 'off';
            comp.PauseButton.Text = 'Pause';
            comp.StopButton = uibutton(comp.GridLayout2, 'push');
            comp.StopButton.Enable = 'off';
            comp.StopButton.Text = 'Stop';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.TargetupdatetimesEditFieldLabel = uilabel(comp.GridLayout3);
            comp.TargetupdatetimesEditFieldLabel.Text = 'Target update time (s)';
            comp.TargetupdatetimesEditField = uieditfield(comp.GridLayout3, 'numeric');
            comp.TargetupdatetimesEditField.Limits = [0.005 Inf];
            comp.TargetupdatetimesEditField.ValueDisplayFormat = '%11.2g';
            comp.TargetupdatetimesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TargetupdatetimesEditFieldValueChanged, true);
            comp.TargetupdatetimesEditField.Value = 0.75;
            comp.ActualupdatetimesEditFieldLabel = uilabel(comp.GridLayout3);
            comp.ActualupdatetimesEditFieldLabel.Text = 'Actual update time (s)';
            comp.ActualupdatetimesEditField = uieditfield(comp.GridLayout3, 'numeric');
            comp.ActualupdatetimesEditField.ValueDisplayFormat = '%11.2g';
            comp.ActualupdatetimesEditField.Editable = 'off';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end