classdef DataColumnAddPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                 matlab.ui.container.GridLayout
        SelectedDataColsListBox    matlab.ui.control.ListBox
        ExtradatacolumnsLabel      matlab.ui.control.Label
        RemoveSelectedButton       matlab.ui.control.Button
        DataColHeaderNameDropDown  matlab.ui.control.DropDown
        DatacolumntoaddLabel       matlab.ui.control.Label
        AddButton                  matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        SelectedDataColumnsChanged
    end

    
    methods (Access = public)
        
        function PopulateDataColumnHeadersList(comp, headers)
            if isscalar(headers)
                headers = string(headers{1});
            end
            comp.DataColHeaderNameDropDown.Items = headers;
        end
        
        function AddColumn(comp, dataColName)   

            if comp.CheckDuplicate(dataColName)
                return;
            end

            if isempty(comp.SelectedDataColsListBox.Items)
                comp.SelectedDataColsListBox.Items = string(dataColName);
            else
                comp.SelectedDataColsListBox.Items = [comp.SelectedDataColsListBox.Items, string(dataColName)];
            end

            args = Palladium.Events.ValueChangedEventData(comp.GetSelectedDataColumns());
            notify(comp, "SelectedDataColumnsChanged", args);
        end

        function colHeaders = GetSelectedDataColumns(comp)
            colHeaders = comp.SelectedDataColsListBox.Items;
        end

        function RemoveColumn(comp, dataColName)
            [~,idx] = ismember(dataColName, comp.SelectedDataColsListBox.Items);
            comp.SelectedDataColsListBox.Items(idx) = [];

            args = Palladium.Events.ValueChangedEventData(comp.GetSelectedDataColumns());
            notify(comp, "SelectedDataColumnsChanged", args);
        end
    end
    
    methods (Access = private)
        
        function existsAlready = CheckDuplicate(comp, valToAdd)
            existsAlready = false;

            if isempty(comp.SelectedDataColsListBox.Items)
                return;
            end

            for i = 1 : length(comp.SelectedDataColsListBox.Items)
                if strcmp(valToAdd, comp.SelectedDataColsListBox.Items(i))
                    existsAlready = true;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(comp, event)
            try
                sel = comp.DataColHeaderNameDropDown.Value;
                if isempty(sel)
                    return;
                end

                comp.AddColumn(sel);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in AddButtonPushed", err);
            end
        end

        % Button pushed function: RemoveSelectedButton
        function RemoveSelectedButtonPushed(comp, event)
            try
                valToRemove = comp.SelectedDataColsListBox.Value;

                if isempty(valToRemove)
                    return;
                end

                comp.RemoveColumn(valToRemove);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in RemoveSelectedButtonPushed", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 553 209];
            comp.GridLayout = uigridlayout(comp);
            comp.AddButton = uibutton(comp.GridLayout, 'push');
            comp.AddButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AddButtonPushed, true);
            comp.AddButton.Text = 'Add ➡️';
            comp.DatacolumntoaddLabel = uilabel(comp.GridLayout);
            comp.DatacolumntoaddLabel.Text = 'Data column to add';
            comp.DataColHeaderNameDropDown = uidropdown(comp.GridLayout);
            comp.DataColHeaderNameDropDown.Items = {};
            comp.DataColHeaderNameDropDown.Value = {};
            comp.RemoveSelectedButton = uibutton(comp.GridLayout, 'push');
            comp.RemoveSelectedButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RemoveSelectedButtonPushed, true);
            comp.RemoveSelectedButton.Text = 'Remove Selected';
            comp.ExtradatacolumnsLabel = uilabel(comp.GridLayout);
            comp.ExtradatacolumnsLabel.Text = 'Extra data columns';
            comp.SelectedDataColsListBox = uilistbox(comp.GridLayout);
            comp.SelectedDataColsListBox.Items = {};
            comp.SelectedDataColsListBox.Value = {};
        end
    end
end