classdef LakeshoreTempControl < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        HeaterStatusPanel             matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        RampEnabledLabel_2            matlab.ui.control.Label
        GridLayout11_2                matlab.ui.container.GridLayout
        RampEnabledLamp               matlab.ui.control.Lamp
        ManualOutputDisplayEditField  matlab.ui.control.NumericEditField
        ManualOutputLabel             matlab.ui.control.Label
        RampRateDisplayEditField      matlab.ui.control.NumericEditField
        RampRateKminEditField_2Label  matlab.ui.control.Label
        SetPointDisplayEditField      matlab.ui.control.NumericEditField
        SetPointKEditField_2Label     matlab.ui.control.Label
        HeaterRangeDisplayEditField   matlab.ui.control.EditField
        HeaterRangeEditFieldLabel     matlab.ui.control.Label
        ControlModeDisplayEditField   matlab.ui.control.EditField
        ControlModeEditFieldLabel     matlab.ui.control.Label
        GridLayout8                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        HeaterPowerPercentEditField   matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        GridLayout11                  matlab.ui.container.GridLayout
        HeaterEnabledLamp             matlab.ui.control.Lamp
        HeaterPowerUnitsLabel         matlab.ui.control.Label
        HeaterPowerDisplayEditField   matlab.ui.control.NumericEditField
        HeaterPowerLabel              matlab.ui.control.Label
        GridLayout7                   matlab.ui.container.GridLayout
        PID_D_DisplayEditField        matlab.ui.control.NumericEditField
        DEditField_2Label             matlab.ui.control.Label
        PID_I_DisplayEditField        matlab.ui.control.NumericEditField
        IEditField_2Label             matlab.ui.control.Label
        PID_P_DisplayEditField        matlab.ui.control.NumericEditField
        PEditField_2Label             matlab.ui.control.Label
        HeaterControlPanel            matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        ManualOutputSetEditField      matlab.ui.control.NumericEditField
        ManualOutputEditFieldLabel    matlab.ui.control.Label
        RampEnabledLabel              matlab.ui.control.Label
        RampEnabledSetCheckbox        matlab.ui.control.CheckBox
        RampRateSetEditField          matlab.ui.control.NumericEditField
        RampRateKminEditFieldLabel    matlab.ui.control.Label
        SetPointSetEditField          matlab.ui.control.NumericEditField
        SetPointKEditFieldLabel       matlab.ui.control.Label
        HeaterRangeSetDropDown        matlab.ui.control.DropDown
        HeaterRangeDropDownLabel      matlab.ui.control.Label
        ControlModeSetDropDown        matlab.ui.control.DropDown
        ControlModeDropDownLabel      matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        PID_D_SetEditField            matlab.ui.control.NumericEditField
        DEditFieldLabel               matlab.ui.control.Label
        PID_I_SetEditField            matlab.ui.control.NumericEditField
        IEditFieldLabel               matlab.ui.control.Label
        PID_P_SetEditField            matlab.ui.control.NumericEditField
        PEditFieldLabel               matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        SetButton                     matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        HeaterSettingsInput % Called when the Set button is pressed to send new settings
    end


    methods (Access = private)

        %% Categoricals 
         
        function catOut = LS331_HeaterRange(~, inputStr);  catOut = categorical(inputStr, ["Off", "Low", "Medium", "High"]); end
        function catOut = LS331_ControlMode(~, inputStr);  catOut = categorical(inputStr, ["Off", "Manual PID", "Zone", "Open Loop", "AutoTune PID", "AutoTune PI", "AutoTune P"]); end

        function catOut = LS350_Channel(~, inputStr);      catOut = categorical(inputStr, ["A", "B", "C", "D", "None"]); end
        function catOut = LS350_ControlMode(~, inputStr);  catOut = categorical(inputStr, ["Off", "Closed Loop PID", "Zone", "Open Loop", "Monitor Out", "Warmup Supply"]); end
        function catOut = LS350_HeaterRange(~, inputStr);  catOut = categorical(inputStr, ["Off", "Range 1", "Range 2", "Range 3", "Range 4", "Range 5"]); end

        function catOut = LS370_HeaterRange(~, inputStr);  catOut = categorical(inputStr, ["Off (0)", " 32 muA (1)", "100 muA (2)", "316 muA (3)", "  1 mA (4)", "  3 mA (5)", " 10 mA (6)", " 32 mA (7)", "100 mA (8)"]); end
        function catOut = LS370_ControlMode(~, inputStr);  catOut = categorical(inputStr, ["Closed Loop PID", "Zone", "Open Loop", "Off"]); end


        %% InitialiseControlModeDropDown
        function InitialiseControlModeDropDown(comp, model)
            %Choose default starting value
            switch(model)
                case("331")
                    defaultVal = comp.LS331_ControlMode("Manual PID");
                case("340")
                    defaultVal = comp.LS350_ControlMode("Closed Loop PID");
                case("350")
                    defaultVal = comp.LS350_ControlMode("Closed Loop PID");
                case("370")
                    defaultVal = comp.LS370_ControlMode("Closed Loop PID");               
                otherwise
                    error("LakeshoreTempControlError:UnsupportedInstrument", "%s", "Unsupported Lakeshore Model " + model + " - should be 331, 340, 350 or 370 (372)");
            end

            %Unpack all the members of the defined categorical
            catVals = categories(defaultVal);
            catStrs = string(catVals)';

            %Set the Items and Items data
            comp.ControlModeSetDropDown.Items = catStrs;

            %Set categorical type as the underlying data type, not just the displayed string
            switch(model)
                case("331")
                    itemsData = comp.LS331_ControlMode(catStrs);    
                case("340")
                    itemsData = comp.LS350_ControlMode(catStrs);    
                case("350")
                    itemsData = comp.LS350_ControlMode(catStrs);    
                case("370")
                    itemsData = comp.LS370_ControlMode(catStrs);                    
                otherwise
                    error("LakeshoreTempControlError:UnsupportedInstrument", "%s", "Unsupported Lakeshore Model " + model + " - should be 331, 340, 350 or 370 (372)");
            end

            %Set starting/default value
            comp.ControlModeSetDropDown.ItemsData = itemsData;
            comp.ControlModeSetDropDown.Value = defaultVal;
            comp.OnControlModeChanged();
        end

        %% InitialiseHeaterRangeDropDown
        function InitialiseHeaterRangeDropDown(comp, model)
            %Choose default starting value
            switch(model)
                case("331")
                    defaultVal = comp.LS331_HeaterRange("Off");
                case("340")
                    defaultVal = comp.LS350_HeaterRange("Range 2");
                case("350")
                    defaultVal = comp.LS350_HeaterRange("Range 2");
                case("370")
                    defaultVal = comp.LS370_HeaterRange("Off (0)");
                otherwise
                    error("LakeshoreTempControlError:UnsupportedInstrument", "%s", "Unsupported Lakeshore Model " + model + " - should be 331, 340, 350 or 370 (372)");
            end

            %Unpack all the members of the defined categorical
            catVals = categories(defaultVal);
            catStrs = string(catVals)';

            %Set the Items and Items data
            comp.HeaterRangeSetDropDown.Items = catStrs;

            %Set categorical type as the underlying data type, not just the displayed string
            switch(model)
                case("331")
                    itemsData = comp.LS331_HeaterRange(catStrs);    
                case("340")
                    itemsData = comp.LS350_HeaterRange(catStrs);    
                case("350")
                    itemsData = comp.LS350_HeaterRange(catStrs);    
                case("370")
                    itemsData = comp.LS370_HeaterRange(catStrs);  
                otherwise
                    error("LakeshoreTempControlError:UnsupportedInstrument", "%s", "Unsupported Lakeshore Model " + model + " - should be 331, 340, 350 or 370 (372)");
            end
            
            %Set starting/default value
            comp.HeaterRangeSetDropDown.ItemsData = itemsData;
            comp.HeaterRangeSetDropDown.Value = defaultVal;
        end

        %% GrabSetData
        function settingStruct = GrabSetData(comp)
            %Grab all the values in the Set panel and package them into a
            %nice easy to handle struct
            settingStruct.ControlMode = comp.ControlModeSetDropDown.Value;
            settingStruct.HeaterRange = comp.HeaterRangeSetDropDown.Value;
            settingStruct.SetPoint = comp.SetPointSetEditField.Value;
            settingStruct.RampRate = comp.RampRateSetEditField.Value;
            settingStruct.RampEnabled = comp.RampEnabledSetCheckbox.Value;
            settingStruct.ManualOutput = comp.ManualOutputSetEditField.Value;
            settingStruct.PID_Settings.P = comp.PID_P_SetEditField.Value;
            settingStruct.PID_Settings.I = comp.PID_I_SetEditField.Value;
            settingStruct.PID_Settings.D = comp.PID_D_SetEditField.Value;
        end

        %% GetSIPrefixAndScale
        function [prefixStr, scaleFactor] = GetSIPrefixAndScale(~, data)
            % GetSIPrefixAndScale(data)
            
            maxVal = max(abs(data), [], 'all', 'omitnan') / 1000;
            if(isnan(maxVal) || maxVal == 0)
                prefixStr = '';
                scaleFactor = 1;
                return;
            end

            log10Max = log10(maxVal);
            log10Max = ceil(log10Max);
         
            log103Max = ceil(log10Max/3) * 3;
            scaleFactor = 10^log103Max;

            prefixStr = Palladium.Utilities.MathsUtils.ConvertExponentToSIPrefix(log103Max);

            %Handle failure to get a prefix - do not scale
            if(strcmp(prefixStr, ''))
                scaleFactor = 1;
            end
        end

        %% OnControlModeChanged
        function OnControlModeChanged(comp)
            
            %Hide/show elements corresponding to only the selected control mode, for a
            %bit more user clarity
            switch(comp.ControlModeSetDropDown.Value)
                 case(comp.LS331_ControlMode("Off"))
                    comp.SetPointSetEditField.Enable = "off";
                    comp.RampRateSetEditField.Enable = "off";
                    comp.RampEnabledSetCheckbox.Enable = "off";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "off";
                    comp.PID_I_SetEditField.Enable = "off";
                    comp.PID_D_SetEditField.Enable = "off";

                 case(comp.LS331_ControlMode("Manual PID"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS331_ControlMode("Zone"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS331_ControlMode("Open Loop"))
                    comp.SetPointSetEditField.Enable = "off";
                    comp.RampRateSetEditField.Enable = "off";
                    comp.RampEnabledSetCheckbox.Enable = "off";
                    comp.ManualOutputSetEditField.Enable = "on";
                    comp.PID_P_SetEditField.Enable = "off";
                    comp.PID_I_SetEditField.Enable = "off";
                    comp.PID_D_SetEditField.Enable = "off";

                case(comp.LS331_ControlMode("AutoTune PID"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS331_ControlMode("AutoTune PI"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS331_ControlMode("AutoTune P"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                 case(comp.LS370_ControlMode("Off"))
                    comp.SetPointSetEditField.Enable = "off";
                    comp.RampRateSetEditField.Enable = "off";
                    comp.RampEnabledSetCheckbox.Enable = "off";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "off";
                    comp.PID_I_SetEditField.Enable = "off";
                    comp.PID_D_SetEditField.Enable = "off";

                case(comp.LS370_ControlMode("Closed Loop PID"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS370_ControlMode("Zone"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS370_ControlMode("Open Loop"))
                    comp.SetPointSetEditField.Enable = "off";
                    comp.RampRateSetEditField.Enable = "off";
                    comp.RampEnabledSetCheckbox.Enable = "off";
                    comp.ManualOutputSetEditField.Enable = "on";
                    comp.PID_P_SetEditField.Enable = "off";
                    comp.PID_I_SetEditField.Enable = "off";
                    comp.PID_D_SetEditField.Enable = "off";

                case(comp.LS350_ControlMode("Off"))
                    comp.SetPointSetEditField.Enable = "off";
                    comp.RampRateSetEditField.Enable = "off";
                    comp.RampEnabledSetCheckbox.Enable = "off";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "off";
                    comp.PID_I_SetEditField.Enable = "off";
                    comp.PID_D_SetEditField.Enable = "off";

                case(comp.LS350_ControlMode("Closed Loop PID"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS350_ControlMode("Zone"))
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "off";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS350_ControlMode("Open Loop"))
                    comp.SetPointSetEditField.Enable = "off";
                    comp.RampRateSetEditField.Enable = "off";
                    comp.RampEnabledSetCheckbox.Enable = "off";
                    comp.ManualOutputSetEditField.Enable = "on";
                    comp.PID_P_SetEditField.Enable = "off";
                    comp.PID_I_SetEditField.Enable = "off";
                    comp.PID_D_SetEditField.Enable = "off";

                case(comp.LS350_ControlMode("Monitor Out"))
                    %Not actually supported at this time - what does this
                    %even do?
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "on";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                case(comp.LS350_ControlMode("Warmup Supply"))
                    %Not actually supported at this time - what does this
                    %even do?
                    comp.SetPointSetEditField.Enable = "on";
                    comp.RampRateSetEditField.Enable = "on";
                    comp.RampEnabledSetCheckbox.Enable = "on";
                    comp.ManualOutputSetEditField.Enable = "on";
                    comp.PID_P_SetEditField.Enable = "on";
                    comp.PID_I_SetEditField.Enable = "on";
                    comp.PID_D_SetEditField.Enable = "on";

                otherwise
                   error("LakeshoreTempControlError:UnsupportedControlMode", "%s", "Unsupported control mode " + string(comp.ControlModeSetDropDown.Value));
            end
        end

        %% Set
        function Set(comp)
            %Called by SetButtonPushed callback

            %Package all settings into a neat struct, then trigger event
            %passing that struct through
            settingStruct = comp.GrabSetData();            
            eventData = Palladium.Instruments.Events.LakeshoreHeaterControlSetEventData(settingStruct);
            notify(comp, 'HeaterSettingsInput', eventData);
        end
    end

    methods (Access = public)

        %% DisplayData
        function DisplayData(comp, settingStruct, heaterPercent, heaterEnabled, heaterPower)
            comp.ControlModeDisplayEditField.Value = strrep(string(settingStruct.ControlMode), "_", " ");
            comp.HeaterRangeDisplayEditField.Value = strrep(string(settingStruct.HeaterRange), "_", " ");
            comp.SetPointDisplayEditField.Value = settingStruct.SetPoint;

            if isnan(settingStruct.RampRate)
                comp.RampRateDisplayEditField.Value = 0;
            else
                comp.RampRateDisplayEditField.Value = settingStruct.RampRate;
            end

            if(settingStruct.RampEnabled)
                comp.RampEnabledLamp.Enable = "on";
                comp.RampEnabledLamp.Color = [0.1 0.9 0.1];
            else
                comp.RampEnabledLamp.Enable = "off";
                comp.RampEnabledLamp.Color = [0.6 0.6 0.6];
            end
            
            comp.ManualOutputDisplayEditField.Value = settingStruct.ManualOutput;
            comp.PID_P_DisplayEditField.Value = settingStruct.PID_Settings.P;
            comp.PID_I_DisplayEditField.Value = settingStruct.PID_Settings.I;
            comp.PID_D_DisplayEditField.Value = settingStruct.PID_Settings.D;

            %Heater power status            
            if(heaterEnabled)
                comp.HeaterEnabledLamp.Enable = "on";
                comp.HeaterEnabledLamp.Color = [0.1 0.9 0.1];
            else
                comp.HeaterEnabledLamp.Enable = "off";
                comp.HeaterEnabledLamp.Color = [0.6 0.6 0.6];
            end
        
            %Convert the heater power value in Watts into a more easily
            %readable SI unit, e.g. mW or uW, and display
            [prefixStr, scaleFactor] = comp.GetSIPrefixAndScale(heaterPower);

            comp.HeaterPowerDisplayEditField.Value = heaterPower / scaleFactor;
            comp.HeaterPowerPercentEditField.Value = heaterPercent;
            comp.HeaterPowerUnitsLabel.Text = string(prefixStr) + "W";
        end

        %% SetTempControllerModel
        function SetTempControllerModel(comp, modelName)
            arguments
                comp;
                modelName;% {mustBeText, mustBeMember(modelName, {"350", "370", "372"})};
            end

            %372 and 370 are the same..
            if(strcmp(modelName, "372"))
                modelName = "370";
            end

            comp.InitialiseControlModeDropDown(modelName);
            comp.InitialiseHeaterRangeDropDown(modelName);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(comp, event)
            comp.Set();
        end

        % Value changed function: ControlModeSetDropDown
        function ControlModeSetDropDownValueChanged(comp, event)
            comp.OnControlModeChanged();
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 643 385];
            comp.GridLayout = uigridlayout(comp);
            comp.HeaterControlPanel = uipanel(comp.GridLayout);
            comp.HeaterControlPanel.Title = 'Heater Control';
            comp.GridLayout2 = uigridlayout(comp.HeaterControlPanel);
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.SetButton = uibutton(comp.GridLayout3, 'push');
            comp.SetButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetButtonPushed, true);
            comp.SetButton.Tooltip = {'Changes will not take effect until the Set button is pressed'};
            comp.SetButton.Text = 'Set';
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.PEditFieldLabel = uilabel(comp.GridLayout4);
            comp.PEditFieldLabel.Text = 'P';
            comp.PID_P_SetEditField = uieditfield(comp.GridLayout4, 'numeric');
            comp.PID_P_SetEditField.Value = 50;
            comp.IEditFieldLabel = uilabel(comp.GridLayout4);
            comp.IEditFieldLabel.Text = 'I';
            comp.PID_I_SetEditField = uieditfield(comp.GridLayout4, 'numeric');
            comp.PID_I_SetEditField.Value = 10;
            comp.DEditFieldLabel = uilabel(comp.GridLayout4);
            comp.DEditFieldLabel.Text = 'D';
            comp.PID_D_SetEditField = uieditfield(comp.GridLayout4, 'numeric');
            comp.PID_D_SetEditField.Value = 5;
            comp.GridLayout5 = uigridlayout(comp.GridLayout2);
            comp.ControlModeDropDownLabel = uilabel(comp.GridLayout5);
            comp.ControlModeDropDownLabel.Text = 'Control Mode';
            comp.ControlModeSetDropDown = uidropdown(comp.GridLayout5);
            comp.ControlModeSetDropDown.Items = {};
            comp.ControlModeSetDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ControlModeSetDropDownValueChanged, true);
            comp.ControlModeSetDropDown.Value = {};
            comp.HeaterRangeDropDownLabel = uilabel(comp.GridLayout5);
            comp.HeaterRangeDropDownLabel.Text = 'Heater Range';
            comp.HeaterRangeSetDropDown = uidropdown(comp.GridLayout5);
            comp.HeaterRangeSetDropDown.Items = {};
            comp.HeaterRangeSetDropDown.Value = {};
            comp.SetPointKEditFieldLabel = uilabel(comp.GridLayout5);
            comp.SetPointKEditFieldLabel.Text = 'Set Point (K)';
            comp.SetPointSetEditField = uieditfield(comp.GridLayout5, 'numeric');
            comp.RampRateKminEditFieldLabel = uilabel(comp.GridLayout5);
            comp.RampRateKminEditFieldLabel.Text = 'Ramp Rate (K/min)';
            comp.RampRateSetEditField = uieditfield(comp.GridLayout5, 'numeric');
            comp.RampRateSetEditField.Limits = [0 Inf];
            comp.RampRateSetEditField.Value = 0.1;
            comp.RampEnabledSetCheckbox = uicheckbox(comp.GridLayout5);
            comp.RampEnabledSetCheckbox.Text = '';
            comp.RampEnabledSetCheckbox.Value = true;
            comp.RampEnabledLabel = uilabel(comp.GridLayout5);
            comp.RampEnabledLabel.Text = 'Ramp Enabled';
            comp.ManualOutputEditFieldLabel = uilabel(comp.GridLayout5);
            comp.ManualOutputEditFieldLabel.Text = 'Manual Output (%)';
            comp.ManualOutputSetEditField = uieditfield(comp.GridLayout5, 'numeric');
            comp.ManualOutputSetEditField.Limits = [0 100];
            comp.HeaterStatusPanel = uipanel(comp.GridLayout);
            comp.HeaterStatusPanel.Title = 'Heater Status';
            comp.GridLayout6 = uigridlayout(comp.HeaterStatusPanel);
            comp.GridLayout7 = uigridlayout(comp.GridLayout6);
            comp.PEditField_2Label = uilabel(comp.GridLayout7);
            comp.PEditField_2Label.Text = 'P';
            comp.PID_P_DisplayEditField = uieditfield(comp.GridLayout7, 'numeric');
            comp.PID_P_DisplayEditField.AllowEmpty = 'on';
            comp.PID_P_DisplayEditField.Editable = 'off';
            comp.PID_P_DisplayEditField.Value = [];
            comp.IEditField_2Label = uilabel(comp.GridLayout7);
            comp.IEditField_2Label.Text = 'I';
            comp.PID_I_DisplayEditField = uieditfield(comp.GridLayout7, 'numeric');
            comp.PID_I_DisplayEditField.AllowEmpty = 'on';
            comp.PID_I_DisplayEditField.Editable = 'off';
            comp.PID_I_DisplayEditField.Value = [];
            comp.DEditField_2Label = uilabel(comp.GridLayout7);
            comp.DEditField_2Label.Text = 'D';
            comp.PID_D_DisplayEditField = uieditfield(comp.GridLayout7, 'numeric');
            comp.PID_D_DisplayEditField.AllowEmpty = 'on';
            comp.PID_D_DisplayEditField.Editable = 'off';
            comp.PID_D_DisplayEditField.Value = [];
            comp.GridLayout8 = uigridlayout(comp.GridLayout6);
            comp.HeaterPowerLabel = uilabel(comp.GridLayout8);
            comp.HeaterPowerLabel.Text = 'Heater Power';
            comp.GridLayout9 = uigridlayout(comp.GridLayout8);
            comp.HeaterPowerDisplayEditField = uieditfield(comp.GridLayout9, 'numeric');
            comp.HeaterPowerDisplayEditField.AllowEmpty = 'on';
            comp.HeaterPowerDisplayEditField.Editable = 'off';
            comp.HeaterPowerDisplayEditField.Value = [];
            comp.HeaterPowerUnitsLabel = uilabel(comp.GridLayout9);
            comp.HeaterPowerUnitsLabel.Interpreter = 'latex';
            comp.HeaterPowerUnitsLabel.Text = 'W';
            comp.GridLayout11 = uigridlayout(comp.GridLayout9);
            comp.HeaterEnabledLamp = uilamp(comp.GridLayout11);
            comp.HeaterEnabledLamp.Enable = 'off';
            comp.Label = uilabel(comp.GridLayout9);
            comp.Label.Text = '%';
            comp.HeaterPowerPercentEditField = uieditfield(comp.GridLayout9, 'numeric');
            comp.HeaterPowerPercentEditField.AllowEmpty = 'on';
            comp.HeaterPowerPercentEditField.Value = [];
            comp.GridLayout10 = uigridlayout(comp.GridLayout6);
            comp.ControlModeEditFieldLabel = uilabel(comp.GridLayout10);
            comp.ControlModeEditFieldLabel.Text = 'Control Mode';
            comp.ControlModeDisplayEditField = uieditfield(comp.GridLayout10, 'text');
            comp.ControlModeDisplayEditField.Editable = 'off';
            comp.HeaterRangeEditFieldLabel = uilabel(comp.GridLayout10);
            comp.HeaterRangeEditFieldLabel.Text = 'Heater Range';
            comp.HeaterRangeDisplayEditField = uieditfield(comp.GridLayout10, 'text');
            comp.HeaterRangeDisplayEditField.Editable = 'off';
            comp.SetPointKEditField_2Label = uilabel(comp.GridLayout10);
            comp.SetPointKEditField_2Label.Text = 'Set Point (K)';
            comp.SetPointDisplayEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.SetPointDisplayEditField.AllowEmpty = 'on';
            comp.SetPointDisplayEditField.Editable = 'off';
            comp.SetPointDisplayEditField.Value = [];
            comp.RampRateKminEditField_2Label = uilabel(comp.GridLayout10);
            comp.RampRateKminEditField_2Label.Text = 'Ramp Rate (K/min)';
            comp.RampRateDisplayEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.RampRateDisplayEditField.AllowEmpty = 'on';
            comp.RampRateDisplayEditField.Editable = 'off';
            comp.RampRateDisplayEditField.Value = [];
            comp.ManualOutputLabel = uilabel(comp.GridLayout10);
            comp.ManualOutputLabel.Text = 'Manual Output (%)';
            comp.ManualOutputDisplayEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.ManualOutputDisplayEditField.AllowEmpty = 'on';
            comp.ManualOutputDisplayEditField.Editable = 'off';
            comp.ManualOutputDisplayEditField.Value = [];
            comp.GridLayout11_2 = uigridlayout(comp.GridLayout10);
            comp.RampEnabledLamp = uilamp(comp.GridLayout11_2);
            comp.RampEnabledLabel_2 = uilabel(comp.GridLayout10);
            comp.RampEnabledLabel_2.Text = 'Ramp Enabled';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end