classdef MagnetControlPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout_StatusInfo          matlab.ui.container.GridLayout
        Display_CurrentAEditField      matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel         matlab.ui.control.Label
        Display_FieldTEditField        matlab.ui.control.NumericEditField
        FieldTEditFieldLabel           matlab.ui.control.Label
        Display_StatusEditField        matlab.ui.control.EditField
        StatusEditFieldLabel           matlab.ui.control.Label
        Display_RampRateTminEditField  matlab.ui.control.NumericEditField
        RampRateTminEditField_2Label   matlab.ui.control.Label
        Display_SetpointTEditField     matlab.ui.control.NumericEditField
        SetpointTEditField_2Label      matlab.ui.control.Label
        GridLayout_CommandButtons      matlab.ui.container.GridLayout
        HoldButton                     matlab.ui.control.Button
        RampToZeroButton               matlab.ui.control.Button
        ToSetpointButton               matlab.ui.control.Button
        GridLayout_ParameterValues     matlab.ui.container.GridLayout
        SetRampRateButton              matlab.ui.control.Button
        RampRateTminEditField          matlab.ui.control.NumericEditField
        RampRateTminEditFieldLabel     matlab.ui.control.Label
        SetSetPointButton              matlab.ui.control.Button
        SetpointTEditField             matlab.ui.control.NumericEditField
        SetpointTEditFieldLabel        matlab.ui.control.Label
        MagnetControlLabel             matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        HoldCommandGiven
        RampRateChanged
        RampToZeroCommandGiven
        SetPointChanged
        ToSetPointCommandGiven
    end

    
    methods (Access = private)
        %% OnHoldButtonPushed
        function OnHoldButtonPushed(comp)
            notify(comp, "HoldCommandGiven");
        end

        %% OnRampToZeroButtonPushed
        function OnRampToZeroButtonPushed(comp)
            notify(comp, "RampToZeroCommandGiven");
        end

        %% OnSetRampRateButtonPushed
        function OnSetRampRateButtonPushed(comp, rampRate_Tmin)
            eventArgs = Palladium.Events.ValueChangedEventData(rampRate_Tmin);
            notify(comp, "RampRateChanged", eventArgs); 
        end

        %% OnSetSetPointButtonPushed
        function OnSetSetPointButtonPushed(comp, setPoint_T)
            eventArgs = Palladium.Events.ValueChangedEventData(setPoint_T);
            notify(comp, "SetPointChanged", eventArgs); 
        end

        %% OnToSetPointButtonPushed
        function OnToSetPointButtonPushed(comp)
            notify(comp, "ToSetPointCommandGiven");
        end
    end
    
    methods (Access = public)
        
        %% SetName
        function SetName(comp, nameString)
            comp.MagnetControlLabel.Text = nameString;
        end

        %% UpdateDisplayedStatus
        function UpdateDisplayedStatus(comp, statusStruct)
            try
                comp.Display_CurrentAEditField.Value = statusStruct.Current_A;
            catch err
                comp.Display_CurrentAEditField.Value = [];
            end

            try
                comp.Display_FieldTEditField.Value = statusStruct.Field_T;
            catch err
                comp.Display_FieldTEditField.Value = [];
            end

            try
                comp.Display_RampRateTminEditField.Value = statusStruct.RampRate_Tmin;
            catch err
                comp.Display_RampRateTminEditField.Value = [];
            end
            try
                comp.Display_SetpointTEditField.Value = statusStruct.SetPoint_T;
            catch err
                comp.Display_SetpointTEditField.Value = [];
            end
            try
                comp.Display_StatusEditField.Value = string(statusStruct.StatusString);
            catch err
                comp.Display_StatusEditField.Value = [];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ToSetpointButton
        function ToSetpointButtonPushed(comp, event)
            try
                comp.OnToSetPointButtonPushed();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in ToSetpointButtonPushed", err);
            end
        end

        % Button pushed function: RampToZeroButton
        function RampToZeroButtonPushed(comp, event)
            try
                comp.OnRampToZeroButtonPushed();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in RampToZeroButtonPushed", err);
            end
        end

        % Button pushed function: HoldButton
        function HoldButtonPushed(comp, event)
            try
                comp.OnHoldButtonPushed();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in HoldButtonPushed", err);
            end
        end

        % Button pushed function: SetRampRateButton
        function SetRampRateButtonPushed(comp, event)
            try
                rampRamp_Tmin = comp.RampRateTminEditField.Value;
                comp.OnSetRampRateButtonPushed(rampRamp_Tmin);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in SetRampRateButtonPushed", err);
            end
        end

        % Button pushed function: SetSetPointButton
        function SetSetPointButtonPushed(comp, event)
            try
                setPoint_T = comp.SetpointTEditField.Value;
                comp.OnSetSetPointButtonPushed(setPoint_T);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in SetSetPointButtonPushed", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 489 485];
            comp.GridLayout = uigridlayout(comp);
            comp.MagnetControlLabel = uilabel(comp.GridLayout);
            comp.MagnetControlLabel.Text = 'Magnet Control';
            comp.GridLayout_ParameterValues = uigridlayout(comp.GridLayout);
            comp.SetpointTEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.SetpointTEditFieldLabel.Text = 'Setpoint (T)';
            comp.SetpointTEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.SetpointTEditField.Limits = [-6 6];
            comp.SetSetPointButton = uibutton(comp.GridLayout_ParameterValues, 'push');
            comp.SetSetPointButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetSetPointButtonPushed, true);
            comp.SetSetPointButton.Text = 'Set';
            comp.RampRateTminEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.RampRateTminEditFieldLabel.Text = 'Ramp Rate (T/min)';
            comp.RampRateTminEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.RampRateTminEditField.Limits = [0 10];
            comp.RampRateTminEditField.Value = 0.3;
            comp.SetRampRateButton = uibutton(comp.GridLayout_ParameterValues, 'push');
            comp.SetRampRateButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetRampRateButtonPushed, true);
            comp.SetRampRateButton.Text = 'Set';
            comp.GridLayout_CommandButtons = uigridlayout(comp.GridLayout);
            comp.ToSetpointButton = uibutton(comp.GridLayout_CommandButtons, 'push');
            comp.ToSetpointButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ToSetpointButtonPushed, true);
            comp.ToSetpointButton.Text = 'To Setpoint';
            comp.RampToZeroButton = uibutton(comp.GridLayout_CommandButtons, 'push');
            comp.RampToZeroButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RampToZeroButtonPushed, true);
            comp.RampToZeroButton.Text = 'Ramp To Zero';
            comp.HoldButton = uibutton(comp.GridLayout_CommandButtons, 'push');
            comp.HoldButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @HoldButtonPushed, true);
            comp.HoldButton.Text = 'Hold';
            comp.GridLayout_StatusInfo = uigridlayout(comp.GridLayout);
            comp.SetpointTEditField_2Label = uilabel(comp.GridLayout_StatusInfo);
            comp.SetpointTEditField_2Label.Text = 'Setpoint (T)';
            comp.Display_SetpointTEditField = uieditfield(comp.GridLayout_StatusInfo, 'numeric');
            comp.Display_SetpointTEditField.AllowEmpty = 'on';
            comp.Display_SetpointTEditField.Editable = 'off';
            comp.Display_SetpointTEditField.Value = [];
            comp.RampRateTminEditField_2Label = uilabel(comp.GridLayout_StatusInfo);
            comp.RampRateTminEditField_2Label.Text = 'Ramp Rate (T/min)';
            comp.Display_RampRateTminEditField = uieditfield(comp.GridLayout_StatusInfo, 'numeric');
            comp.Display_RampRateTminEditField.AllowEmpty = 'on';
            comp.Display_RampRateTminEditField.Editable = 'off';
            comp.Display_RampRateTminEditField.Value = [];
            comp.StatusEditFieldLabel = uilabel(comp.GridLayout_StatusInfo);
            comp.StatusEditFieldLabel.Text = 'Status';
            comp.Display_StatusEditField = uieditfield(comp.GridLayout_StatusInfo, 'text');
            comp.Display_StatusEditField.Editable = 'off';
            comp.FieldTEditFieldLabel = uilabel(comp.GridLayout_StatusInfo);
            comp.FieldTEditFieldLabel.Text = 'Field (T)';
            comp.Display_FieldTEditField = uieditfield(comp.GridLayout_StatusInfo, 'numeric');
            comp.Display_FieldTEditField.AllowEmpty = 'on';
            comp.Display_FieldTEditField.Editable = 'off';
            comp.Display_FieldTEditField.Value = [];
            comp.CurrentAEditFieldLabel = uilabel(comp.GridLayout_StatusInfo);
            comp.CurrentAEditFieldLabel.Text = 'Current (A)';
            comp.Display_CurrentAEditField = uieditfield(comp.GridLayout_StatusInfo, 'numeric');
            comp.Display_CurrentAEditField.AllowEmpty = 'on';
            comp.Display_CurrentAEditField.Value = [];
        end
    end
end