classdef ScanFileSetupControlPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout_RunButtonCentring  matlab.ui.container.GridLayout
        InsertAutonamingtagButton     matlab.ui.control.Button
        ContinuousCheckBox            matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        FileNameEditField             matlab.ui.control.EditField
        SavetonewfileCheckBox         matlab.ui.control.CheckBox
        DataColumnAddPanel            Palladium.Instruments.Controls.DataColumnAddPanel
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        AbortPushed
        InsertSmartTag
        RunPushed
        ScanDataChange
        SelectedDataColumnsChanged
    end

    properties (Access = public)
        ExtraDataColumnsHeaders = [];
    end
    
    
    methods (Access = private)
        
        function ExtraDataColumnsSelectionChanged(comp, value)
            comp.ExtraDataColumnsHeaders = value;
            eventArgs = Palladium.Events.ValueChangedEventData(value);
            notify(comp, 'SelectedDataColumnsChanged', eventArgs);
        end
        
        function ScanDataChanged(comp)
            value = comp.CollectScanDetails();
            eventArgs = Palladium.Events.ValueChangedEventData(value);
            notify(comp, "ScanDataChange", eventArgs);            
        end

    end
    
    methods (Access = public)

        %% Abort
        function Abort(comp)
            notify(comp, "AbortPushed");
        end

        %% CollectScanDetails
        function scanDetails = CollectScanDetails(comp)
            %Package up all the values entered in to the GUI to send back

            %File saving
            scanDetails.SaveSweepFile = comp.SavetonewfileCheckBox.Value;
            scanDetails.FileName = comp.FileNameEditField.Value;
        end

        %% DisableRunButton
        function DisableRunButton(comp)
            comp.RunButton.Enable = "off";
        end

        %% EnableRunButton
        function EnableRunButton(comp)
            comp.RunButton.Enable = "on";
        end

        %% HideDataColumnAddPanel
        function HideDataColumnAddPanel(comp)
            comp.DataColumnAddPanel.Visible = false;
        end

        %% IsContinuousSelected
        function cont = IsContinuousSelected(comp)
            cont = comp.ContinuousCheckBox.Value;
        end

        %% OnScanDataChanged
        function OnScanDataChanged(comp, sweepDetails)
        end

        %% Run
        function Run(comp)
            if comp.SavetonewfileCheckBox.Value
                if isempty(comp.FileNameEditField.Value) || strcmp(comp.FileNameEditField.Value, "")
                    Palladium.Utilities.GUIUtils.ShowWarning(comp, "Cannot run with empty sweep name (would write to main file path, in conflict with overall Palladium instance). Please enter a Sweep Name, or untick Save To File to disable file saving", "Empty Sweep Name");
                    return;
                end
            end

            notify(comp, "RunPushed");
        end    

        %% SetContinuous
        function SetContinuous(comp, tf)
            %Tick or untick Continuous (scan again as soon as one finishes), as the user does - for sequences and scripts.
            comp.ContinuousCheckBox.Value = logical(tf);
        end

        %% SetSaveToFile
        function SetSaveToFile(comp, tf)
            %Tick or untick Save to new file, as the user does (the sweep name field follows it) - for sequences and scripts.
            comp.SavetonewfileCheckBox.Value = logical(tf);
            comp.SavetonewfileCheckBoxValueChanged([]);
        end

        %% SetNewSweepName
        function SetNewSweepName(comp, str)
            comp.FileNameEditField.Value = str;
        end

         %% SetReady
        function SetReady(comp)
            comp.RunButton.Text = "Run";
            comp.RunButton.BackgroundColor = [0.89,0.94,0.80];
            comp.InsertAutonamingtagButton.Enable = "on";
        end

        %% SetRunning
        function SetRunning(comp)
            comp.RunButton.Text = "Abort";
            comp.RunButton.BackgroundColor = [0.82,0.66,0.66];
            comp.InsertAutonamingtagButton.Enable = "off";
        end

        %% ShowDataColumnAddPanel
        function ShowDataColumnAddPanel(comp)
            comp.DataColumnAddPanel.Visible = true;
        end

        %% UpdateAvailableDataColumnHeaders
        function UpdateAvailableDataColumnHeaders(comp, headers)
            comp.DataColumnAddPanel.PopulateDataColumnHeadersList(headers);
            comp.InsertAutonamingtagButton.Enable = "on"; %Only enable once we have a list of headers
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            Palladium.Core.ErrorGuard.AddListener(comp.DataColumnAddPanel, 'SelectedDataColumnsChanged', @(src,evnt)comp.ExtraDataColumnsSelectionChanged(evnt.Value));
        end

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            try
                if(strcmp(comp.RunButton.Text, "Run"))
                    comp.Run();
                else
                    comp.Abort();
                end
            catch err
                Palladium.Core.ErrorGuard.HandleError("RunButtonPushed", err);
            end
        end

        % Button pushed function: InsertAutonamingtagButton
        function InsertAutonamingtagButtonPushed(comp, event)
            try
                eventArgs = Palladium.Events.ValueChangedEventData(comp.FileNameEditField.Value);
                notify(comp, "InsertSmartTag", eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("InsertAutonamingtagButtonPushed", err);
            end
        end

        % Value changed function: SavetonewfileCheckBox
        function SavetonewfileCheckBoxValueChanged(comp, event)
            try
                value = comp.SavetonewfileCheckBox.Value;

                %Toggle the editing of the file name edit field as a visual
                %clue
                if value
                    comp.FileNameEditField.Enable = "on";
                else
                    comp.FileNameEditField.Enable = "off";
                end

                comp.ScanDataChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("SavetonewfileCheckBoxValueChanged", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 528 262];
            comp.GridLayout = uigridlayout(comp);
            comp.DataColumnAddPanel = Palladium.Instruments.Controls.DataColumnAddPanel(comp.GridLayout);
            comp.SavetonewfileCheckBox = uicheckbox(comp.GridLayout);
            comp.SavetonewfileCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SavetonewfileCheckBoxValueChanged, true);
            comp.SavetonewfileCheckBox.Tooltip = {'Select this to cause a seperate named data file to be created for each time Run is hit, with the specified name'};
            comp.SavetonewfileCheckBox.Text = '  Save to new file';
            comp.FileNameEditField = uieditfield(comp.GridLayout, 'text');
            comp.FileNameEditField.Enable = 'off';
            comp.FileNameEditField.Tooltip = {'Filename of the saved data will be the current overall file name, with this text appended. Enclose names of parameters in [brackets] to auto-populate names with data from the measurements, eg.'; ''; '"Sweep at T = [Sample Temperature(K),%6.2f] K"'; ''; 'The second argument, and its comma delimiter, are optional, and tell the code how to format the numerical value as string. '; ''; 'Common options for the format string are:'; ''; '%.2f - round to 2d.p.'; ''; '%.3G - round to 3 s.f., no exponent notation'};
            comp.FileNameEditField.Value = ' - Sweep File';
            comp.GridLayout_RunButtonCentring = uigridlayout(comp.GridLayout);
            comp.RunButton = uibutton(comp.GridLayout_RunButtonCentring, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Enable = 'off';
            comp.RunButton.Text = 'Run';
            comp.ContinuousCheckBox = uicheckbox(comp.GridLayout_RunButtonCentring);
            comp.ContinuousCheckBox.Tooltip = {'Set to true to cause a new Sweep to immediately Run once the previous one finishes.'};
            comp.ContinuousCheckBox.Text = 'Continuous';
            comp.InsertAutonamingtagButton = uibutton(comp.GridLayout_RunButtonCentring, 'push');
            comp.InsertAutonamingtagButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InsertAutonamingtagButtonPushed, true);
            comp.InsertAutonamingtagButton.Enable = 'off';
            comp.InsertAutonamingtagButton.Tooltip = {'Add smart text like [Sample Temperature(K),%6.2f] to the Sweep File name above, which will auto-update with the values corresponding to that data column.'};
            comp.InsertAutonamingtagButton.Text = 'Insert Auto-naming tag ';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end