classdef SweepSectionsControl < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        Panel       matlab.ui.container.Panel
        Image1_H    matlab.ui.control.Image
        Image6_H    matlab.ui.control.Image
        Image5_H    matlab.ui.control.Image
        Image4_H    matlab.ui.control.Image
        Image3_H    matlab.ui.control.Image
        Image2_H    matlab.ui.control.Image
        Image6      matlab.ui.control.Image
        Slider      matlab.ui.control.RangeSlider
        Image5      matlab.ui.control.Image
        Image4      matlab.ui.control.Image
        Image3      matlab.ui.control.Image
        Image2      matlab.ui.control.Image
        Image1      matlab.ui.control.Image
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        SelectionChanged
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Enable (1,1) string {mustBeMember(Enable, ["on","off"])} = "on";
    end
    
    
    methods (Access = private)
        
        %% HighlightRange
        function HighlightRange(comp, min, max)
            for i = 1 : min
                comp.UnHighlightPanel(i);
            end

            for i = min + 1 : max
                comp.HighlightPanel(i);
            end

            for i = max + 1 : 6
                if(i < 7)
                    comp.UnHighlightPanel(i);
                end
            end

        end

        %% HighlightPanel
        function HighlightPanel(comp, n)

            pnlToHide = comp.GetPanelFromIndex(n);
            pnlToShow = comp.GetHighlightedPanelFromIndex(n);

            pnlToHide.Parent = comp.Panel;

            pnlToShow.Parent = comp.GridLayout;
            pnlToShow.Layout.Row = 1;
            pnlToShow.Layout.Column = n + 1;
            
        end


        %% UnHighlightPanel
        function UnHighlightPanel(comp, n)

            pnlToHide = comp.GetHighlightedPanelFromIndex(n);
            pnlToShow = comp.GetPanelFromIndex(n);

            pnlToHide.Parent = comp.Panel;

            pnlToShow.Parent = comp.GridLayout;
            pnlToShow.Layout.Row = 1;
            pnlToShow.Layout.Column = n + 1;
            
        end

         %% GetHighlightedPanelFromIndex
         function img = GetHighlightedPanelFromIndex(comp, n)
             
            switch(n)
                case(1)
                    img = comp.Image1_H;
                case(2)
                    img = comp.Image2_H;
                case(3)
                    img = comp.Image3_H;
                case(4)
                    img = comp.Image4_H;
                case(5)
                    img = comp.Image5_H;
                case(6)
                    img = comp.Image6_H;
                otherwise
                    error("SweepSectionsControlError:InvalidIndex", "Invalid index");
            end
         end

         %% GetPanelFromIndex
         function img = GetPanelFromIndex(comp, n)
             
            switch(n)
                case(1)
                    img = comp.Image1;
                case(2)
                    img = comp.Image2;
                case(3)
                    img = comp.Image3;
                case(4)
                    img = comp.Image4;
                case(5)
                    img = comp.Image5;
                case(6)
                    img = comp.Image6;
                otherwise
                    error("SweepSectionsControlError:InvalidIndex", "Invalid index");
            end
        end

        %% OnSliderValueChanged
        function OnSliderValueChanged(comp, min, max)
            comp.HighlightRange(min, max); 

            %Trigger event
            eventData = Palladium.Instruments.Events.SweepSectionsEventData(min, max);
            notify(comp, 'SelectionChanged', eventData);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            %Make highlighting correspond to the slider positions
            comp.OnSliderValueChanged(comp.Slider.Value(1), comp.Slider.Value(2));
        end

        % Value changed function: Slider
        function SliderValueChanged(comp, event)
            try
                value = comp.Slider.Value;
                comp.Slider.Value = round(value);
                comp.OnSliderValueChanged(comp.Slider.Value(1), comp.Slider.Value(2));
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in SliderValueChanged", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.Slider.Enable = comp.Enable;
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 324 254];
            comp.GridLayout = uigridlayout(comp);
            comp.Image1 = uiimage(comp.GridLayout);
            comp.Image1.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage1.png');
            comp.Image2 = uiimage(comp.GridLayout);
            comp.Image2.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage2.png');
            comp.Image3 = uiimage(comp.GridLayout);
            comp.Image3.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage3.png');
            comp.Image4 = uiimage(comp.GridLayout);
            comp.Image4.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage4.png');
            comp.Image5 = uiimage(comp.GridLayout);
            comp.Image5.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage5.png');
            comp.Slider = uislider(comp.GridLayout, 'range');
            comp.Slider.Limits = [0 6];
            comp.Slider.MajorTicks = [];
            comp.Slider.MajorTickLabels = {''};
            comp.Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderValueChanged, true);
            comp.Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6];
            comp.Slider.Value = [0 4];
            comp.Image6 = uiimage(comp.GridLayout);
            comp.Image6.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage6.png');
            comp.Panel = uipanel(comp.GridLayout);
            comp.Panel.Visible = 'off';
            comp.Image2_H = uiimage(comp.Panel);
            comp.Image2_H.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage2-H.png');
            comp.Image3_H = uiimage(comp.Panel);
            comp.Image3_H.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage3-H.png');
            comp.Image4_H = uiimage(comp.Panel);
            comp.Image4_H.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage4_H.png');
            comp.Image5_H = uiimage(comp.Panel);
            comp.Image5_H.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage5_H.png');
            comp.Image6_H = uiimage(comp.Panel);
            comp.Image6_H.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage6_H.png');
            comp.Image1_H = uiimage(comp.Panel);
            comp.Image1_H.ImageSource = fullfile(pathToMLAPP, 'resources', 'SweepSelectorImage1_H.png');
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end