classdef SweepSetupControl_Ramp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        SweepSectionsControl          Palladium.Instruments.Controls.SweepSectionsControl
        GridLayout_RunButtonCentring  matlab.ui.container.GridLayout
        RampToZeroButton              matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        SweepSetupLabel               matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout11                  matlab.ui.container.GridLayout
        UnitsLabel4                   matlab.ui.control.Label
        RemainingTimeEditField        matlab.ui.control.EditField
        CurrentValueLabel             matlab.ui.control.Label
        CurrentValueEditField         matlab.ui.control.NumericEditField
        GridLayout10                  matlab.ui.container.GridLayout
        UnitsLabel5                   matlab.ui.control.Label
        Ratemin1EditField             matlab.ui.control.NumericEditField
        Ratemin1Label                 matlab.ui.control.Label
        ListBox                       matlab.ui.control.ListBox
        GridLayout2                   matlab.ui.container.GridLayout
        UnitsLabel3                   matlab.ui.control.Label
        UnitsLabel2                   matlab.ui.control.Label
        UnitsLabel1                   matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MinEditFieldLabel             matlab.ui.control.Label
        MidValueEditField             matlab.ui.control.NumericEditField
        MidValueEditFieldLabel        matlab.ui.control.Label
        MaxEditField                  matlab.ui.control.NumericEditField
        MaxEditFieldLabel             matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        Abort
        RampToZero
        Run
        SweepDataChange
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        MaxVal (1,1) double = 1;
        MidVal (1,1) double = 0;
        MinVal (1,1) double = -1;
    end
    
    properties (Access = private)
        EndSectionNo = 4;
        RampRate_min (1,1) double = 10;
        StartSectionNo = 0;
        TotalTimeMin (1,1) double = 1;
        UnitsStr (1,1) string = "";
    end
    

    methods (Access = public)

        %% SetStartingValues
        function SetStartingValues(comp, min, mid, max)
            %Clamp all values to be within limits
            comp.MinVal = comp.ClampVals(min, comp.MinEditField);
            comp.MidVal = comp.ClampVals(mid, comp.MidValueEditField);
            comp.MaxVal = comp.ClampVals(max, comp.MaxEditField);

            %Trigger an update to send these to the controller
            comp.SweepDataChanged();
        end

        %% SetDefaultRatePerMin
        function SetDefaultRatePerMin(comp, rate)
            comp.Ratemin1EditField.Value = rate;
            comp.RatePerMin = rate;
        end

        %% SetLimits
        function SetLimits(comp, min, max)
            comp.MinEditField.Limits = [min max];
            comp.MidValueEditField.Limits = [min max];
            comp.MaxEditField.Limits = [min max];
        end

        %% SetTitle
        function SetTitle(comp, title)
            comp.SweepSetupLabel.Text = title;
        end

        %% SetUnitsString
        function SetUnitsString(comp, unitsStr)
            comp.UnitsLabel1.Text = unitsStr;
            comp.UnitsLabel2.Text = unitsStr;
            comp.UnitsLabel3.Text = unitsStr;
            comp.UnitsLabel4.Text = unitsStr;
            comp.UnitsLabel5.Text = unitsStr;
            comp.UnitsStr = unitsStr;
        end

        %% ShowCurrentValue
        function ShowCurrentValue(comp, currentValue)
            comp.CurrentValueEditField.Value = currentValue;
        end

        %% SweepComplete
        function SweepComplete(comp)
            %Call this from external code once the sweep is complete and we are ready to run
            %once again
            comp.SetReady();
        end

        %% OnAbortButtonPushed
        function OnAbortButtonPushed(comp)

            %Return to state ready to Run again
            comp.SetReady();

            %Trigger event
            notify(comp, 'Abort');
        end

        %% OnRampToZeroButtonPushed
        function OnRampToZeroButtonPushed(comp)
            %Trigger event
            notify(comp, 'RampToZero');
        end

        %% OnRunButtonPushed
        function OnRunButtonPushed(comp)
            %Trigger an update to send sweep detail values to the controller
            comp.SweepDataChanged();

            %Turn Run button into Abort button
            comp.SetRunning();

            %Trigger event
            notify(comp, 'Run');
        end

        %% OnSweepDataChanged
        function OnSweepDataChanged(comp, sweepDetails)
            %Called by logic controllers after they have recieved events about values changing - update the GUI to reflect the new data

            comp.TotalTimeMin = sweepDetails.TotalTimeMin;
            comp.UpdateTimeRemainingDisplay(comp.TotalTimeMin);

            %Extremal values
            comp.MinEditField.Value = sweepDetails.MinVal;
            comp.MinVal = sweepDetails.MinVal;
            comp.MidValueEditField.Value = sweepDetails.MidVal;
            comp.MidVal = sweepDetails.MidVal;
            comp.MaxEditField.Value = sweepDetails.MaxVal;
            comp.MaxVal = sweepDetails.MaxVal;

            %Sweep speed params
            comp.RampRate_min = sweepDetails.RampRate_min;

            %Update the list of points displayed
            comp.ShowSweepPoints(sweepDetails, comp.UnitsStr);
        end

        %% UpdateTimeRemainingDisplay
        function UpdateTimeRemainingDisplay(comp, remainingTimeMin)
            remainingTimeMin = max([remainingTimeMin, 0]);
            duration = minutes(remainingTimeMin);
            s = string(duration,'hh:mm:ss');
            comp.RemainingTimeEditField.Value = s;
        end

    end

    methods (Access = private)

        %% ClampVals
        function newVal = ClampVals(~, val, editFieldRef)
            if(val > editFieldRef.Limits(2))
                newVal = editFieldRef.Limits(2);
            elseif(val < editFieldRef.Limits(1))
                newVal = editFieldRef.Limits(1);
            else
                newVal = val;
            end
        end

        %% CollectSweepDetails
        function sweepDetails = CollectSweepDetails(comp)
            %Package up all the values entered in to the GUI to send back

            %Extremal values
            sweepDetails.MinVal = comp.MinVal;
            sweepDetails.MidVal = comp.MidVal;
            sweepDetails.MaxVal = comp.MaxVal;

            %Sweep speed params
            sweepDetails.RampRate_min = comp.RampRate_min;

            %Which segments of the draggable SweepParts GUI to use, 1-6
            sweepDetails.StartSectionNo = comp.StartSectionNo;
            sweepDetails.EndSectionNo = comp.EndSectionNo;
        end

        %% SetReady
        function SetReady(comp)
            comp.RemainingTimeEditField.Value = "--:--:--";
            comp.RunButton.Text = "Run";
            comp.RunButton.BackgroundColor = [0.89,0.94,0.80];

            comp.MaxEditField.Enable = "on";
            comp.MinEditField.Enable = "on";
            comp.MidValueEditField.Enable = "on";
            comp.Ratemin1EditField.Enable = "on";

            comp.SweepSectionsControl.Enable = "on";

        end

        %% SetRunning
        function SetRunning(comp)

            comp.RunButton.Text = "Abort";
            comp.RunButton.BackgroundColor = [0.82,0.66,0.66];

            comp.MaxEditField.Enable = "off";
            comp.MinEditField.Enable = "off";
            comp.MidValueEditField.Enable = "off";

            comp.SweepSectionsControl.Enable = "off";

            comp.Ratemin1EditField.Enable = "off";

            comp.UpdateTimeRemainingDisplay(comp.TotalTimeMin);
        end

        %% ShowSweepPoints
        function ShowSweepPoints(comp, sweepData, units)

            %If sweep is empty, just clear the GUI and return
            if isempty(sweepData)
                comp.ListBox.Items = {};
                return;
            end

            %If the sweep data are there, but the list of points is empty -
            %presumably because we did something dumb like asking for a
            %step size of zero - also just return
            if isempty(sweepData.Points)
                comp.ListBox.Items = {};
                return;
            end

            values = sweepData.Points;

            %Preallocate for speed
            itemStrs = strings(length(values), 1);
            for i = 1 : length(values)
                itemStrs(i) = string(num2str(values(i))) + " " + string(units);
            end

            %Show these values in the list box which displays all the
            %points to be measured, in order
            comp.ListBox.Items = itemStrs;
        end

        %% SweepSectionsControlSelectionChanged
        function SweepSectionsControlSelectionChanged(comp, ~, eventData)
            comp.StartSectionNo = eventData.Start;
            comp.EndSectionNo = eventData.Stop;

            comp.SweepDataChanged();
        end

        %% SweepDataChanged
        function SweepDataChanged(comp)
            %Fire event to tell logic controller classes that things have
            %been changed by the user. Will cause SweepDataChanged to get
            %called (after the controller has cleaned up the data).
            sweepDetails = comp.CollectSweepDetails();
            eventArgs = Palladium.Events.ValueChangedEventData(sweepDetails);
            notify(comp, "SweepDataChange", eventArgs);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.update();
            %Subscribe to events
            Palladium.Core.ErrorGuard.AddListener(comp.SweepSectionsControl, 'SelectionChanged', @(src,evnt)comp.SweepSectionsControlSelectionChanged(src,evnt));

            %Call a controller logic update, to process through the
            %default-set values
            comp.SweepDataChanged();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            try
                if(strcmp(comp.RunButton.Text, "Run"))
                    comp.OnRunButtonPushed();
                else
                    comp.OnAbortButtonPushed();
                end
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in RunButtonPushed", err);
            end
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(comp, event)
            try
                %Make sure values are small -> big, in the right order
                if(comp.MaxEditField.Value < comp.MidValueEditField.Value)
                    comp.MaxEditField.Value = comp.MidValueEditField.Value;
                end
                value = comp.MaxEditField.Value;
                comp.MaxVal = value;

                comp.SweepDataChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in MaxEditFieldValueChanged", err);
            end
        end

        % Value changed function: MidValueEditField
        function MidValueEditFieldValueChanged(comp, event)
            try
                %Make sure values are small -> big, in the right order
                if(comp.MidValueEditField.Value > comp.MaxEditField.Value)
                    comp.MidValueEditField.Value = comp.MaxEditField.Value;
                end
                if(comp.MidValueEditField.Value < comp.MinEditField.Value)
                    comp.MidValueEditField.Value = comp.MinEditField.Value;
                end

                value = comp.MidValueEditField.Value;
                comp.MidVal = value;

                comp.SweepDataChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in MidValueEditFieldValueChanged", err);
            end
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(comp, event)
            try
                %Make sure values are small -> big, in the right order
                if(comp.MinEditField.Value > comp.MidValueEditField.Value)
                    comp.MinEditField.Value = comp.MidValueEditField.Value;
                end

                value = comp.MinEditField.Value;
                comp.MinVal = value;

                comp.SweepDataChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in MinEditFieldValueChanged", err);
            end
        end

        % Value changed function: Ratemin1EditField
        function Ratemin1EditFieldValueChanged(comp, event)
            try
                value = comp.Ratemin1EditField.Value;
                comp.RampRate_min = value;

                comp.SweepDataChanged();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in Ratemin1EditFieldValueChanged", err);
            end
        end

        % Button pushed function: RampToZeroButton
        function RampToZeroButtonPushed(comp, event)
            try
                %Abort a sweep if it is running
                if(~strcmp(comp.RunButton.Text, "Run"))
                    comp.OnAbortButtonPushed();
                end

                %Give command to ramp to zero
                comp.OnRampToZeroButtonPushed();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in RampToZeroButtonPushed", err);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 521 613];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.MaxEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MaxEditFieldLabel.Text = 'Max';
            comp.MaxEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MaxEditFieldValueChanged, true);
            comp.MaxEditField.Value = 1;
            comp.MidValueEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MidValueEditFieldLabel.Text = 'Mid Value';
            comp.MidValueEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MidValueEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MidValueEditFieldValueChanged, true);
            comp.MinEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MinEditFieldLabel.Text = 'Min';
            comp.MinEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MinEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MinEditFieldValueChanged, true);
            comp.MinEditField.Value = -1;
            comp.UnitsLabel1 = uilabel(comp.GridLayout2);
            comp.UnitsLabel1.Text = '';
            comp.UnitsLabel2 = uilabel(comp.GridLayout2);
            comp.UnitsLabel2.Text = '';
            comp.UnitsLabel3 = uilabel(comp.GridLayout2);
            comp.UnitsLabel3.Text = '';
            comp.GridLayout4 = uigridlayout(comp.GridLayout);
            comp.ListBox = uilistbox(comp.GridLayout4);
            comp.ListBox.Items = {};
            comp.ListBox.Enable = 'off';
            comp.ListBox.Value = {};
            comp.GridLayout5 = uigridlayout(comp.GridLayout4);
            comp.GridLayout10 = uigridlayout(comp.GridLayout5);
            comp.Ratemin1Label = uilabel(comp.GridLayout10);
            comp.Ratemin1Label.Interpreter = 'latex';
            comp.Ratemin1Label.Text = 'Rate (min$^{-1} )';
            comp.Ratemin1EditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.Ratemin1EditField.Limits = [0 Inf];
            comp.Ratemin1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Ratemin1EditFieldValueChanged, true);
            comp.Ratemin1EditField.Value = 10;
            comp.UnitsLabel5 = uilabel(comp.GridLayout10);
            comp.UnitsLabel5.Text = '';
            comp.GridLayout11 = uigridlayout(comp.GridLayout5);
            comp.CurrentValueEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.CurrentValueEditField.Editable = 'off';
            comp.CurrentValueLabel = uilabel(comp.GridLayout11);
            comp.CurrentValueLabel.Text = 'Current Value:';
            comp.RemainingTimeEditField = uieditfield(comp.GridLayout11, 'text');
            comp.RemainingTimeEditField.Editable = 'off';
            comp.RemainingTimeEditField.Value = '--:--:--';
            comp.UnitsLabel4 = uilabel(comp.GridLayout11);
            comp.UnitsLabel4.Text = '';
            comp.SweepSetupLabel = uilabel(comp.GridLayout);
            comp.SweepSetupLabel.Text = 'Sweep Setup';
            comp.GridLayout_RunButtonCentring = uigridlayout(comp.GridLayout);
            comp.RunButton = uibutton(comp.GridLayout_RunButtonCentring, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Text = 'Run';
            comp.RampToZeroButton = uibutton(comp.GridLayout_RunButtonCentring, 'push');
            comp.RampToZeroButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RampToZeroButtonPushed, true);
            comp.RampToZeroButton.Text = 'Ramp To Zero';
            comp.SweepSectionsControl = Palladium.Instruments.Controls.SweepSectionsControl(comp.GridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end