classdef SweepSetupControl_Stepped < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        DataColumnAddPanel            Palladium.Instruments.Controls.DataColumnAddPanel
        SweepSectionsControl          Palladium.Instruments.Controls.SweepSectionsControl
        FileNameEditField             matlab.ui.control.EditField
        SavetonewfileCheckBox         matlab.ui.control.CheckBox
        GridLayout_RunButtonCentring  matlab.ui.container.GridLayout
        InsertAutonamingtagButton     matlab.ui.control.Button
        ContinuousCheckBox            matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        SweepSetupLabel               matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        SettleTimesEditField          matlab.ui.control.NumericEditField
        SettleTimesEditFieldLabel     matlab.ui.control.Label
        UnitsLabel5                   matlab.ui.control.Label
        StepSizeEditField             matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel        matlab.ui.control.Label
        NoofStepsEditField            matlab.ui.control.NumericEditField
        NoofStepsEditFieldLabel       matlab.ui.control.Label
        Ratemin1EditField             matlab.ui.control.NumericEditField
        Ratemin1Label                 matlab.ui.control.Label
        GridLayout7                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        RemainingTimeEditField        matlab.ui.control.EditField
        UnitsLabel4                   matlab.ui.control.Label
        CurrentValueLabel             matlab.ui.control.Label
        CurrentValueEditField         matlab.ui.control.NumericEditField
        ListBox                       matlab.ui.control.ListBox
        GridLayout2                   matlab.ui.container.GridLayout
        UnitsLabel3                   matlab.ui.control.Label
        UnitsLabel2                   matlab.ui.control.Label
        UnitsLabel1                   matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MinEditFieldLabel             matlab.ui.control.Label
        MidValueEditField             matlab.ui.control.NumericEditField
        MidValueEditFieldLabel        matlab.ui.control.Label
        MaxEditField                  matlab.ui.control.NumericEditField
        MaxEditFieldLabel             matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        Abort
        InsertSmartTag
        Run
        SweepDataChange
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        MaxVal (1,1) double = 1;
        MidVal (1,1) double = 0;
        MinVal (1,1) double = -1;
        TotalSteps (1,1) double {mustBeInteger(TotalSteps)} = 1;
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        SettleTime (1,1) double = 0.2; % Settle time after changing a value but before measuring, in seconds. Up to instrument implementations how to actually use this.
    end
    
    properties (Access = private)
        EndSectionNo = 4;
        MeasurementsRunning (1,1) logical = false;
        StartSectionNo = 0;
        StepSize (1,1) double
        TargetNumSteps = [];
        TotalTimeMin (1,1) double = 1;
        UnitsStr (1,1) string = "";
    end
    
    properties (Access = public)
        ExtraDataColumnsHeaders = [];
    end
    

    methods (Access = public)
        
        %% IsContinuousSelected
        function cont = IsContinuousSelected(comp)
            cont = comp.ContinuousCheckBox.Value;
        end

        %% SetStartingValues
        function SetStartingValues(comp, min, mid, max)
            %Clamp all values to be within limits
            comp.MinVal = comp.ClampVals(min, comp.MinEditField);
            comp.MidVal = comp.ClampVals(mid, comp.MidValueEditField);
            comp.MaxVal = comp.ClampVals(max, comp.MaxEditField);
  
            %Trigger an update to send these to the controller
            comp.SweepDataChanged();
        end

        %% SetDefaultRatePerMin
        function SetDefaultRatePerMin(comp, rate)
            comp.Ratemin1EditField.Value = rate;
            comp.RatePerMin = rate;
        end
        
        %% SetLimits
        function SetLimits(comp, min, max)
            comp.MinEditField.Limits = [min max];
            comp.MidValueEditField.Limits = [min max];
            comp.MaxEditField.Limits = [min max];
        end

        %% SetTitle
        function SetTitle(comp, title)
            comp.SweepSetupLabel.Text = title;
        end

        %% SetUnitsString
        function SetUnitsString(comp, unitsStr)
            comp.UnitsLabel1.Text = unitsStr;
            comp.UnitsLabel2.Text = unitsStr;
            comp.UnitsLabel3.Text = unitsStr;
            comp.UnitsLabel4.Text = unitsStr;
            comp.UnitsLabel5.Text = unitsStr;
            comp.UnitsStr = unitsStr;
        end

        %% StepComplete
        function StepComplete(comp, ~, currentValue)
            comp.CurrentValueEditField.Value = currentValue;
            %Right now we don't show step number, but maybe we want to
            %later? So there it is, the second argument, currently ~
        end

        %% SweepComplete
        function SweepComplete(comp)
            %Call this from external code once the sweep is complete and we are ready to run
            %once again
            comp.SetReady();
        end

        %% LockRunButton
        function LockRunButton(comp)
            comp.MeasurementsRunning = false;
            comp.UpdateRunButtonEnableState();
        end

        %% UnlockRunButton
        function UnlockRunButton(comp)
            comp.MeasurementsRunning = true;
            comp.UpdateRunButtonEnableState();
        end
        
        %% OnAbortButtonPushed
        function OnAbortButtonPushed(comp)

            %Return to state ready to Run again
            comp.SetReady();
            
            %Trigger event
            notify(comp, 'Abort');
        end

        %% RunSweep
        function RunSweep(comp)            
            %Turn Run button into Abort button
            comp.SetRunning();

            %Trigger event
            notify(comp, 'Run');
        end

        %% OnRunButtonPushed
        function OnRunButtonPushed(comp)   
            comp.RunSweep();
        end 

        %% OnSweepDataChanged
        function OnSweepDataChanged(comp, sweepDetails)
            %Called by logic controllers after they have recieved events about values changing - update the GUI to reflect the new data     
         
            comp.TotalTimeMin = sweepDetails.TotalTimeMin;
            comp.UpdateTimeRemainingDisplay(comp.TotalTimeMin);

            %Extremal values
            comp.MinEditField.Value = sweepDetails.MinVal;
            comp.MinVal = sweepDetails.MinVal;
            comp.MidValueEditField.Value = sweepDetails.MidVal;
            comp.MidVal = sweepDetails.MidVal;
            comp.MaxEditField.Value = sweepDetails.MaxVal;
            comp.MaxVal = sweepDetails.MaxVal;

            %Sweep speed params
            comp.NoofStepsEditField.Value = sweepDetails.TargetNumSteps;
            %Don't set the property TargetNumSteps if it is empty, as we use that to store [] to say if we are editing StepSize instead
            if ~isempty(comp.TargetNumSteps)
                comp.TargetNumSteps = sweepDetails.TargetNumSteps;
            end
            comp.SettleTimesEditField.Value = sweepDetails.SettleTime;
            comp.SettleTime = sweepDetails.SettleTime;
            comp.StepSizeEditField.Value = sweepDetails.StepSize;
            comp.StepSize = sweepDetails.StepSize;

            %Update the list of points displayed
            comp.ShowSweepPoints(sweepDetails, comp.UnitsStr);
         
            comp.UpdateRunButtonEnableState();
        end

        %% UpdateRunButtonEnableState
        function UpdateRunButtonEnableState(comp)
            if comp.CanStart()
                comp.RunButton.Enable = "on";
            else
                comp.RunButton.Enable = "off";
            end
        end

        %% UpdateTimeRemainingDisplay
        function UpdateTimeRemainingDisplay(comp, remainingTimeMin)
            remainingTimeMin = max([remainingTimeMin, 0]);
            duration = minutes(remainingTimeMin);
            s = string(duration,'hh:mm:ss');
            comp.RemainingTimeEditField.Value = s;            
        end

        %% UpdateAvailableDataColumnHeaders
        function UpdateAvailableDataColumnHeaders(comp, headers)
            comp.DataColumnAddPanel.PopulateDataColumnHeadersList(headers);
            comp.InsertAutonamingtagButton.Enable = "on"; %Only enable once we have a list of headers
        end
        
        %% SetNewSweepName
        function SetNewSweepName(comp, str)
            comp.FileNameEditField.Value = str;
            comp.SweepDataChanged();
        end
    end

    methods (Access = private)      
      
        %% CanStart
        function canStart = CanStart(comp)
            canStart = true;

            if ~comp.MeasurementsRunning
                canStart = false;
                return;
            end

            if isempty(comp.TargetNumSteps) || comp.TargetNumSteps < 1
                if isempty(comp.StepSize) || comp.StepSize == 0 || isnan(comp.StepSize)
                    canStart = false;
                    return;
                end
            end
        end

        %% ClampVals
        function newVal = ClampVals(~, val, editFieldRef)
            if(val > editFieldRef.Limits(2))
                newVal = editFieldRef.Limits(2);
            elseif(val < editFieldRef.Limits(1))
                newVal = editFieldRef.Limits(1);
            else
                newVal = val;
            end
        end

        %% CollectSweepDetails
        function sweepDetails = CollectSweepDetails(comp)
            %Package up all the values entered in to the GUI to send back

            %Extremal values
            sweepDetails.MinVal = comp.MinVal;
            sweepDetails.MidVal = comp.MidVal;
            sweepDetails.MaxVal = comp.MaxVal;

            %Sweep speed params
            sweepDetails.TargetNumSteps = comp.TargetNumSteps;
            sweepDetails.SettleTime = comp.SettleTime;
            sweepDetails.StepSize = comp.StepSize;

            %Which segments of the draggable SweepParts GUI to use, 1-6
            sweepDetails.StartSectionNo = comp.StartSectionNo;
            sweepDetails.EndSectionNo = comp.EndSectionNo;   

            %File saving
            sweepDetails.SaveSweepFile = comp.SavetonewfileCheckBox.Value;
            sweepDetails.FileName = comp.FileNameEditField.Value;
        end
        
        %% SetReady
        function SetReady(comp)
            comp.RemainingTimeEditField.Value = "--:--:--";
            comp.RunButton.Text = "Run";
            comp.RunButton.BackgroundColor = [0.89,0.94,0.80];

            comp.MaxEditField.Enable = "on";
            comp.MinEditField.Enable = "on";
            comp.MidValueEditField.Enable = "on";
            comp.StepSizeEditField.Enable = "on";
            comp.NoofStepsEditField.Enable = "on";
            comp.SettleTimesEditField.Enable = "on";

            comp.SweepSectionsControl.Enable = "on";
            comp.InsertAutonamingtagButton.Enable = "on";
        end

        %% SetRunning
        function SetRunning(comp)

            comp.RunButton.Text = "Abort";
            comp.RunButton.BackgroundColor = [0.82,0.66,0.66];

            comp.MaxEditField.Enable = "off";
            comp.MinEditField.Enable = "off";
            comp.MidValueEditField.Enable = "off";

            comp.SweepSectionsControl.Enable = "off";

            comp.StepSizeEditField.Enable = "off";
            comp.NoofStepsEditField.Enable = "off";
            comp.SettleTimesEditField.Enable = "off";
            comp.InsertAutonamingtagButton.Enable = "off";

            comp.UpdateTimeRemainingDisplay(comp.TotalTimeMin);
        end

        %% ShowSweepPoints
        function ShowSweepPoints(comp, sweepData, units)
            
            %If sweep is empty, just clear the GUI and return
            if isempty(sweepData)
                comp.ListBox.Items = {};
                return;
            end

            %If the sweep data are there, but the list of points is empty -
            %presumably because we did something dumb like asking for a
            %step size of zero - also just return
            if isempty(sweepData.Points)
                comp.ListBox.Items = {};
                return;
            end

            values = sweepData.Points;

            %Preallocate for speed
            itemStrs = String.empty(length(values), 1);
            for i = 1 : length(values)
                itemStrs(i) = string(num2str(values(i))) + " " + string(units);
            end

            %Show these values in the list box which displays all the
            %points to be measured, in order
            comp.ListBox.Items = itemStrs;
        end

        %% SweepSectionsControlSelectionChanged
        function SweepSectionsControlSelectionChanged(comp, ~, eventData)
            comp.StartSectionNo = eventData.Start;
            comp.EndSectionNo = eventData.Stop;
            
            comp.SweepDataChanged(); 
        end

        %% UpdateNumSteps
        function UpdateNumSteps(comp)
            value = comp.StepSizeEditField.Value;
            mag = comp.CalculateTotalMagnitude();

            numSteps = round(mag/value);
            comp.NoofStepsEditField.Value = numSteps;
        end

        %% SweepDataChanged
        function SweepDataChanged(comp)   
            %Fire event to tell logic controller classes that things have
            %been changed by the user. Will cause SweepDataChanged to get
            %called (after the controller has cleaned up the data).
            sweepDetails = comp.CollectSweepDetails();
            eventArgs = Palladium.Events.ValueChangedEventData(sweepDetails);
            notify(comp, "SweepDataChange", eventArgs); 
        end

        %% ExtraDataColumnsSelectionChanged
        function ExtraDataColumnsSelectionChanged(comp, colHeadersArray)
            comp.ExtraDataColumnsHeaders = colHeadersArray;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.update();
            %Subscribe to events
            addlistener(comp.SweepSectionsControl, 'SelectionChanged', @(src,evnt)comp.SweepSectionsControlSelectionChanged(src,evnt));
            addlistener(comp.DataColumnAddPanel, 'SelectedDataColumnsChanged', @(src,evnt)comp.ExtraDataColumnsSelectionChanged(evnt.Value));

            %Call a controller logic update, to process through the
            %default-set values
            comp.SweepDataChanged();             
        end

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            if(strcmp(comp.RunButton.Text, "Run"))
                comp.OnRunButtonPushed();
            else
                comp.OnAbortButtonPushed();
            end
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(comp, event)
             %Make sure values are small -> big, in the right order
            if(comp.MaxEditField.Value < comp.MidValueEditField.Value)
                comp.MaxEditField.Value = comp.MidValueEditField.Value;
            end
            value = comp.MaxEditField.Value;
            comp.MaxVal = value;

            comp.SweepDataChanged();
        end

        % Value changed function: MidValueEditField
        function MidValueEditFieldValueChanged(comp, event)
            %Make sure values are small -> big, in the right order
            if(comp.MidValueEditField.Value > comp.MaxEditField.Value)
                comp.MidValueEditField.Value = comp.MaxEditField.Value;
            end
            if(comp.MidValueEditField.Value < comp.MinEditField.Value)
                comp.MidValueEditField.Value = comp.MinEditField.Value;
            end
            
            value = comp.MidValueEditField.Value;
            comp.MidVal = value;        

            comp.SweepDataChanged();   
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(comp, event)
            %Make sure values are small -> big, in the right order
            if(comp.MinEditField.Value > comp.MidValueEditField.Value)
                comp.MinEditField.Value = comp.MidValueEditField.Value;
            end
            
            value = comp.MinEditField.Value;
            comp.MinVal = value;           

            comp.SweepDataChanged(); 
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(comp, event)
            comp.StepSize = comp.StepSizeEditField.Value;

            %We just changed the stepsize, so we are not setting sweep
            %params using Number of Steps, so blank that out so the
            %calculator code knows this
            comp.TargetNumSteps = [];

            %A safety net in case we set 0 as the stepsize..
            if comp.StepSize == 0
                comp.TargetNumSteps = 1;
            end
            
            comp.SweepDataChanged();                    
        end

        % Value changed function: NoofStepsEditField
        function NoofStepsEditFieldValueChanged(comp, event)
            comp.TargetNumSteps = comp.NoofStepsEditField.Value;

            comp.SweepDataChanged();     
        end

        % Value changed function: SettleTimesEditField
        function SettleTimesEditFieldValueChanged(comp, event)
            value = comp.SettleTimesEditField.Value;
            comp.SettleTime = value;
            comp.SweepDataChanged();     
        end

        % Value changed function: SavetonewfileCheckBox
        function SavetonewfileCheckBoxValueChanged(comp, event)
            value = comp.SavetonewfileCheckBox.Value;

            %Toggle the editing of the file name edit field as a visual
            %clue
            if value
                comp.FileNameEditField.Enable = "on";
            else
                comp.FileNameEditField.Enable = "off";
            end

            comp.SweepDataChanged();     
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(comp, event)
            comp.SweepDataChanged();                
        end

        % Button pushed function: InsertAutonamingtagButton
        function InsertAutonamingtagButtonPushed(comp, event)
            eventArgs = Palladium.Events.ValueChangedEventData(comp.FileNameEditField.Value);
            notify(comp, "InsertSmartTag", eventArgs); 
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
          
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 827];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.MaxEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MaxEditFieldLabel.Text = 'Max';
            comp.MaxEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MaxEditFieldValueChanged, true);
            comp.MaxEditField.Value = 1;
            comp.MidValueEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MidValueEditFieldLabel.Text = 'Mid Value';
            comp.MidValueEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MidValueEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MidValueEditFieldValueChanged, true);
            comp.MinEditFieldLabel = uilabel(comp.GridLayout2);
            comp.MinEditFieldLabel.Text = 'Min';
            comp.MinEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.MinEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MinEditFieldValueChanged, true);
            comp.MinEditField.Value = -1;
            comp.UnitsLabel1 = uilabel(comp.GridLayout2);
            comp.UnitsLabel1.Text = '';
            comp.UnitsLabel2 = uilabel(comp.GridLayout2);
            comp.UnitsLabel2.Text = '';
            comp.UnitsLabel3 = uilabel(comp.GridLayout2);
            comp.UnitsLabel3.Text = '';
            comp.GridLayout4 = uigridlayout(comp.GridLayout);
            comp.ListBox = uilistbox(comp.GridLayout4);
            comp.ListBox.Items = {};
            comp.ListBox.Enable = 'off';
            comp.ListBox.Value = {};
            comp.GridLayout5 = uigridlayout(comp.GridLayout4);
            comp.GridLayout7 = uigridlayout(comp.GridLayout5);
            comp.GridLayout9 = uigridlayout(comp.GridLayout7);
            comp.CurrentValueEditField = uieditfield(comp.GridLayout9, 'numeric');
            comp.CurrentValueEditField.Editable = 'off';
            comp.CurrentValueLabel = uilabel(comp.GridLayout9);
            comp.CurrentValueLabel.Text = 'Current Value:';
            comp.UnitsLabel4 = uilabel(comp.GridLayout9);
            comp.UnitsLabel4.Text = '';
            comp.RemainingTimeEditField = uieditfield(comp.GridLayout9, 'text');
            comp.RemainingTimeEditField.Editable = 'off';
            comp.RemainingTimeEditField.Value = '--:--:--';
            comp.GridLayout10 = uigridlayout(comp.GridLayout5);
            comp.Ratemin1Label = uilabel(comp.GridLayout10);
            comp.Ratemin1Label.Interpreter = 'latex';
            comp.Ratemin1Label.Text = 'Rate (min$^{-1} )';
            comp.Ratemin1EditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.Ratemin1EditField.Limits = [0 Inf];
            comp.Ratemin1EditField.Editable = 'off';
            comp.Ratemin1EditField.Enable = 'off';
            comp.Ratemin1EditField.Value = 10;
            comp.NoofStepsEditFieldLabel = uilabel(comp.GridLayout10);
            comp.NoofStepsEditFieldLabel.Interpreter = 'latex';
            comp.NoofStepsEditFieldLabel.Text = 'No. of Steps';
            comp.NoofStepsEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.NoofStepsEditField.Limits = [1 Inf];
            comp.NoofStepsEditField.RoundFractionalValues = 'on';
            comp.NoofStepsEditField.ValueDisplayFormat = '%d';
            comp.NoofStepsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NoofStepsEditFieldValueChanged, true);
            comp.NoofStepsEditField.Value = 1;
            comp.StepSizeEditFieldLabel = uilabel(comp.GridLayout10);
            comp.StepSizeEditFieldLabel.Interpreter = 'latex';
            comp.StepSizeEditFieldLabel.Text = 'Step Size';
            comp.StepSizeEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.StepSizeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @StepSizeEditFieldValueChanged, true);
            comp.StepSizeEditField.Value = 0.1;
            comp.UnitsLabel5 = uilabel(comp.GridLayout10);
            comp.UnitsLabel5.Text = '';
            comp.SettleTimesEditFieldLabel = uilabel(comp.GridLayout10);
            comp.SettleTimesEditFieldLabel.Interpreter = 'latex';
            comp.SettleTimesEditFieldLabel.Text = 'Settle Time (s)';
            comp.SettleTimesEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.SettleTimesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SettleTimesEditFieldValueChanged, true);
            comp.SettleTimesEditField.Value = 0.1;
            comp.SweepSetupLabel = uilabel(comp.GridLayout);
            comp.SweepSetupLabel.Text = 'Sweep Setup';
            comp.GridLayout_RunButtonCentring = uigridlayout(comp.GridLayout);
            comp.RunButton = uibutton(comp.GridLayout_RunButtonCentring, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Enable = 'off';
            comp.RunButton.Text = 'Run';
            comp.ContinuousCheckBox = uicheckbox(comp.GridLayout_RunButtonCentring);
            comp.ContinuousCheckBox.Tooltip = {'Set to true to cause a new Sweep to immediately Run once the previous one finishes.'};
            comp.ContinuousCheckBox.Text = 'Continuous';
            comp.InsertAutonamingtagButton = uibutton(comp.GridLayout_RunButtonCentring, 'push');
            comp.InsertAutonamingtagButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InsertAutonamingtagButtonPushed, true);
            comp.InsertAutonamingtagButton.Enable = 'off';
            comp.InsertAutonamingtagButton.Tooltip = {'Add smart text like [Sample Temperature(K),%6.2f] to the Sweep File name above, which will auto-update with the values corresponding to that data column.'};
            comp.InsertAutonamingtagButton.Text = 'Insert Auto-naming tag ';
            comp.SavetonewfileCheckBox = uicheckbox(comp.GridLayout);
            comp.SavetonewfileCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SavetonewfileCheckBoxValueChanged, true);
            comp.SavetonewfileCheckBox.Tooltip = {'Select this to cause a seperate named data file to be created for each time Run is hit, with the specified name'};
            comp.SavetonewfileCheckBox.Text = '  Save to new file';
            comp.FileNameEditField = uieditfield(comp.GridLayout, 'text');
            comp.FileNameEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FileNameEditFieldValueChanged, true);
            comp.FileNameEditField.Enable = 'off';
            comp.FileNameEditField.Tooltip = {'Filename of the saved data will be the current overall file name, with this text appended. Enclose names of parameters in [brackets] to auto-populate names with data from the measurements, eg.'; ''; '"Sweep at T = [Sample Temperature(K),%6.2f] K"'; ''; 'The second argument, and its comma delimiter, are optional, and tell the code how to format the numerical value as string. '; ''; 'Common options for the format string are:'; ''; '%.2f - round to 2d.p.'; ''; '%.3G - round to 3 s.f., no exponent notation'};
            comp.FileNameEditField.Value = ' - Sweep File';
            comp.SweepSectionsControl = Palladium.Instruments.Controls.SweepSectionsControl(comp.GridLayout);
            comp.DataColumnAddPanel = Palladium.Instruments.Controls.DataColumnAddPanel(comp.GridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end