classdef SequenceEditor_ComEntry_DatafileCommand < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                 matlab.ui.container.GridLayout
        FileNameLabel              matlab.ui.control.Label
        DataFolderLabel            matlab.ui.control.Label
        WriteFileCheckBox          matlab.ui.control.CheckBox
        FileNameTextArea           matlab.ui.control.TextArea
        DirectoryTextArea          matlab.ui.control.TextArea
        BrowseButton               matlab.ui.control.Button
        DataFilePathCommandsLabel  matlab.ui.control.Label
        InsertButton               matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        CommandInsert
    end

    
    methods (Access = public)
        
        function SetDefaultPaths(comp, defaultDir, defaultFileName)
            comp.DirectoryTextArea.Value = defaultDir;
            comp.FileNameTextArea.Value = defaultFileName;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertButton
        function InsertButtonPushed(comp, event)
            try
                details.Type = Palladium.Sequence.CommandEncoder.Enc_DataFile;
                details.Directory = comp.DirectoryTextArea.Value{1};
                details.FileName = comp.FileNameTextArea.Value{1};
                details.WriteToFile = comp.WriteFileCheckBox.Value;

                eventArgs = Palladium.Events.CommandEntryEventData(details);
                notify(comp, 'CommandInsert', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in InsertButtonPushed", err, Standalone = true, Figure = comp);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(comp, event)
            try
                result =  uigetdir(comp.DirectoryTextArea.Value{1}, "Select Directory");
                if result == 0
                    return;
                end

                comp.DirectoryTextArea.Value = string(result);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in BrowseButtonPushed", err, Standalone = true, Figure = comp);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 326 151];
            comp.GridLayout = uigridlayout(comp);
            comp.InsertButton = uibutton(comp.GridLayout, 'push');
            comp.InsertButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InsertButtonPushed, true);
            comp.InsertButton.Text = '⬅️ Insert';
            comp.DataFilePathCommandsLabel = uilabel(comp.GridLayout);
            comp.DataFilePathCommandsLabel.Text = 'Data File Path Commands';
            comp.BrowseButton = uibutton(comp.GridLayout, 'push');
            comp.BrowseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @BrowseButtonPushed, true);
            comp.BrowseButton.Text = 'Browse';
            comp.DirectoryTextArea = uitextarea(comp.GridLayout);
            comp.FileNameTextArea = uitextarea(comp.GridLayout);
            comp.WriteFileCheckBox = uicheckbox(comp.GridLayout);
            comp.WriteFileCheckBox.Text = 'Write File';
            comp.WriteFileCheckBox.Value = true;
            comp.DataFolderLabel = uilabel(comp.GridLayout);
            comp.DataFolderLabel.Enable = 'off';
            comp.DataFolderLabel.Text = 'Data Folder';
            comp.FileNameLabel = uilabel(comp.GridLayout);
            comp.FileNameLabel.Enable = 'off';
            comp.FileNameLabel.Text = 'File Name';
        end
    end
end