classdef SequenceEditor_ComEntry_InstrumentCommand < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        ControlLabel             matlab.ui.control.Label
        CommandEditField         matlab.ui.control.EditField
        CommandEditFieldLabel    matlab.ui.control.Label
        InsertButton             matlab.ui.control.Button
        ControlDropDown          matlab.ui.control.DropDown
        InstrumentDropDown       matlab.ui.control.DropDown
        InstrumentDropDownLabel  matlab.ui.control.Label
        InstrumentCommandLabel   matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        CommandInsert
    end

    properties (Access = public)
        Command_EditField
        Control_DropDown
        Control_Label
        Instrument_DropDown
    end
    

    methods (Access = public)

        function RefreshInstrumentNames(comp, names, data)
            comp.InstrumentDropDown.Items = names;
            comp.InstrumentDropDown.ItemsData = data;
        end
    end

    methods (Access = private)

        %% AddCommandEditFieldContextMenu
        function AddCommandEditFieldContextMenu(comp, instrRef, commandEditField, Settings)
            arguments
                comp;
                instrRef (1,1) Palladium.Core.Instrument;
                commandEditField (1,1);
                Settings.ControlName = [];
            end

            if isempty(Settings.ControlName)    %Use the Instrument itself
                methodNames = Palladium.Utilities.PluginLoading.GetInstrumentMethods(instrRef);
            else    %Get methods of the Control
                methodNames = Palladium.Utilities.PluginLoading.GetInstrumentControlMethods(instrRef, Settings.ControlName);
            end

            parentFig = ancestor(comp, 'matlab.ui.Figure');
            cm = uicontextmenu(parentFig);
            for i = 1 : length(methodNames)
                m = uimenu(cm, "Text", methodNames{i});
                m.MenuSelectedFcn = Palladium.Core.ErrorGuard.Wrap(@(s,e)PopulateBoxWithSig(methodNames{i}, commandEditField), Context = "Error filling command box", Standalone = true, Figure = comp);
            end

            commandEditField.ContextMenu = cm;

            function PopulateBoxWithSig(sigString, edtfield)
                edtfield.Value = sigString;
            end

        end

        %% GetSelectedInstrument
        function instr = GetSelectedInstrument(comp)
            if isempty(comp.InstrumentDropDown.Items)
                instr = [];
                Palladium.Utilities.GUIUtils.ShowWarning(comp, "Instrument selection is empty, please select an Instrument from the drop down", "No instrument selected");
                return;
            end

            idx = comp.InstrumentDropDown.ValueIndex;
            itemData = comp.InstrumentDropDown.ItemsData{idx};
            instr = itemData;
        end

        %% InstrumentControlSelectionChanged
        function InstrumentControlSelectionChanged(comp, controlDropDown, commandEditField)
            instrRef = comp.GetSelectedInstrument();

            controlName = controlDropDown.Value;

            if strcmp(controlName, "")
                %Add a context menu to the command edit field which contains
                %all the valid functions for this instrument
                comp.AddCommandEditFieldContextMenu(instrRef, commandEditField);
            else
                %Add a context menu to the command edit field which contains
                %all the valid functions for this instrument CONTROL
                comp.AddCommandEditFieldContextMenu(instrRef, commandEditField, ControlName=controlName);
            end
        end

        %% InstrumentSelectionChanged
        function InstrumentSelectionChanged(comp, controlDropDown, controlLabel, commandEditField)
            instrRef = comp.GetSelectedInstrument();
            controlNames = instrRef.GetRegisteredControlNames();

            if isempty(controlNames)
                controlDropDown.Items = {};
                controlDropDown.Enable = 'Off';
                controlLabel.Enable = 'Off';
            else
                controlDropDown.Items = ["", controlNames];
                controlDropDown.Enable = 'On';
                controlLabel.Enable = 'On';
            end

            %Add a context menu to the command edit field which contains
            %all the valid functions for this instrument
            comp.AddCommandEditFieldContextMenu(instrRef, commandEditField);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            %Map the UI elements into public properties so the parent GUI
            %can call methods on them
            comp.Command_EditField = comp.CommandEditField;
            comp.Control_DropDown = comp.ControlDropDown;
            comp.Instrument_DropDown = comp.InstrumentDropDown;
            comp.Control_Label = comp.ControlLabel;
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(comp, event)
            try
                if isempty(comp.InstrumentDropDown.Value)
                    return;
                end

                details.Type = Palladium.Sequence.CommandEncoder.Enc_Instr;
                details.Instrument = comp.GetSelectedInstrument();
                details.InstrumentName = comp.InstrumentDropDown.Value;
                details.ControlName = comp.ControlDropDown.Value;
                details.CommandString = comp.CommandEditField.Value;

                eventArgs = Palladium.Events.CommandEntryEventData(details);
                notify(comp, 'CommandInsert', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in InsertButtonPushed", err, Standalone = true, Figure = comp);
            end
        end

        % Value changed function: InstrumentDropDown
        function InstrumentDropDownValueChanged(comp, event)
            try
                %value = comp.InstrumentDropDown.Value;
                comp.InstrumentSelectionChanged(comp.ControlDropDown, comp.ControlLabel, comp.CommandEditField)
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in InstrumentDropDownValueChanged", err, Standalone = true, Figure = comp);
            end
        end

        % Value changed function: ControlDropDown
        function ControlDropDownValueChanged(comp, event)
            try
                %value = comp.ControlDropDown.Value;
                comp.InstrumentControlSelectionChanged(comp.ControlDropDown, comp.CommandEditField);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in ControlDropDownValueChanged", err, Standalone = true, Figure = comp);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 396 160];
            comp.GridLayout = uigridlayout(comp);
            comp.InstrumentCommandLabel = uilabel(comp.GridLayout);
            comp.InstrumentCommandLabel.Text = 'Instrument Command';
            comp.InstrumentDropDownLabel = uilabel(comp.GridLayout);
            comp.InstrumentDropDownLabel.Text = 'Instrument';
            comp.InstrumentDropDown = uidropdown(comp.GridLayout);
            comp.InstrumentDropDown.Items = {''};
            comp.InstrumentDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @InstrumentDropDownValueChanged, true);
            comp.InstrumentDropDown.Value = '';
            comp.ControlDropDown = uidropdown(comp.GridLayout);
            comp.ControlDropDown.Items = {''};
            comp.ControlDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ControlDropDownValueChanged, true);
            comp.ControlDropDown.Value = '';
            comp.InsertButton = uibutton(comp.GridLayout, 'push');
            comp.InsertButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InsertButtonPushed, true);
            comp.InsertButton.Text = '⬅️ Insert';
            comp.CommandEditFieldLabel = uilabel(comp.GridLayout);
            comp.CommandEditFieldLabel.Text = 'Command';
            comp.CommandEditField = uieditfield(comp.GridLayout, 'text');
            comp.CommandEditField.Value = 'PrintIdentifier()';
            comp.ControlLabel = uilabel(comp.GridLayout);
            comp.ControlLabel.Enable = 'off';
            comp.ControlLabel.Text = 'Control';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end