classdef SequenceEditor_ComEntry_RunSequenceCommand < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        FilePathTextArea               matlab.ui.control.TextArea
        BrowseButton                   matlab.ui.control.Button
        RunNestedSequenceCommandLabel  matlab.ui.control.Label
        InsertButton                   matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        CommandInsert
    end

    properties (Access = public)
        SequenceFileExtension string = ".seq";
    end
    
    
    methods (Access = public)
        
         function SetDefaultPaths(comp, defaultDir, seqFileExtension)
            comp.FilePathTextArea.Value = defaultDir;
            comp.SequenceFileExtension = seqFileExtension;
         end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertButton
        function InsertButtonPushed(comp, event)
            try
                details.Type = Palladium.Sequence.CommandEncoder.Enc_RunSequence;
                details.SequenceFilePath = comp.FilePathTextArea.Value;

                eventArgs = Palladium.Events.CommandEntryEventData(details);
                notify(comp, 'CommandInsert', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in InsertButtonPushed", err, Standalone = true, Figure = comp);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(comp, event)
            try
                filter = {"*" + comp.SequenceFileExtension, "Palladium Sequence Files"; "*.*", "All Files"};
                [file,location] =  uigetfile(filter, "Select Sequence File", comp.FilePathTextArea.Value{1}, "MultiSelect", "off");
                if file == 0
                    return;
                end

                comp.FilePathTextArea.Value = string(fullfile(location, file));
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in BrowseButtonPushed", err, Standalone = true, Figure = comp);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 385 125];
            comp.GridLayout = uigridlayout(comp);
            comp.InsertButton = uibutton(comp.GridLayout, 'push');
            comp.InsertButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InsertButtonPushed, true);
            comp.InsertButton.Text = '⬅️ Insert';
            comp.RunNestedSequenceCommandLabel = uilabel(comp.GridLayout);
            comp.RunNestedSequenceCommandLabel.Text = 'Run Nested Sequence Command';
            comp.BrowseButton = uibutton(comp.GridLayout, 'push');
            comp.BrowseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @BrowseButtonPushed, true);
            comp.BrowseButton.Text = 'Browse';
            comp.FilePathTextArea = uitextarea(comp.GridLayout);
        end
    end
end