classdef SequenceEditor_ComEntry_WaitCommand < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        InsertButton        matlab.ui.control.Button
        WaitValueEditField  matlab.ui.control.NumericEditField
        WaitUnitsDropDown   matlab.ui.control.DropDown
        WaitCommandLabel    matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        CommandInsert
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertButton
        function InsertButtonPushed(comp, event)
            try
                details.Type = Palladium.Sequence.CommandEncoder.Enc_Wait;
                details.WaitValue = comp.WaitValueEditField.Value;
                details.WaitUnits = comp.WaitUnitsDropDown.Value;

                eventArgs = Palladium.Events.CommandEntryEventData(details);
                notify(comp, 'CommandInsert', eventArgs);
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in InsertButtonPushed", err, Standalone = true, Figure = comp);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 269 62];
            comp.GridLayout = uigridlayout(comp);
            comp.WaitCommandLabel = uilabel(comp.GridLayout);
            comp.WaitCommandLabel.Text = 'Wait Command';
            comp.WaitUnitsDropDown = uidropdown(comp.GridLayout);
            comp.WaitUnitsDropDown.Items = {'sec', 'min', 'hr'};
            comp.WaitUnitsDropDown.Value = 'sec';
            comp.WaitValueEditField = uieditfield(comp.GridLayout, 'numeric');
            comp.WaitValueEditField.Limits = [0 Inf];
            comp.WaitValueEditField.Value = 0.5;
            comp.InsertButton = uibutton(comp.GridLayout, 'push');
            comp.InsertButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InsertButtonPushed, true);
            comp.InsertButton.Text = '⬅️ Insert';
        end
    end
end