classdef SequenceEditor_DefaultGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PalladiumSequenceEditorUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        Panel_2                    matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        SequenceEditor_ComEntry_DatafileCommand  Palladium.Sequence.Views.SequenceEditor_ComEntry_DatafileCommand
        SequenceEditor_ComEntry_RunSequenceCommand  Palladium.Sequence.Views.SequenceEditor_ComEntry_RunSequenceCommand
        SequenceEditor_ComEntry_InstrumentCommand  Palladium.Sequence.Views.SequenceEditor_ComEntry_InstrumentCommand
        SequenceEditor_ComEntry_WaitCommand  Palladium.Sequence.Views.SequenceEditor_ComEntry_WaitCommand
        GridLayout8                matlab.ui.container.GridLayout
        AbortSequenceButton        matlab.ui.control.Button
        RunSequenceButton          matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        ViewEditSequenceLabel      matlab.ui.control.Label
        SequenceEdit_TextArea      matlab.ui.control.TextArea
        GridLayout4                matlab.ui.container.GridLayout
        ControlLabel_4             matlab.ui.control.Label
        ControlLabel_3             matlab.ui.control.Label
        ControlLabel_2             matlab.ui.control.Label
        ControlLabel_1             matlab.ui.control.Label
        ControlDropDown_4          matlab.ui.control.DropDown
        ControlDropDown_3          matlab.ui.control.DropDown
        ControlDropDown_2          matlab.ui.control.DropDown
        ControlDropDown_1          matlab.ui.control.DropDown
        CommandEditField_4         matlab.ui.control.EditField
        CommandEditField_4Label    matlab.ui.control.Label
        CommandEditField_3         matlab.ui.control.EditField
        CommandEditField_3Label    matlab.ui.control.Label
        CommandEditField_2         matlab.ui.control.EditField
        CommandEditField_2Label    matlab.ui.control.Label
        CommandEditField_1         matlab.ui.control.EditField
        InstrumentDropDown_4       matlab.ui.control.DropDown
        InstrumentDropDown_4Label  matlab.ui.control.Label
        InstrumentDropDown_3       matlab.ui.control.DropDown
        InstrumentDropDown_3Label  matlab.ui.control.Label
        InstrumentDropDown_2       matlab.ui.control.DropDown
        InstrumentDropDown_2Label  matlab.ui.control.Label
        InstrumentDropDown_1       matlab.ui.control.DropDown
        QueueButton_4              matlab.ui.control.Button
        QueueButton_3              matlab.ui.control.Button
        QueueButton_2              matlab.ui.control.Button
        QueueButton_1              matlab.ui.control.Button
        CommandEditFieldLabel      matlab.ui.control.Label
        RunCommandsLabel           matlab.ui.control.Label
        InstrumentDropDownLabel    matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        Tree                       matlab.ui.container.Tree
        GridLayout3                matlab.ui.container.GridLayout
        RefreshButton              matlab.ui.control.Button
        DirectoryLabel             matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        BrowseButton               matlab.ui.control.Button
    end


    properties (Access = private)
        SequenceText = {'% Write Sequence here'};
    end

    properties (Access = public)
        DefaultDir;
        FileExtension;
        SelectedDir;
        SelectedFileName;
    end

    events
        CommandInsert;
        DirectorySelect;
        FileSelect;
        SaveButtonPressed;
        SequenceAbort;
        SequenceRun;
        SingleCommandQueued;
    end


    methods (Access = public)

        %% Close
        function Close(app)
            delete(app);
        end

        %% DirectorySelected
        function DirectorySelected(app, dir)
            %Cache value
            eventArgs = Palladium.Events.ValueChangedEventData(dir);
            app.SelectedDir = dir;

            %Fire event
            notify(app, "DirectorySelect", eventArgs);
        end

        %% FileSelected
        function FileSelected(app, fileName)
            %Cache value
            eventArgs = Palladium.Events.ValueChangedEventData(fileName);
            app.SelectedFileName = fileName;

            %Fire event
            notify(app, "FileSelect", eventArgs);
        end

        %% GetUIFigureHandle
        function figHandle = GetUIFigureHandle(app)
            figHandle = app.PalladiumSequenceEditorUIFigure;
        end

        %% InsertSequenceLine
        function InsertSequenceLine(app, str)

            if isempty(app.SequenceText)
                app.SequenceText = {char(str)};   %Make sure this is a cell array of strings, {'First line text', 'second'} - but must just be a string when one line, not a 1x1 cell..
            else
                app.SequenceText(end+1, 1) = {char(str)};
            end

            app.SequenceEdit_TextArea.Value = app.SequenceText;
        end

        %% OnDirectorySelected
        function OnDirectorySelected(app)
            app.TextArea.Value = app.SelectedDir;
            app.UpdateTree();
        end

        %% OnFileSelected
        function OnFileSelected(app) %#ok<MANU>

        end

        %% OnSequenceAbort
        function OnSequenceAbort(app)
            app.AbortSequenceButton.Enable = "off";
            app.RunSequenceButton.Enable = "on";
        end

        %% RefreshDir
        function RefreshDir(app)
            %Trigger event to say this directory was selected - external
            %code will then update the GUI
            app.DirectorySelected(app.SelectedDir);
        end

        %% RefreshInstrumentNames
        function RefreshInstrumentNames(app, instrumentsList)
            %Handle case of no instruments - e.g. if we launch the SE before adding any
            if isempty(instrumentsList)
                app.InstrumentDropDown_1.Items = {};
                app.InstrumentDropDown_1.ItemsData = [];
                app.InstrumentDropDown_2.Items = {};
                app.InstrumentDropDown_2.ItemsData = [];
                app.InstrumentDropDown_3.Items = {};
                app.InstrumentDropDown_3.ItemsData = [];
                app.InstrumentDropDown_4.Items = {};
                app.InstrumentDropDown_4.ItemsData = [];
                return;
            end

            names = strings(length(instrumentsList), 1);
            data = cell(length(instrumentsList), 1);

            for i = 1 : length(instrumentsList)
                names(i) = instrumentsList{i}.Name;
                data{i} = instrumentsList{i};
            end

            app.SequenceEditor_ComEntry_InstrumentCommand.RefreshInstrumentNames(names, data);

            app.InstrumentDropDown_1.Items = names;
            app.InstrumentDropDown_1.ItemsData = data;
            app.InstrumentDropDown_2.Items = names;
            app.InstrumentDropDown_2.ItemsData = data;
            app.InstrumentDropDown_3.Items = names;
            app.InstrumentDropDown_3.ItemsData = data;
            app.InstrumentDropDown_4.Items = names;
            app.InstrumentDropDown_4.ItemsData = data;

            app.InstrumentSelectionChanged(app.InstrumentDropDown_1, app.ControlDropDown_1, app.ControlLabel_1, app.CommandEditField_1);
            app.InstrumentSelectionChanged(app.InstrumentDropDown_2, app.ControlDropDown_2, app.ControlLabel_2, app.CommandEditField_2);
            app.InstrumentSelectionChanged(app.InstrumentDropDown_3, app.ControlDropDown_3, app.ControlLabel_3, app.CommandEditField_3);
            app.InstrumentSelectionChanged(app.InstrumentDropDown_4, app.ControlDropDown_4, app.ControlLabel_4, app.CommandEditField_4);


            app.InstrumentSelectionChanged(app.SequenceEditor_ComEntry_InstrumentCommand.Instrument_DropDown, app.SequenceEditor_ComEntry_InstrumentCommand.Control_DropDown, app.SequenceEditor_ComEntry_InstrumentCommand.Control_Label, app.SequenceEditor_ComEntry_InstrumentCommand.Command_EditField);
        end


        %% ResetQueueButton
        function ResetQueueButton(~, queueButton)
            queueButton.Enable = "on";
            queueButton.Text = "Queue";
            queueButton.BackgroundColor = [0.83,0.99,0.81];
        end

        %% RunSequence
        function RunSequence(app)
            %Cache value
            eventArgs = Palladium.Events.SequenceEventData(app.SequenceText);

            %Fire event
            notify(app, "SequenceRun", eventArgs);
        end

        %% SaveSequence
        function SaveSequence(app)
            %Cache value
            eventArgs = Palladium.Events.SequenceEventData(app.SequenceText);

            %Fire event
            notify(app, "SaveButtonPressed", eventArgs);
        end

        %% SeizeFocus
        function SeizeFocus(app)
            % Calling uigetfile or similar modal dialogs may send the app to the back.
            % Bring it back to the front, preserving maximised, minimised etc.
            windowState = app.PalladiumSequenceEditorUIFigure.WindowState;
            app.PalladiumSequenceEditorUIFigure.Visible = 'off';
            app.PalladiumSequenceEditorUIFigure.Visible = 'on';
            app.PalladiumSequenceEditorUIFigure.WindowState = windowState;
        end

        %% SetIcon
        function SetIcon(app, iconPath)
            app.PalladiumSequenceEditorUIFigure.Icon = iconPath;
        end

        %% SetSequence
        function SetSequence(app, seqLines)
            app.SequenceEdit_TextArea.Value = seqLines;
            app.SequenceText = seqLines;
        end

        %% SequenceComplete
        function SequenceComplete(app)
            app.AbortSequenceButton.Enable = "off";
            app.RunSequenceButton.Enable = "on";
        end

        %% SetDefaultPaths
        function SetDefaultPaths(app, defaultDir, seqFileExtension, defaultDataDir, defaultDataFileName)

            app.DefaultDir = defaultDir;
            app.FileExtension = seqFileExtension;
            app.DirectorySelected(defaultDir);

            app.SequenceEditor_ComEntry_DatafileCommand.SetDefaultPaths(defaultDataDir, defaultDataFileName);
            app.SequenceEditor_ComEntry_RunSequenceCommand.SetDefaultPaths(defaultDir, seqFileExtension);
        end

    end

    methods (Access = private)


        %% AddCommandEditFieldContextMenu
        function AddCommandEditFieldContextMenu(app, instrRef, commandEditField, Settings)
            arguments
                app;
                instrRef (1,1) Palladium.Core.Instrument;
                commandEditField (1,1);
                Settings.ControlName = [];
            end

            if isempty(Settings.ControlName)    %Use the Instrument itself
                methodNames = Palladium.Utilities.PluginLoading.GetInstrumentMethods(instrRef);
            else    %Get methods of the Control
                methodNames = Palladium.Utilities.PluginLoading.GetInstrumentControlMethods(instrRef, Settings.ControlName);
            end


            cm = uicontextmenu(app.PalladiumSequenceEditorUIFigure);
            for i = 1 : length(methodNames)
                m = uimenu(cm, "Text", methodNames{i});
                m.MenuSelectedFcn = Palladium.Core.ErrorGuard.Wrap(@(s,e)PopulateBoxWithSig(methodNames{i}, commandEditField), Context = "Error filling command box", Standalone = true, Figure = app.PalladiumSequenceEditorUIFigure);
            end

            commandEditField.ContextMenu = cm;

            function PopulateBoxWithSig(sigString, edtfield)
                edtfield.Value = sigString;
            end

        end

        %% AddNodesForFolderContents
        function folderNodesToPopulate = AddNodesForFolderContents(app, rootNode, folderPath)
            folderNodesToPopulate = [];

            %Get contents of directory
            dirContents = dir(string(folderPath));

            %Add folders first
            for i = 1 : length(dirContents)
                item = dirContents(i);
                if item.isdir
                    if ~contains(item.name, '.')    %Exclude the '.' and '..' results one always gets
                        nodeStr = "📂 " + item.name;
                        foldrNode = uitreenode(rootNode, "Text", nodeStr, "UserData", item);
                        folderNodesToPopulate = [folderNodesToPopulate foldrNode]; %#ok<AGROW>
                    end
                end
            end

            %Then add files
            namesOfNodesToAdd = {};
            items = {};
            for i = 1 : length(dirContents)
                item = dirContents(i);
                if ~item.isdir
                    [~, ~, ext] = fileparts(item.name);
                    if ext == app.FileExtension
                        nodeStr = item.name;
                        items = [items, item]; %#ok<AGROW>
                        namesOfNodesToAdd = [namesOfNodesToAdd, nodeStr]; %#ok<AGROW>
                    end
                end
            end

            %Put this extra step in in case we want to sort or sift the
            %nodes before adding..
            for i = 1 : length(namesOfNodesToAdd)
                nodeStr = namesOfNodesToAdd{i};
                itemUserData = items{i};
                uitreenode(rootNode, "Text", nodeStr, "UserData", itemUserData);
            end

        end

        %% ClearTree
        function ClearTree(app)
            nodes = app.Tree.Children;
            nodes.delete;
        end

        %% CommandInserted
        function CommandInserted(app, details)
            %Gets called by the CommandInsert event of the Components on
            %the right which allow defining and building a command to
            %insert script line for. Passes a flexible struct of info
            %('details') for later actual encoding

            %Just re-throw event for the Controller to pick up, avoid
            %having logic here in the View
            eventArgs = Palladium.Events.CommandEntryEventData(details);
            notify(app, 'CommandInsert', eventArgs);
        end

        %% GetCommandString
        function str = GetCommandString(~, commandEditField)
            str = commandEditField.Value;
        end

        %% GetSelectedInstrument
        function instr = GetSelectedInstrument(~, instrumentDropDown)
            if isempty(instrumentDropDown)
                instr = [];
                return;
            end

            if isempty(instrumentDropDown.Items)
                instr = [];
                Palladium.Utilities.GUIUtils.ShowWarning(instrumentDropDown, "Instrument selection is empty, please select an Instrument from the drop down", "No instrument selected");
                return;
            end

            idx = instrumentDropDown.ValueIndex;
            itemData = instrumentDropDown.ItemsData{idx};
            instr = itemData;
        end


        %% InstrumentControlSelectionChanged
        function InstrumentControlSelectionChanged(app, instrumentDropDown, controlDropDown, ~, commandEditField)
            instrRef = app.GetSelectedInstrument(instrumentDropDown);


            controlName = controlDropDown.Value;

            if strcmp(controlName, "")
                %Add a context menu to the command edit field which contains
                %all the valid functions for this instrument
                app.AddCommandEditFieldContextMenu(instrRef, commandEditField);
            else
                %Add a context menu to the command edit field which contains
                %all the valid functions for this instrument CONTROL
                app.AddCommandEditFieldContextMenu(instrRef, commandEditField, ControlName=controlName);
            end
        end

        %% InstrumentSelectionChanged
        function InstrumentSelectionChanged(app, instrumentDropDown, controlDropDown, controlLabel, commandEditField)
            instrRef = app.GetSelectedInstrument(instrumentDropDown);

            if isempty(instrRef)
                return;
            end

            controlNames = instrRef.GetRegisteredControlNames();

            if isempty(controlNames)
                controlDropDown.Items = {};
                controlDropDown.Enable = 'Off';
                controlLabel.Enable = 'Off';
            else
                controlDropDown.Items = ["", controlNames];
                controlDropDown.Enable = 'On';
                controlLabel.Enable = 'On';
            end

            %Add a context menu to the command edit field which contains
            %all the valid functions for this instrument
            app.AddCommandEditFieldContextMenu(instrRef, commandEditField);
        end

        %% NodeSelectionChange
        function NodeSelectionChange(app, ~, event)
            % Function for the SelectionChangedFcn callback
            nodes = event.SelectedNodes;
            node = nodes(1);    %Multiselect is off so in fact only one node can ever be selected here
            app.OnSingleNodeSelected(node);
        end

        %% OnQueueButtonPushed
        function OnQueueButtonPushed(app, queueButton, instrumentDropDown, controlDropDown, commandEditField)

            instrRef = app.GetSelectedInstrument(instrumentDropDown);

            if isempty(instrRef); return; end

            controlName = controlDropDown.Value;

            commandStr = app.GetCommandString(commandEditField);

            completeFunc = @()app.ResetQueueButton(queueButton);

            eventArgs = Palladium.Events.CommandEventData(instrRef, commandStr, controlName, completeFunc);
            notify(app, "SingleCommandQueued", eventArgs);

            queueButton.Enable = "off";
            queueButton.BackgroundColor = [1.00,0.93,0.75];
            queueButton.Text = "Executing..";
            drawnow();
        end

        %% OnSingleNodeSelected
        function OnSingleNodeSelected(app, node)
            filePath = fullfile(node.UserData.folder, node.UserData.name);
            app.FileSelected(filePath);
        end

        %% UpdateTree
        function UpdateTree(app)
            app.ClearTree();

            [~, dirName, ~] = fileparts(app.SelectedDir);

            %Add single parent node showing the directory we are in
            nodeStr = "📂 " + dirName;
            rootNode = uitreenode(app.Tree, "Text", nodeStr, "UserData", []);

            %Add folders and files in the starting root directory
            folderNodesToPopulate = app.AddNodesForFolderContents(rootNode, app.SelectedDir);

            %If there were folders in there, look inside them, and keep
            %looking in subfolders until there are no more folders left in
            %the heirachy
            while ~isempty(folderNodesToPopulate)
                folderPathsToCheck = cell(length(folderNodesToPopulate), 1);
                folderNodes = cell(length(folderNodesToPopulate), 1);
                for i = 1 : length(folderNodesToPopulate)
                    path = fullfile(folderNodesToPopulate(i).UserData.folder, folderNodesToPopulate(i).UserData.name);
                    folderPathsToCheck{i} = path;
                    folderNodes{i} = folderNodesToPopulate(i);
                end

                folderNodesToPopulate = [];

                for i = 1 : length(folderNodes)
                    %Add folders and files in this directory being checked
                    folderNodesToPopulateInThisFolder = app.AddNodesForFolderContents(folderNodes{i}, folderPathsToCheck(i));
                    folderNodesToPopulate = [folderNodesToPopulate, folderNodesToPopulateInThisFolder]; %#ok<AGROW>
                end
            end

            % Expand the tree
            expand(app.Tree);

            %Set callback for selection change events
            app.Tree.SelectionChangedFcn = Palladium.Core.ErrorGuard.Wrap(@app.NodeSelectionChange, Context = "Error in NodeSelectionChange", Standalone = true, Figure = app.PalladiumSequenceEditorUIFigure);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Subscribe to events of components
            Palladium.Core.ErrorGuard.AddListener(app.SequenceEditor_ComEntry_WaitCommand, "CommandInsert", @(src,evnt)app.CommandInserted(evnt.Details), Standalone = true, Figure = app.PalladiumSequenceEditorUIFigure);
            Palladium.Core.ErrorGuard.AddListener(app.SequenceEditor_ComEntry_InstrumentCommand, "CommandInsert", @(src,evnt)app.CommandInserted(evnt.Details), Standalone = true, Figure = app.PalladiumSequenceEditorUIFigure);
            Palladium.Core.ErrorGuard.AddListener(app.SequenceEditor_ComEntry_DatafileCommand, "CommandInsert", @(src,evnt)app.CommandInserted(evnt.Details), Standalone = true, Figure = app.PalladiumSequenceEditorUIFigure);
            Palladium.Core.ErrorGuard.AddListener(app.SequenceEditor_ComEntry_RunSequenceCommand, "CommandInsert", @(src,evnt)app.CommandInserted(evnt.Details), Standalone = true, Figure = app.PalladiumSequenceEditorUIFigure);

            %Initialise text area
            app.SequenceEdit_TextArea.Value = app.SequenceText;
            drawnow;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            try
                %Show dialogue to select new directory
                [dir] = uigetdir(app.DefaultDir, "📂 Select Directory");

                %Bring this GUI back to the front - Matlab has an annoying
                %habit of hiding it behind other windows when closing a modal
                %dialogue box
                app.SeizeFocus();

                %Handle case of cancelled dialogue box
                if dir == 0
                    return;
                end

                %Set the selected directory to the chosen one
                app.SelectedDir = dir;

                %Trigger event to say this directory was selected - external
                %code will then update the GUI
                app.DirectorySelected(dir);
            catch err
                Palladium.Core.ErrorGuard.HandleError("BrowseButtonPushed", err, Standalone = true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            try
                app.RefreshDir();
            catch err
                Palladium.Core.ErrorGuard.HandleError("RefreshButtonPushed", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Button pushed function: QueueButton_1
        function QueueButton_1_Pushed(app, event)
            try
                app.OnQueueButtonPushed(app.QueueButton_1, app.InstrumentDropDown_1, app.ControlDropDown_1, app.CommandEditField_1);
            catch err
                Palladium.Core.ErrorGuard.HandleError("QueueButton_1_Pushed", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Button pushed function: QueueButton_2
        function QueueButton_2Pushed(app, event)
            try
                app.OnQueueButtonPushed(app.QueueButton_2, app.InstrumentDropDown_2, app.ControlDropDown_2, app.CommandEditField_2);
            catch err
                Palladium.Core.ErrorGuard.HandleError("QueueButton_2Pushed", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Button pushed function: QueueButton_3
        function QueueButton_3Pushed(app, event)
            try
                app.OnQueueButtonPushed(app.QueueButton_3, app.InstrumentDropDown_3, app.ControlDropDown_3, app.CommandEditField_3);
            catch err
                Palladium.Core.ErrorGuard.HandleError("QueueButton_3Pushed", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Button pushed function: QueueButton_4
        function QueueButton_4Pushed(app, event)
            try
                app.OnQueueButtonPushed(app.QueueButton_4, app.InstrumentDropDown_4, app.ControlDropDown_4, app.CommandEditField_4);
            catch err
                Palladium.Core.ErrorGuard.HandleError("QueueButton_4Pushed", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: InstrumentDropDown_1
        function InstrumentDropDown_1ValueChanged(app, event)
            try
                app.InstrumentSelectionChanged(app.InstrumentDropDown_1, app.ControlDropDown_1, app.ControlLabel_1, app.CommandEditField_1);
            catch err
                Palladium.Core.ErrorGuard.HandleError("InstrumentDropDown_1ValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: InstrumentDropDown_2
        function InstrumentDropDown_2ValueChanged(app, event)
            try
                app.InstrumentSelectionChanged(app.InstrumentDropDown_2, app.ControlDropDown_2, app.ControlLabel_2, app.CommandEditField_2);
            catch err
                Palladium.Core.ErrorGuard.HandleError("InstrumentDropDown_2ValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: InstrumentDropDown_3
        function InstrumentDropDown_3ValueChanged(app, event)
            try
                app.InstrumentSelectionChanged(app.InstrumentDropDown_3, app.ControlDropDown_3, app.ControlLabel_3, app.CommandEditField_3);
            catch err
                Palladium.Core.ErrorGuard.HandleError("InstrumentDropDown_3ValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: InstrumentDropDown_4
        function InstrumentDropDown_4ValueChanged(app, event)
            try
                app.InstrumentSelectionChanged(app.InstrumentDropDown_4, app.ControlDropDown_4, app.ControlLabel_4, app.CommandEditField_4);
            catch err
                Palladium.Core.ErrorGuard.HandleError("InstrumentDropDown_4ValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: ControlDropDown_1
        function ControlDropDown_1ValueChanged(app, event)
            try
                app.InstrumentControlSelectionChanged(app.InstrumentDropDown_1, app.ControlDropDown_1, app.ControlLabel_1, app.CommandEditField_1);
            catch err
                Palladium.Core.ErrorGuard.HandleError("ControlDropDown_1ValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: ControlDropDown_2
        function ControlDropDown_2ValueChanged(app, event)
            try
                app.InstrumentControlSelectionChanged(app.InstrumentDropDown_2, app.ControlDropDown_2, app.ControlLabel_2, app.CommandEditField_2);
            catch err
                Palladium.Core.ErrorGuard.HandleError("ControlDropDown_2ValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: ControlDropDown_3
        function ControlDropDown_3ValueChanged(app, event)
            try
                app.InstrumentControlSelectionChanged(app.InstrumentDropDown_3, app.ControlDropDown_3, app.ControlLabel_3, app.CommandEditField_3);
            catch err
                Palladium.Core.ErrorGuard.HandleError("ControlDropDown_3ValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: ControlDropDown_4
        function ControlDropDown_4ValueChanged(app, event)
            try
                app.InstrumentControlSelectionChanged(app.InstrumentDropDown_4, app.ControlDropDown_4, app.ControlLabel_4, app.CommandEditField_4);
            catch err
                Palladium.Core.ErrorGuard.HandleError("ControlDropDown_4ValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Value changed function: SequenceEdit_TextArea
        function SequenceEdit_TextAreaValueChanged(app, event)
            try
                value = app.SequenceEdit_TextArea.Value;

                app.SequenceText = value;
            catch err
                Palladium.Core.ErrorGuard.HandleError("SequenceEdit_TextAreaValueChanged", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Button pushed function: RunSequenceButton
        function RunSequenceButtonPushed(app, event)
            try
                app.AbortSequenceButton.Enable = "on";
                app.RunSequenceButton.Enable = "off";
                app.RunSequence();
            catch err
                Palladium.Core.ErrorGuard.HandleError("RunSequenceButtonPushed", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Button pushed function: AbortSequenceButton
        function AbortSequenceButtonPushed(app, event)
            try
                notify(app, "SequenceAbort");
            catch err
                Palladium.Core.ErrorGuard.HandleError("AbortSequenceButtonPushed", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                app.SaveSequence();
            catch err
                Palladium.Core.ErrorGuard.HandleError("SaveButtonPushed", err, Standalone=true, Figure = app.PalladiumSequenceEditorUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PalladiumSequenceEditorUIFigure = uifigure('Visible', 'off');
            app.PalladiumSequenceEditorUIFigure.Name = 'Palladium Sequence Editor';
            app.PalladiumSequenceEditorUIFigure.Icon = 'PalladiumDAQIcon.png';
            app.GridLayout = uigridlayout(app.PalladiumSequenceEditorUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BrowseButton = uibutton(app.GridLayout3, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = '📂 Browse';
            app.TextArea = uitextarea(app.GridLayout3);
            app.DirectoryLabel = uilabel(app.GridLayout3);
            app.DirectoryLabel.Text = 'Directory';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = '🔁 Refresh';
            app.Tree = uitree(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.InstrumentDropDownLabel = uilabel(app.GridLayout4);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.RunCommandsLabel = uilabel(app.GridLayout4);
            app.RunCommandsLabel.Text = 'Run Commands';
            app.CommandEditFieldLabel = uilabel(app.GridLayout4);
            app.CommandEditFieldLabel.Text = 'Command';
            app.QueueButton_1 = uibutton(app.GridLayout4, 'push');
            app.QueueButton_1.ButtonPushedFcn = createCallbackFcn(app, @QueueButton_1_Pushed, true);
            app.QueueButton_1.Tooltip = {'Add this command to the queue of commands to be executed at the start of subsequent measurement ticks.'};
            app.QueueButton_1.Text = 'Queue';
            app.QueueButton_2 = uibutton(app.GridLayout4, 'push');
            app.QueueButton_2.ButtonPushedFcn = createCallbackFcn(app, @QueueButton_2Pushed, true);
            app.QueueButton_2.Tooltip = {'Add this command to the queue of commands to be executed at the start of subsequent measurement ticks.'};
            app.QueueButton_2.Text = 'Queue';
            app.QueueButton_3 = uibutton(app.GridLayout4, 'push');
            app.QueueButton_3.ButtonPushedFcn = createCallbackFcn(app, @QueueButton_3Pushed, true);
            app.QueueButton_3.Tooltip = {'Add this command to the queue of commands to be executed at the start of subsequent measurement ticks.'};
            app.QueueButton_3.Text = 'Queue';
            app.QueueButton_4 = uibutton(app.GridLayout4, 'push');
            app.QueueButton_4.ButtonPushedFcn = createCallbackFcn(app, @QueueButton_4Pushed, true);
            app.QueueButton_4.Tooltip = {'Add this command to the queue of commands to be executed at the start of subsequent measurement ticks.'};
            app.QueueButton_4.Text = 'Queue';
            app.InstrumentDropDown_1 = uidropdown(app.GridLayout4);
            app.InstrumentDropDown_1.Items = {};
            app.InstrumentDropDown_1.ValueChangedFcn = createCallbackFcn(app, @InstrumentDropDown_1ValueChanged, true);
            app.InstrumentDropDown_1.Value = {};
            app.InstrumentDropDown_2Label = uilabel(app.GridLayout4);
            app.InstrumentDropDown_2Label.Text = 'Instrument';
            app.InstrumentDropDown_2 = uidropdown(app.GridLayout4);
            app.InstrumentDropDown_2.Items = {};
            app.InstrumentDropDown_2.ValueChangedFcn = createCallbackFcn(app, @InstrumentDropDown_2ValueChanged, true);
            app.InstrumentDropDown_2.Value = {};
            app.InstrumentDropDown_3Label = uilabel(app.GridLayout4);
            app.InstrumentDropDown_3Label.Text = 'Instrument';
            app.InstrumentDropDown_3 = uidropdown(app.GridLayout4);
            app.InstrumentDropDown_3.Items = {};
            app.InstrumentDropDown_3.ValueChangedFcn = createCallbackFcn(app, @InstrumentDropDown_3ValueChanged, true);
            app.InstrumentDropDown_3.Value = {};
            app.InstrumentDropDown_4Label = uilabel(app.GridLayout4);
            app.InstrumentDropDown_4Label.Text = 'Instrument';
            app.InstrumentDropDown_4 = uidropdown(app.GridLayout4);
            app.InstrumentDropDown_4.Items = {};
            app.InstrumentDropDown_4.ValueChangedFcn = createCallbackFcn(app, @InstrumentDropDown_4ValueChanged, true);
            app.InstrumentDropDown_4.Value = {};
            app.CommandEditField_1 = uieditfield(app.GridLayout4, 'text');
            app.CommandEditField_1.Value = 'PrintIdentifier()';
            app.CommandEditField_2Label = uilabel(app.GridLayout4);
            app.CommandEditField_2Label.Text = 'Command';
            app.CommandEditField_2 = uieditfield(app.GridLayout4, 'text');
            app.CommandEditField_2.Value = 'PrintIdentifier()';
            app.CommandEditField_3Label = uilabel(app.GridLayout4);
            app.CommandEditField_3Label.Text = 'Command';
            app.CommandEditField_3 = uieditfield(app.GridLayout4, 'text');
            app.CommandEditField_3.Value = 'PrintIdentifier()';
            app.CommandEditField_4Label = uilabel(app.GridLayout4);
            app.CommandEditField_4Label.Text = 'Command';
            app.CommandEditField_4 = uieditfield(app.GridLayout4, 'text');
            app.CommandEditField_4.Value = 'PrintIdentifier()';
            app.ControlDropDown_1 = uidropdown(app.GridLayout4);
            app.ControlDropDown_1.Items = {};
            app.ControlDropDown_1.ValueChangedFcn = createCallbackFcn(app, @ControlDropDown_1ValueChanged, true);
            app.ControlDropDown_1.Enable = 'off';
            app.ControlDropDown_1.Value = {};
            app.ControlDropDown_2 = uidropdown(app.GridLayout4);
            app.ControlDropDown_2.Items = {};
            app.ControlDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ControlDropDown_2ValueChanged, true);
            app.ControlDropDown_2.Enable = 'off';
            app.ControlDropDown_2.Value = {};
            app.ControlDropDown_3 = uidropdown(app.GridLayout4);
            app.ControlDropDown_3.Items = {};
            app.ControlDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ControlDropDown_3ValueChanged, true);
            app.ControlDropDown_3.Enable = 'off';
            app.ControlDropDown_3.Value = {};
            app.ControlDropDown_4 = uidropdown(app.GridLayout4);
            app.ControlDropDown_4.Items = {};
            app.ControlDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ControlDropDown_4ValueChanged, true);
            app.ControlDropDown_4.Enable = 'off';
            app.ControlDropDown_4.Value = {};
            app.ControlLabel_1 = uilabel(app.GridLayout4);
            app.ControlLabel_1.Enable = 'off';
            app.ControlLabel_1.Text = 'Control';
            app.ControlLabel_2 = uilabel(app.GridLayout4);
            app.ControlLabel_2.Enable = 'off';
            app.ControlLabel_2.Text = 'Control';
            app.ControlLabel_3 = uilabel(app.GridLayout4);
            app.ControlLabel_3.Enable = 'off';
            app.ControlLabel_3.Text = 'Control';
            app.ControlLabel_4 = uilabel(app.GridLayout4);
            app.ControlLabel_4.Enable = 'off';
            app.ControlLabel_4.Text = 'Control';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SequenceEdit_TextArea = uitextarea(app.GridLayout5);
            app.SequenceEdit_TextArea.ValueChangedFcn = createCallbackFcn(app, @SequenceEdit_TextAreaValueChanged, true);
            app.SequenceEdit_TextArea.Value = {'% Sequence text here'};
            app.ViewEditSequenceLabel = uilabel(app.GridLayout5);
            app.ViewEditSequenceLabel.Text = 'View/Edit Sequence';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.SaveButton = uibutton(app.GridLayout8, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RunSequenceButton = uibutton(app.GridLayout8, 'push');
            app.RunSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @RunSequenceButtonPushed, true);
            app.RunSequenceButton.Text = 'Run Sequence';
            app.AbortSequenceButton = uibutton(app.GridLayout8, 'push');
            app.AbortSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @AbortSequenceButtonPushed, true);
            app.AbortSequenceButton.Enable = 'off';
            app.AbortSequenceButton.Text = 'Abort Sequence';
            app.Panel_2 = uipanel(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.SequenceEditor_ComEntry_WaitCommand = Palladium.Sequence.Views.SequenceEditor_ComEntry_WaitCommand(app.GridLayout6);
            app.SequenceEditor_ComEntry_InstrumentCommand = Palladium.Sequence.Views.SequenceEditor_ComEntry_InstrumentCommand(app.GridLayout6);
            app.SequenceEditor_ComEntry_InstrumentCommand.Command_EditField = [];
            app.SequenceEditor_ComEntry_InstrumentCommand.Control_DropDown = [];
            app.SequenceEditor_ComEntry_InstrumentCommand.Control_Label = [];
            app.SequenceEditor_ComEntry_InstrumentCommand.Instrument_DropDown = [];
            app.SequenceEditor_ComEntry_RunSequenceCommand = Palladium.Sequence.Views.SequenceEditor_ComEntry_RunSequenceCommand(app.GridLayout6);
            app.SequenceEditor_ComEntry_DatafileCommand = Palladium.Sequence.Views.SequenceEditor_ComEntry_DatafileCommand(app.GridLayout6);
            % Show the figure after all components are created
            app.PalladiumSequenceEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SequenceEditor_DefaultGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PalladiumSequenceEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PalladiumSequenceEditorUIFigure)
        end
    end
end