classdef PalladiumDAQ_DefaultGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PalladiumUIFigure        matlab.ui.Figure
        GridLayout_Main          matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout_TopMenuStrip  matlab.ui.container.GridLayout
        NewPlotButtonsPanel      Palladium.Components.NewPlotButtonsPanel
        GridLayout6              matlab.ui.container.GridLayout
        HelpButton               matlab.ui.control.Button
        SettingsButton           matlab.ui.control.Button
        SequenceEditorButton     matlab.ui.control.Button
        DataViewerButton         matlab.ui.control.Button
        GridLayout3              matlab.ui.container.GridLayout
        FilePathsPanel           Palladium.Components.FilePathsPanel
        GridLayout4              matlab.ui.container.GridLayout
        StateControlPanel        Palladium.Components.StateControlPanel
        GridLayout2              matlab.ui.container.GridLayout
        StatusPanel              Palladium.Components.StatusPanel
        VersionLabel             matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        SetupTab                 matlab.ui.container.Tab
        GridLayout5              matlab.ui.container.GridLayout
        InstrumentOptionsPanel   Palladium.Components.InstrumentOptionsPanel
        InstrumentBrowserPanel   Palladium.Components.InstrumentBrowserPanel
    end


    properties (Access = private)
        DependentWindows = {}; %Windows such as Plotter windows, BN Displays etc, that we want to close when the main window is closed, so we should keep track of references to them all
        ProgressBar; % reference to any progress bar window that may be displayed
        BigNumDisplays;
        PlottingTabs = {}; 
        PlottingWindows = {}; 
    end

    properties (Access = public)
        Controller; % Logic class that controls all the backend functionality for the GUI
        TimingLoopController;
        PalladiumIconPath; % Path to an image file for showing the Palladium icon in GUIs
    end

    methods (Access = public)
 
        %% AddInstrumentControl
        function cont = AddInstrumentControl(app, instrRef, controlDetailsStruct)
            try
                %Check that the control has not already been added (before
                %creating the new tab..)
                if ~isempty(instrRef.GetRegisteredControlObjectsFromName(controlDetailsStruct.Name))
                    error("AddInstrumentControlError:DuplicateControlName", "%s", "A Control object of name " + controlDetailsStruct.Name + " has already been added to Instrument " + instrRef.Name);
                end

                %Create a new tab
                tabName = instrRef.Name + " - " + controlDetailsStruct.TabName;
                tab = app.CreateInstrumentControlTab(tabName);

                %Pass its reference into the Controller to join everything
                %up
                cont = app.Controller.InstrumentController.AddInstrumentControl(tab, instrRef, controlDetailsStruct);

                %Make the tab scrollable, in case the control is really long
                %and goes off the bottom of the window
                tab.Scrollable = 'on';

                %Update the GUI
                app.InstrumentOptionsPanel.OnControlEnabled(controlDetailsStruct.Name, instrRef);
            catch err
                app.Controller.HandleError("Error adding instrument control " + controlDetailsStruct.Name, err);
            end
        end

        %% AddNewPlotter
        function pltr = AddNewPlotter(app, parent, size)
            %Is this actually referenced by anything?
            arguments
                app;
                parent;
                size = "Medium";
            end

            %Create the plotter
            pltr = app.Controller.AddNewPlotter(parent, Size=size);
        end

        %% AddNewSimplePlotter
        function pltr = AddNewSimplePlotter(app, parent, size)
            %Is this actually referenced by anything?
            arguments
                app;
                parent;
                size = "Medium";
            end

            %Create the plotter
            pltr = app.Controller.AddNewSimplePlotter(parent, size);
        end        

        %% AddNewPlottingTab
        function [listOfPltrs, tab] = AddNewPlottingTab(app, rows, columns)
            tab = app.CreateNewTab("📈 Plotting");
            listOfPltrs = app.CreatePlottersAndAddToParent(tab, rows, columns);

            %For plotting tabs, add a Context Menu which, for now, has one
            %Entry - Close Tab
            cm = uicontextmenu(app.PalladiumUIFigure);
            m1 = uimenu(cm, "Text", "❌ Close Tab");
            m1.MenuSelectedFcn   = Palladium.Core.ErrorGuard.Wrap(@(src, evt)app.RemoveTab(tab), Context = "Error removing tab");
            tab.ContextMenu = cm;

            %Add the tab to the list of plotter tabs
            app.RegisterPlotterTab(tab);

            %Sort the tabs
            app.SortTabs();

            %Force a GUI update
            %Had to add this to solve Issue #61 - Adding an
            %InstrumentControl before any Plotting Tabs, and then allowing
            %the programme to auto-add the first Plotting Tab would make
            %the Plotters in that tab not ever display any data. CopyPlot
            %would show all the data prsent and correct, so it was clearly
            %a GUI update issue, which this solves.
            drawnow();
        end

        %% AddNewPlottingWindow
        function listOfPltrs = AddNewPlottingWindow(app, rows, columns)
            window = uifigure("Name", "📈 Plotting", "Position", [0 0 1000 700], "Icon", app.PalladiumIconPath);
            listOfPltrs = app.CreatePlottersAndAddToParent(window, rows, columns, window);            

            app.RegisterPlotterWindow(window);
            app.RegisterDependentWindow(window);
        end

        %% ApplySettings
        function ApplySettings(app, e)
            %pathSettings = e.PathSettings;
            windowSettings = e.WindowSettings;

            %Apply window settings, like size and position
            if ~isempty(windowSettings.DefaultSize)
                app.PalladiumUIFigure.Position = [app.PalladiumUIFigure.Position(1), app.PalladiumUIFigure.Position(2), windowSettings.DefaultSize(1), windowSettings.DefaultSize(2)];
            end

            %Window position + maximise if set
            if windowSettings.Maximised
                drawnow;
                app.PalladiumUIFigure.WindowState = 'maximized';
            elseif ~isempty(windowSettings.DefaultPosition)
                app.PalladiumUIFigure.Position = [windowSettings.DefaultPosition(1), windowSettings.DefaultPosition(2), app.PalladiumUIFigure.Position(3), app.PalladiumUIFigure.Position(4)];
            else
                %Move the window to the centre of the screen. Should stop
                %it appearing off the top of screens of users with
                %unconventional
                %monitor setups..
                movegui(app.PalladiumUIFigure, 'center');
            end

            %Icon path
            app.PalladiumIconPath = windowSettings.PalladiumIconPath;
        end

        %% AssignControllersAndHookUpEvents
        function AssignControllersAndHookUpEvents(app, controller, timingLoopController, instrumentController)
            app.Controller = controller;
            app.TimingLoopController = timingLoopController;

            app.Controller.RegisterUIFigure(app.PalladiumUIFigure);

            %Subscribe to core Controller events
            Palladium.Core.ErrorGuard.AddListener(controller, "DataRowUpdated", @(s,e)app.UpdateBigNumberDisplays(e), Controller=controller);          
            Palladium.Core.ErrorGuard.AddListener(controller, "FileWriteOptionsChanged", @(s,e)app.OnFileWriteOptionsChanged(e), Controller=controller);       
            Palladium.Core.ErrorGuard.AddListener(controller, "FinalisePreset", @(s,e)app.FinalisePreset(), Controller=controller);               
            Palladium.Core.ErrorGuard.AddListener(controller, "GreenStatus", @(s,e)app.ShowGreenStatus(e), Controller=controller);                 
            Palladium.Core.ErrorGuard.AddListener(controller, "Loaded", @(s,e)app.UnlockInput(), Controller=controller);           
            Palladium.Core.ErrorGuard.AddListener(controller, "RedStatus", @(s,e)app.ShowRedStatus(e), Controller=controller);         
            Palladium.Core.ErrorGuard.AddListener(controller, "SettingsApplied", @(s,e)app.ApplySettings(e), Controller=controller);                            
            Palladium.Core.ErrorGuard.AddListener(controller, "StartedShowingProgress", @(s,e)app.ShowProgressBar(e), Controller=controller);    
            Palladium.Core.ErrorGuard.AddListener(controller, "StoppedShowingProgress", @(s,e)app.CloseProgressBar(), Controller=controller);   
            Palladium.Core.ErrorGuard.AddListener(controller, "UpdatedProgress", @(s,e)app.UpdateProgressBar(e), Controller=controller);              
            Palladium.Core.ErrorGuard.AddListener(controller, "YellowStatus", @(s,e)app.ShowYellowStatus(e), Controller=controller);              
       
            %Subscribe to Timing Loop events
            Palladium.Core.ErrorGuard.AddListener(timingLoopController, "Paused", @(s,e)app.OnPaused(), Controller=controller);
            Palladium.Core.ErrorGuard.AddListener(timingLoopController, "Resumed", @(s,e)app.OnResumed(), Controller=controller);
            Palladium.Core.ErrorGuard.AddListener(timingLoopController, "Started", @(s,e)app.OnStarted(), Controller=controller);
            Palladium.Core.ErrorGuard.AddListener(timingLoopController, "Stopped", @(s,e)app.OnStopped(), Controller=controller);
            Palladium.Core.ErrorGuard.AddListener(timingLoopController, "TargetUpdateTimeChanged", @(s,e)app.OnTargetUpdateTimeChanged(e),Controller=controller);
            Palladium.Core.ErrorGuard.AddListener(timingLoopController, "UpdateTimeChanged", @(s,e)app.OnUpdateTimeChanged(e),Controller=controller);

            %Subscribe to InstrumentController events
            Palladium.Core.ErrorGuard.AddListener(instrumentController, "DefaultEnabledInstrumentControlAdd", @(s,e)app.AddInstrumentControl(e.InstrumentRef, e.ControlDetailsStruct), Controller=controller);
            Palladium.Core.ErrorGuard.AddListener(instrumentController, "InstrumentAdded", @(s,e)app.OnInstrumentAdded(e), Controller=controller);
            Palladium.Core.ErrorGuard.AddListener(instrumentController, "InstrumentRemoved", @(s,e)app.OnInstrumentRemoved(e), Controller=controller);
            Palladium.Core.ErrorGuard.AddListener(instrumentController, "InstrumentListPopulated", @(s,e)app.PopulateInstrumentList(e), Controller=controller);
        end

        %% Close
        function Close(app)
            
            %First, Stop measurements
            try
                if ~isempty(app.Controller)
                    app.Controller.OnFigureClosed();
                end
            catch err
                warning("CloseWarning:ErrorMessage", "%s", err.message);
            end

            %Close all dependent windows
            try
                for i = 1 : length(app.DependentWindows)
                    win = app.DependentWindows(i);
                    if isvalid(win)
                        delete(win);
                    end
                end
            catch err
                warning("CloseWarning:DependentWindowsCloseFailed", "Error closing dependent windows");
                warning("CloseWarning:ErrorMessage", "%s", err.message);
            end

            %Normal Close request function
            delete(app);
        end

        %% CloseProgressBar
        function CloseProgressBar(app)
            %Close any progress bar window, used to
            %show the user when slow operations are going on
            if ~isempty(app.ProgressBar)
                close(app.ProgressBar);
            else
                warning("CloseProgressBarWarning:NoProgressBar", "Tried to close a progress bar but there isn't one to close");
            end

            %Remove reference
            app.ProgressBar = [];
        end

        %% CreateInstrumentControlTab
        function tab = CreateInstrumentControlTab(app, tabName)
            %For now, simple pass through to generic tab creation
            tab = app.CreateNewTab(tabName);
        end

        %% CreateNewTab
        function tab = CreateNewTab(app, title)
            tab = uitab(app.TabGroup, 'Title', title);
            app.SortTabs();
        end
 
        %% DisplayTargetUpdateTime
        function DisplayTargetUpdateTime(app, timeInS)
            if ~isnan(timeInS)
                app.StateControlPanel.TargetUpdateTime_s = timeInS;
            end
        end

        %% DisplayUpdateTime
        function DisplayUpdateTime(app, timeInS)
            if ~isnan(timeInS)
                app.StateControlPanel.UpdateTime_s = timeInS;
            end
        end

        %% FinalisePreset
        function FinalisePreset(app)
            %Called once the preset changes are all applied, to do final
            %housekeeping like refreshing UI
            app.InstrumentOptionsPanel.RefreshControls();
        end

        %% GetPlottingWindowsAndTabs
        function [tabs, windows] = GetPlottingWindowsAndTabs(app)
            tabs = app.PlottingTabs;
            windows = app.PlottingWindows;
        end

        %% GetUIFigureHandle
        function figHandle = GetUIFigureHandle(app)
            figHandle = app.PalladiumUIFigure;
        end

        %% GrabPathData
        function [dir, fileName, description, ext] = GrabPathData(app)
            dir = app.FilePathsPanel.Directory;
            fileName = app.FilePathsPanel.FileName;
            description = app.FilePathsPanel.Description;
            ext = app.FilePathsPanel.FileExtension;
        end

        %% GrabFileWriteModeData
        function [saveFile, fileWriteMode] = GrabFileWriteModeData(app)
            saveFile = app.FilePathsPanel.SaveToFile;
            fileWriteMode = app.FilePathsPanel.FileWriteOption;
        end

        %% OnFileWriteOptionsChanged
        function OnFileWriteOptionsChanged(app, e)
            fileWriteDetails = e.Value;

            %Pass through to the FilePathsPanel, to update its GUI
            app.FilePathsPanel.OnFileWriteOptionsChanged(fileWriteDetails);
        end

        %% OnInstrumentAdded
        function OnInstrumentAdded(app, e)
            instrStringToAdd = e.InstrStringToAdd;
            instance = e.InstrumentRef;
            app.InstrumentBrowserPanel.OnInstrumentAdded(instrStringToAdd, instance);
        end

        %% OnInstrumentRemoved
        function OnInstrumentRemoved(app, e)
            instrumentRef = e.Value;
            app.InstrumentBrowserPanel.OnInstrumentRemoved(instrumentRef);
        end

        %% OnPaused
        function OnPaused(app)
            %Update StateControl Panel to show current state
            app.StateControlPanel.OnPaused();
        end
        
        %% OnResumed
        function OnResumed(app)            
            %Update StateControl Panel to show current state
            app.StateControlPanel.OnResumed();
        end

        %% OnStarted
        function OnStarted(app)

            %If there are no Plotting Tabs, add one
            if(isempty(app.PlottingTabs))
                app.AddNewPlottingTab(2,1);
            end

            %Update StateControl Panel to show current state
            app.StateControlPanel.OnStarted();

            %Disable certain UI elements while measurements are running
            app.InstrumentBrowserPanel.Enabled = false;
            app.InstrumentOptionsPanel.Enabled = false;
            app.FilePathsPanel.LockInput();
            app.InstrumentBrowserPanel.LockInput();
            app.InstrumentOptionsPanel.LockInput();
            app.NewPlotButtonsPanel.OnStart();
        end
        
        
        %% OnStopped
        function OnStopped(app)
            %Update StateControl Panel to show current state
            app.StateControlPanel.OnStopped();

            %Enable certain UI Elements now measurements are not running and we returned to ready status
            app.StateControlPanel.Stop();

            app.FilePathsPanel.UnlockInput();
            app.InstrumentBrowserPanel.UnlockInput();
            app.InstrumentOptionsPanel.UnlockInput();
        end

        %% OnTargetUpdateTimeChanged
        function OnTargetUpdateTimeChanged(app, eventData)
            %Update the GUI to show newly set value
            targetUpdateTime_s = eventData.Value;
            app.DisplayTargetUpdateTime(targetUpdateTime_s);
        end

        %% OnUpdateTimeChanged
        function OnUpdateTimeChanged(app, eventData)
            %Update the GUI to show newly set value
            updateTime_s = eventData.Value;            
            app.DisplayUpdateTime(updateTime_s);
        end

        %% PausePressed
        function PausePressed(app, ~, ~)
            app.Controller.TimingLoopController.Pause();
        end

        %% PopulateInstrumentList
        function PopulateInstrumentList(app, e)
            cellArrayOfInstrumentNameStrings = e.Value;
            app.InstrumentBrowserPanel.PopulateInstrumentList(cellArrayOfInstrumentNameStrings);
        end

        %% RefocusWindow
        function RefocusWindow(app)
            %Bring the window back to the front, after a filedialog or similar
            % Calling uigetfile or similar modal dialogs may send the app to the back.
            % Bring it back to the front. Also loses maximised state etc (argh!). Save current window state and restore it.
            windowState = app.PalladiumUIFigure.WindowState;
            app.PalladiumUIFigure.Visible = 'off';
            app.PalladiumUIFigure.Visible = 'on';
            app.PalladiumUIFigure.WindowState = windowState;
        end
        
        %% RegisterDependentWindow
        function RegisterDependentWindow(app, win)
            app.DependentWindows = [app.DependentWindows win];
        end

        %% RemoveInstrumentControl
        function RemoveInstrumentControl(app, instrRef, controlDetailsStruct)            
            %Update the GUI to reflect changes
            app.RemoveTabOfName(instrRef.Name + " - " + controlDetailsStruct.TabName);
            app.InstrumentOptionsPanel.OnControlDisabled(controlDetailsStruct.Name, instrRef);

            %Pass on to Controller
            app.Controller.InstrumentController.RemoveInstrumentControl(instrRef, controlDetailsStruct);    
        end

        %% RemoveTab
        function RemoveTab(~, tab)
            delete(tab);
        end

        %% RemoveTabOfName
        function RemoveTabOfName(app, tabName)
            %Get all tabs that are children of the TabGroup, delete any
            %whose name matches the tabName input. Be aware this will
            %delete ALL tabs of that name so.. avoid tabs of the same
            %name.. Plotting tabs always are called the same thing..
            for i = length(app.TabGroup.Children) : -1 : 1
                if strcmp(app.TabGroup.Children(i).Title, tabName)
                    delete(app.TabGroup.Children(i));
                end
            end
        end

        %% ResumePressed
        function ResumePressed(app, ~, ~)
            app.Controller.TimingLoopController.Resume();
        end

        %% SelectedInstrumentChanged
        function SelectedInstrumentChanged(app, ~, evnt)
            instRef = evnt.InstrumentRef;
            app.InstrumentOptionsPanel.OpenInstrument(instRef);
        end

        function SetVersionInformation(app, fullversionString, major, minor, build, authorString)
            %Show the latest build version information on the GUI
            arguments
                app;
                fullversionString   {mustBeTextScalar};
                major               (1,1) double {mustBeInteger}; %#ok<INUSA>
                minor               (1,1) double {mustBeInteger}; %#ok<INUSA>
                build               (1,1) double {mustBeInteger}; %#ok<INUSA>
                authorString        {mustBeTextScalar};
            end

            %Update the version label UILabel
            app.VersionLabel.Text = string(fullversionString) + " - " + string(authorString);            
        end

        %% ShowGreenStatus
        function ShowGreenStatus(app, e)
            app.StatusPanel.ShowGreenStatus(e.Message);
        end

        %% ShowYellowStatus
        function ShowYellowStatus(app, e)
            app.StatusPanel.ShowYellowStatus(e.Message);
        end

        %% ShowRedStatus
        function ShowRedStatus(app, e)
            app.StatusPanel.ShowRedStatus(e.Message);
        end

        %% ShowProgressBar
        function ShowProgressBar(app, e)          
            %Create a modal (forces itself to the front of focus and
            %prevents clicking on stuff behind it) progress bar window, to
            %show the user when slow operations are going on
            
            %If we already have one open, close it
            if ~isempty(app.ProgressBar)
                close(app.ProgressBar);
            end

            title = e.Title;
            message = e.Message;

            %Create in new window, blocking input
            app.ProgressBar = uiprogressdlg(app.PalladiumUIFigure, "Title", title, "Message", message,...
                "Interpreter", "none", "Icon", app.PalladiumUIFigure.Icon);

            %Force a GUI update
            drawnow();
        end

        %% ShowSettingsMenu
        function ShowSettingsMenu(app)

            %Create a window and add a grid layout to it
            menuWindow = uifigure("WindowStyle","normal", "Name", "Settings", Icon=app.PalladiumUIFigure.Icon);
            menuWindow.Position = [menuWindow.Position(1) menuWindow.Position(2) 300 400];
            grid = uigridlayout(menuWindow, [3, 3]);

            % set first and second rows to fixed 30 pixels, third row fills remaining space
            grid.RowHeight = {30, 30, '1x'};

            % set column 2 to a fixed width (example 80 px); other columns stretch
            grid.ColumnWidth = {'1x', 80, '1x'};

            %Load Preset Button
            bt1 = uibutton(grid, "FontName", "Georgia", "FontSize", 14, Text= "Load Preset", Tooltip= "Load a Preset file - saves the list and properties of Instruments, as well as Plotter and general settings, for ease of startup.");
            bt1.ButtonPushedFcn  = Palladium.Core.ErrorGuard.Wrap(@(s,e)LoadPreset(app, menuWindow), Context = "Error loading preset");
            bt1.Layout.Row = 1;
            bt1.Layout.Column = 2;

            %Save Preset Button
            bt2 = uibutton(grid, "FontName", "Georgia", "FontSize", 14, Text= "Save Preset", Tooltip= "Save current configuration as a Preset file that can be loaded later to recover this setup (plotting tab settinsg, Instruments and their properties etc).");
            bt2.ButtonPushedFcn  = Palladium.Core.ErrorGuard.Wrap(@(s,e)SavePreset(app, menuWindow), Context = "Error saving preset");
            bt2.Layout.Row = 2;
            bt2.Layout.Column = 2;

            function LoadPreset(app, menuWindow)
                %Close the Settings Menu
                delete(menuWindow);

                if ~isempty(app.Controller)
                    app.Controller.LoadPreset(app);
                else
                    warning("LoadPresetWarning:ControllerNotAssigned", "app.Controller not assigned, cannot LoadPreset");
                end
            end

            function SavePreset(app, menuWindow)
                %Close the Settings Menu                
                delete(menuWindow);

                if ~isempty(app.Controller)
                    app.Controller.SavePreset(app);
                else
                    warning("SavePresetWarning:ControllerNotAssigned", "app.Controller not assigned, cannot SavePreset");
                end
            end
        end

        %% StartPressed
        function StartPressed(app, ~, ~)
            app.Controller.TimingLoopController.Start();
        end

        %% StopPressed
        function StopPressed(app, ~, ~)
            app.Controller.TimingLoopController.Stop();
        end

        %% TargetUpdateTimeChanged
        function TargetUpdateTimeChanged(app, ~, ~)
            %Called by the GUI buttons
            targetUpdateTime_s = app.StateControlPanel.TargetUpdateTime_s;
            app.Controller.TimingLoopController.SetUpdateTime(targetUpdateTime_s);
        end

        %% UnlockInput
        function UnlockInput(app)
            %Let the user interact with the GUI now it is loaded and ready
            app.FilePathsPanel.UnlockInput();
            app.InstrumentBrowserPanel.UnlockInput();
            app.InstrumentOptionsPanel.UnlockInput();
            app.StateControlPanel.UnlockInput();
        end

        %% UpdateProgressBar
        function UpdateProgressBar(app, e)
            
            %Update a previously-created progress bar window, used to
            %show the user when slow operations are going on
            if isempty(app.ProgressBar)
                warning("UpdateProgressBarWarning:NoProgressBar", "Tried to updated a progress bar but there isn't one to update. Run ShowProgressBar first.");
                return;
            end

            %Update
            app.ProgressBar.Value = e.Progress;
            app.ProgressBar.Message = e.Message;
            drawnow();
        end        
            
    end

    methods (Access = private)

        %% CreatePlottersAndAddToParent
        function listOfPltrs = CreatePlottersAndAddToParent(app, parent, numRows, numColumns, parentFigure)
            arguments
                app;
                parent;
                numRows (1,1) {mustBeInteger};
                numColumns (1,1) {mustBeInteger};
                parentFigure = [];
            end

            grid = uigridlayout(parent, [numRows, numColumns], "ColumnSpacing", 2, "RowSpacing", 2, "Padding", [2 2 2 2]);

            if(numRows*numColumns == 1)
                size = "Large";
            elseif(numRows*numColumns == 2)
                size = "Medium";
            else
                size = "Small";
            end

            listOfPltrs = [];
            for i = 1 : numRows
                for j = 1 : numColumns
                    pltr = app.Controller.AddNewPlotter(grid, parentFigure, Size=size, RegisterPlotter=true);
                    listOfPltrs = [listOfPltrs, pltr]; %#ok<AGROW>
                end
            end
        end

        %% SelectedInstrumentControlEnabled
        function SelectedInstrumentControlEnabled(app, ~, eventData)         
            controlDetailStruct = eventData.Value;
            instrRef = controlDetailStruct.instrRef;

            %Pass on
            app.AddInstrumentControl(instrRef, controlDetailStruct);
        end

        %% SelectedInstrumentControlDisabled
        function SelectedInstrumentControlDisabled(app, ~, eventData)  
            controlDetailStruct = eventData.Value;
            instrRef = controlDetailStruct.instrRef;

            %Pass on
            app.RemoveInstrumentControl(instrRef, controlDetailStruct); 
        end


        %% InstrumentAdded
        function InstrumentAdded(app, ~, eventData)            
            instString = string(eventData.Value);
            app.Controller.InstrumentController.AddInstrument(instString);
        end

        %% InstrumentRemoved
        function InstrumentRemoved(app, ~, eventData)        
            instRef = eventData.Value;

            %Remove any Instrument Controls (and associated GUI elements)
            %too
            [~, controlDetailsStructsList] = instRef.GetRegisteredControlObjects();         
            for i = 1 : length(controlDetailsStructsList)
                app.RemoveInstrumentControl(instRef, controlDetailsStructsList(i));
            end

            app.Controller.InstrumentController.RemoveInstrument(instRef);
        end
        
        %% NewBigNumberDisplayPressed
        function NewBigNumberDisplayPressed(app, ~, ~)
          
            iconPath = app.Controller.ApplicationDir + "\+Palladium\+Components\Graphics\BigNumDisplayIcon.png";

            %Create a dialogue box askign which value we want to display
            headers = app.Controller.Headers;

            %Quit here if there are no values to choose from yet - i.e. if
            %the programme is not yet running.
            if isempty(headers)
                return;
            end

            try
                [indx, tf] = listdlg('Name', 'Select value', 'PromptString', 'Select value to be displayed in Big Number Window.', 'SelectionMode', 'single', 'ListString', headers, 'ListSize', [250,350]);

                if(tf)
                    valHeader = string(headers(indx));
                    unitsStr = string(headers(indx));

                    f= uifigure("Icon", iconPath);
                    g = uigridlayout(f, [1,1]);
                    b = Palladium.Components.BigNumberDisplay(g);

                    b.IndexToShow = indx;
                    b.SetTitle(valHeader);
                    b.SetUnits(unitsStr);

                    app.BigNumDisplays = [app.BigNumDisplays b];
                    app.RegisterDependentWindow(f);

                    %Prevent the display being sent to the back of the tab
                    %stack and everything minimising..
                    %this.RefocusWindow();
                    f.Visible = 'off';
                    f.Visible = 'on';
                end
            catch err
                app.Controller.HandleError("Error creating big number display", err);
            end
        end

        %% NewPlotTabPressed
        function NewPlotTabPressed(app, ~, eventData)
            app.AddNewPlottingTab(eventData.Rows, eventData.Cols);
        end

        %% NewPlotWindowPressed
        function NewPlotWindowPressed(app, ~, eventData)
            app.AddNewPlottingWindow(eventData.Rows, eventData.Cols);
        end

        %% DescriptionChanged
        function DescriptionChanged(app, ~, eventData)
            desc = string(eventData.Value);
            app.Controller.SetFilePathsDescription(desc);
        end

        %% DirectoryChanged
        function DirectoryChanged(app, ~, eventData)
            dr = string(eventData.Value);
            app.Controller.SetFilePathsDirectory(dr);
            app.RefocusWindow();
        end 
        
        %% FileNameChanged
        function FileNameChanged(app, ~, eventData)
            fn = string(eventData.Value);
            app.Controller.SetFilePathsFileName(fn);
        end 
        
        %% FileWriteModeChanged
        function FileWriteModeChanged(app, ~, eventData)
            wm = string(eventData.Value);
            app.Controller.SetFilePathsWriteMode(wm);
        end 
        
        %% RegisterPlotterTab
        function RegisterPlotterTab(app, tab)
            try
                %Add the tabs to the list of tracked plotter tabs. Just for
                %cleaning up later and to monitor if we have at least one
                %on programme Run
                if(isempty(app.PlottingTabs))
                    app.PlottingTabs = {tab};
                else
                    app.PlottingTabs = [app.PlottingTabs, {tab}];
                end

            catch err
                app.HandleError("Error registering plotter tab", err);
            end
        end

        %% RegisterPlotterWindow
        function RegisterPlotterWindow(app, window)
            try
                %Add the window to the list of tracked plotter windows. Just for
                %cleaning up later, saving presets
                if(isempty(app.PlottingWindows))
                    app.PlottingWindows = {window};
                else
                    app.PlottingWindows = [app.PlottingWindows, {window}];
                end

            catch err
                app.HandleError("Error registering plotter window", err);
            end
        end

        %% SaveFileBoolChanged
        function SaveFileBoolChanged(app, ~, eventData)
            saveFile = eventData.Value;
            app.Controller.SetFilePathsSaveFileBool(saveFile);
        end

        %% SortTabs
        function SortTabs(app)
            %Cancel if there are no tabs to sort
            if (isempty(app.TabGroup.Children) || length(app.TabGroup.Children) < 2)
                return;
            end

            %Sort the order the GUI tabs are displayed in - namely so that Plotting
            %tabs come after everything else
            tabs = app.TabGroup.Children(2:end);    %Grab all but the first tab (the Settings tab, which always wants to be first)

            %Seperate the sheep and the goats - plotter tabs and
            %non-plotter tabs, then stick em back together again,
            %non-plotter first then plotters. This will preserve the
            %current order of each, and we can extend this by sorting the
            %sub-types later if desired
            plotter_tabs = [];
            other_tabs = [];
            for i = 1 : length(tabs)
                if(tabs(i).Title == "📈 Plotting")
                    plotter_tabs = [plotter_tabs; tabs(i)]; %#ok<AGROW>
                else
                    other_tabs = [other_tabs; tabs(i)]; %#ok<AGROW>
                end
            end

            modified_tabs = [app.TabGroup.Children(1); other_tabs; plotter_tabs];   %Put the Settings tab back on the front, then recombine all tabs
            app.TabGroup.Children = modified_tabs;
        end

        %% SubscribeToEvents
        function SubscribeToEvents(app)
            %InstrumentBrowserPanel
            Palladium.Core.ErrorGuard.AddListener(app.InstrumentBrowserPanel, 'SelectionChange', @(src,evnt)app.SelectedInstrumentChanged(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.InstrumentBrowserPanel, 'InstrumentAdded', @(src,evnt)app.InstrumentAdded(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.InstrumentBrowserPanel, 'InstrumentRemoved', @(src,evnt)app.InstrumentRemoved(src,evnt));

            %InstrumentBrowserPanel
            Palladium.Core.ErrorGuard.AddListener(app.InstrumentOptionsPanel, 'InstrumentControlEnabled', @(src,evnt)app.SelectedInstrumentControlEnabled(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.InstrumentOptionsPanel, 'InstrumentControlDisabled', @(src,evnt)app.SelectedInstrumentControlDisabled(src,evnt));

            %StateControlPanel
            Palladium.Core.ErrorGuard.AddListener(app.StateControlPanel, 'Started', @(src,evnt)app.StartPressed(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.StateControlPanel, 'Paused', @(src,evnt)app.PausePressed(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.StateControlPanel, 'Resumed', @(src,evnt)app.ResumePressed(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.StateControlPanel, 'Stopped', @(src,evnt)app.StopPressed(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.StateControlPanel, 'TargetUpdateTimeChanged', @(src,evnt)app.TargetUpdateTimeChanged(src,evnt));

            %NewPlotButtonsPanel
            Palladium.Core.ErrorGuard.AddListener(app.NewPlotButtonsPanel, 'NewWindowButtonClicked', @(src,evnt)app.NewPlotWindowPressed(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.NewPlotButtonsPanel, 'NewTabButtonClicked', @(src,evnt)app.NewPlotTabPressed(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.NewPlotButtonsPanel, 'NewBigNumberDisplayClicked', @(src,evnt)app.NewBigNumberDisplayPressed(src,evnt));

            %FilePathsPanel
            Palladium.Core.ErrorGuard.AddListener(app.FilePathsPanel, 'DescriptionChanged', @(src,evnt)app.DescriptionChanged(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.FilePathsPanel, 'DirectoryChanged', @(src,evnt)app.DirectoryChanged(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.FilePathsPanel, 'FileNameChanged', @(src,evnt)app.FileNameChanged(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.FilePathsPanel, 'FileWriteModeChanged', @(src,evnt)app.FileWriteModeChanged(src,evnt));
            Palladium.Core.ErrorGuard.AddListener(app.FilePathsPanel, 'SaveFileBoolChanged', @(src,evnt)app.SaveFileBoolChanged(src,evnt));
        end

        
        %% UpdateBigNumberDisplays
        function UpdateBigNumberDisplays(app, e)

            newDataRow = e.DataRow;

            if isempty(app.BigNumDisplays)
                return;
            end

            %Check for any displays that may have been closed by a
            %discourteous user - remove them from the list of displays to
            %update if so.
            for i = length(app.BigNumDisplays) : - 1 : 1
                if(~isvalid(app.BigNumDisplays(i)))
                    app.BigNumDisplays(i) = [];
                end
            end

            for i = 1 : length(app.BigNumDisplays)
                bnd = app.BigNumDisplays(i);

                bnd.UpdateValue(newDataRow);
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

            %Subscribe to events
            app.SubscribeToEvents();

        end

        % Close request function: PalladiumUIFigure
        function PalladiumUIFigureCloseRequest(app, event)
            app.Close();
        end

        % Button pushed function: DataViewerButton
        function DataViewerButtonPushed(app, event)
            try
                app.Controller.OpenDataViewer();
            catch err
                 Palladium.Core.ErrorGuard.HandleError("Error in DataViewerButtonPushed", err);
            end
        end

        % Button pushed function: SequenceEditorButton
        function SequenceEditorButtonPushed(app, event)
            try
                app.Controller.OpenSequenceEditor();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in SequenceEditorButtonPushed", err);
            end
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            try
                app.ShowSettingsMenu();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in SettingsButtonPushed", err);
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            try
                app.Controller.OpenHelp();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in HelpButtonPushed", err);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PalladiumUIFigure = uifigure('Visible', 'off');
            app.PalladiumUIFigure.Name = 'Palladium Data Acquisition';
            app.PalladiumUIFigure.Icon = fullfile(pathToMLAPP, '..', '', '+Components', 'Graphics', 'PalladiumDAQIcon.png');
            app.PalladiumUIFigure.CloseRequestFcn = createCallbackFcn(app, @PalladiumUIFigureCloseRequest, true);
            app.GridLayout_Main = uigridlayout(app.PalladiumUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout_Main);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = '⚙️ Setup';
            app.GridLayout5 = uigridlayout(app.SetupTab);
            app.InstrumentBrowserPanel = Palladium.Components.InstrumentBrowserPanel(app.GridLayout5);
            app.InstrumentOptionsPanel = Palladium.Components.InstrumentOptionsPanel(app.GridLayout5);
            app.GridLayout2 = uigridlayout(app.GridLayout_Main);
            app.VersionLabel = uilabel(app.GridLayout2);
            app.VersionLabel.Enable = 'off';
            app.VersionLabel.Text = 'Palladium DAQ Version x.x.x - M.J.Coak, University of Birmingham';
            app.StatusPanel = Palladium.Components.StatusPanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.GridLayout_Main);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.StateControlPanel = Palladium.Components.StateControlPanel(app.GridLayout4);
            app.FilePathsPanel = Palladium.Components.FilePathsPanel(app.GridLayout3);
            app.Panel = uipanel(app.GridLayout_Main);
            app.GridLayout_TopMenuStrip = uigridlayout(app.Panel);
            app.GridLayout6 = uigridlayout(app.GridLayout_TopMenuStrip);
            app.DataViewerButton = uibutton(app.GridLayout6, 'push');
            app.DataViewerButton.ButtonPushedFcn = createCallbackFcn(app, @DataViewerButtonPushed, true);
            app.DataViewerButton.Text = '🔍 Data Viewer';
            app.SequenceEditorButton = uibutton(app.GridLayout6, 'push');
            app.SequenceEditorButton.ButtonPushedFcn = createCallbackFcn(app, @SequenceEditorButtonPushed, true);
            app.SequenceEditorButton.Text = '📋 Sequence Editor';
            app.SettingsButton = uibutton(app.GridLayout6, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Tooltip = {'Show Settings Menu'};
            app.SettingsButton.Text = '⚙️ Settings';
            app.HelpButton = uibutton(app.GridLayout6, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '❔Help';
            app.NewPlotButtonsPanel = Palladium.Components.NewPlotButtonsPanel(app.GridLayout_TopMenuStrip);
            % Show the figure after all components are created
            app.PalladiumUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PalladiumDAQ_DefaultGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PalladiumUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PalladiumUIFigure)
        end
    end
end