classdef DataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataViewerUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        PlotterPanel        Palladium.Components.PlotterPanel
        Panel               matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
        RefreshButton       matlab.ui.control.Button
        DirectoryLabel      matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        BrowseButton        matlab.ui.control.Button
        Tree                matlab.ui.container.CheckBoxTree
    end


    properties (Access = private)
        DefaultDir; % Description
        SelectedDir;
        FileExtension; % Description
        DataReader;
        DataWriter;
        DisplayNames = [];
        LoadedData = [];
        SingleLoadedFileName = [];
        FileNodes; % Nodes in the tree that correspond to files, not folders, to make it simpler to loop over them

        %UI themes/styles
        Style_DefaultNode = uistyle("FontColor", [0 0 0]);
        Style_IncompatibleNode = uistyle("FontColor", [0.65 0.65 0.65]);
        Style_NoHeadersNode = uistyle("Icon","error","IconAlignment","leftmargin", "FontColor", [0.5 0.3 0.3]);
    end

    methods (Access = private)
       
        %% AddNodesForFolderContents
        function folderNodesToPopulate = AddNodesForFolderContents(app, rootNode, folderPath)
            folderNodesToPopulate = [];

            %Get contents of directory
            dirContents = dir(string(folderPath));

            %Add folders first
            for i = 1 : length(dirContents)
                item = dirContents(i);
                if item.isdir
                    if ~contains(item.name, '.')    %Exclude the '.' and '..' results one always gets
                        nodeStr = "📂 " + item.name;
                        foldrNode = uitreenode(rootNode, "Text", nodeStr, "UserData", item);
                        folderNodesToPopulate = [folderNodesToPopulate foldrNode]; %#ok<AGROW>
                    end
                end
            end

            %Then add files
            namesOfNodesToAdd = {};
            items = {};
            for i = 1 : length(dirContents)
                item = dirContents(i);
                if ~item.isdir
                    [~, ~, ext] = fileparts(item.name);
                    if ext == app.FileExtension
                        nodeStr = item.name;
                        items = [items, item]; %#ok<AGROW>
                        namesOfNodesToAdd = [namesOfNodesToAdd, nodeStr]; %#ok<AGROW>
                    end
                end
            end

            %Put this extra step in in case we want to sort or sift the
            %nodes before adding..
            for i = 1 : length(namesOfNodesToAdd)
                nodeStr = namesOfNodesToAdd{i};
                itemUserData = items{i};

                %Read the file headers of the datafile and store in the
                %node's UserData
                path = app.GetFilePathFromUserData(itemUserData);
                try
                    itemUserData.FileHeaders = app.DataReader.ReadHeadersFromFile(path);
                catch err
                    itemUserData.FileHeaders = [];
                    Palladium.Logging.Logger.Log("Warning", "Could not find headers in data file " + path + ". File will not be openable");
                end

                %Create the node, with user data pointing to its path, name, headers etc
                node = uitreenode(rootNode, "Text", nodeStr, "UserData", itemUserData);
                app.FileNodes = [app.FileNodes, node];

                %If the node points to an invalid file - one with no
                %headers - give it an Error icon and grey out the text
                if isempty(itemUserData.FileHeaders)
                    addStyle(app.Tree, app.Style_NoHeadersNode, "node",node);
                end
            end
        end

        %% CheckMatchingHeaders
        function headersOK = CheckMatchingHeaders(~, refHeaders, headers)
           
            if length(refHeaders) ~= length(headers)
                headersOK = false;
                return;
            end

            if refHeaders == headers
                headersOK = true;
            else
                headersOK = false;
            end
        end

        %% ClearData
        function ClearData(app)
            app.LoadedData = [];
            app.PlotterPanel.ResetPlotter();
        end

        %% ClearTree
        function ClearTree(app)
            nodes = app.Tree.Children;
            nodes.delete;
            app.FileNodes = [];
        end

        %% GetFilePathFromUserData
        function path = GetFilePathFromUserData(~, userData)
            path = string(fullfile(userData.folder, userData.name));
        end

        %% LoadDataArray
        function [headers, array] = LoadDataArray(app, path)
            [~, headers, array] = app.DataReader.ReadFile(path);
        end

        %% NodeCheckboxesChange
        function NodeCheckboxesChange(app, ~, event)
            % Function for the CheckedNodesChangedFcn  callback
            nodes = event.LeafCheckedNodes;

            %Remove any directory nodes from the list
            for i = length(nodes) : -1 : 1
                if contains(nodes(i).Text, "📂")
                    nodes(i) = [];
                end
            end

            app.OnCheckedNodesChanged(nodes);
        end

        %% OnAxesSelectionChange
        function OnAxesSelectionChange(app)
            %Called by the PlotterPanel's events - tell it to update its
            %plots (PlotterPanel does not store data, we need to store it
            %here and send it in again
            app.UpdatePlot();
        end

        %% OnCheckedNodesChanged
        function OnCheckedNodesChanged(app, checkedNodes)
            app.DisplayNames = [];
            pathsToLoad = strings(0);

            if isempty(checkedNodes)
                app.ClearData;
                app.ResetNodeColouring();
            else
                for i = 1 : length(checkedNodes)
                    %Check that this node has headers at all, only add if
                    %it does
                    userData = checkedNodes(i).UserData;
                    if ~isempty(userData.FileHeaders)
                        %Grab the display names (file names from the nodes) for the
                        %multiplot
                        [~, fileName, ~] = fileparts(userData.name);

                        %This i==1 check is needed because otherwise assigning
                        %foo(1) = "String" gives.. nan for some reason?
                        if i == 1
                            app.DisplayNames = string(fileName);
                        else
                            app.DisplayNames(i) = string(fileName);
                        end

                        if i == 1
                            app.SetCompatibleNodeColouring(userData);
                        end

                        %Construct the individual paths to load
                        pathsToLoad = [pathsToLoad, app.GetFilePathFromUserData(userData)]; %#ok<AGROW> - we can't know the length ahead of time as we check each for being valid
                    end
                end

                %Load in the data
                if ~isempty(pathsToLoad)
                    app.UpdateLoadedData(pathsToLoad);
                end
            end
         
        end

        %% OnDirectorySelected
        function OnDirectorySelected(app)
            app.TextArea.Value = app.SelectedDir;
            app.UpdateTree();
        end

        %% OnSingleNodeSelected
        function OnSingleNodeSelected(app, node)
            %If we just click a node (which I found to be the automatic UX
            %reflex), rather than checking boxes, ignore the checked nodes
            %and just plot this one new node - kind of like previewing the
            %node, but keep the checks as they are. A further change to
            %checked states (e.g. checking this one we just clicked if we
            %like it) will get us back to displaying all the checked data.
            if ~isempty(node.UserData.FileHeaders)
                pathToLoad = app.GetFilePathFromUserData(node.UserData);
                app.UpdateLoadedData(pathToLoad);
            end
        end

        %% NodeSelectionChange
        function NodeSelectionChange(app, ~, event)
            % Function for the SelectionChangedFcn callback
            nodes = event.SelectedNodes;
            node = nodes(1);    %Multiselect is off so in fact only one node can ever be selected here
            app.OnSingleNodeSelected(node);
        end

        %% ResetNodeColouring
        function ResetNodeColouring(app)
            if isempty(app.FileNodes)
                return;
            end

            %It is very slow to do addStyle to individual nodes - do the
            %default to the whole tree, then tweak some nodes. also, note
            %that added styles just.. build up over time (!), so if we don't call
            %remove occasionally the performance gets very slow indeed
            removeStyle(app.Tree);

            %Scan over all the nodes, for specific checks
            for i = 1 : length(app.FileNodes)
                if isempty(app.FileNodes(i).UserData.FileHeaders)  %Exclude any nodes that are to invalid/empty files, they are already styled with error icon etc
                    addStyle(app.Tree, app.Style_NoHeadersNode, "node", app.FileNodes(i));
                end
            end
        end

        %% SavePlot
        function SavePlot(app, ~, eventData)
            fig = eventData.Figure;
            fig.Visible = 'off';
            
            %If we are looking at a single file, then default to naming
            %this saved figure that same file name. If multiple, no default
            %is obvious so use a default string name
            if isempty(app.SingleLoadedFileName)
                fileName = "Data Viewer Figure";
            else
                fileName = string(app.SingleLoadedFileName);
            end            

            %No obvious way to autoname figures here - they can be
            %combinations of multiple files. Show a dialogue box to name
            %it.
            [fileName, path] = uiputfile("*.fig", "Save figure as", fullfile(app.SelectedDir, fileName + ".fig"));

            if isequal(fileName,0) || isequal(path,0)
                %User clicked Cancel
                delete(fig);
                return;
            end

            %Strip extra file extension
            [~, fileNameNoExt, ~] = fileparts(fileName);

            %Save the figure (.fig and .png), with exactly the name chosen
            app.DataWriter.SaveFigure(fig, eventData.Axes, path, fileNameNoExt, ExactFileName=true);
        end

        %% SeizeFocus
        function SeizeFocus(app)
            % Calling uigetfile or similar modal dialogs may send the app to the back.
            % Bring it back to the front, preserving maximised, minimised etc.
            windowState = app.DataViewerUIFigure.WindowState;
            app.DataViewerUIFigure.Visible = 'off';
            app.DataViewerUIFigure.Visible = 'on';
            app.DataViewerUIFigure.WindowState = windowState;
        end

        %% SetCompatibleNodeColouring
        function SetCompatibleNodeColouring(app, userData)
            %userData is of the node to compare to - set any node with
            %different headers to that one to grey to show this
            if isempty(app.FileNodes)
                return;
            end

        %It is very slow to do addStyle to individual nodes - do the
            %default to the whole tree, then tweak some nodes. also, note
            %that added styles just.. build up over time (!), so if we don't call
            %remove occasionally the performance gets very slow indeed
            removeStyle(app.Tree);

            for i = 1 : length(app.FileNodes)
                if isempty(app.FileNodes(i).UserData.FileHeaders)  
                    %Any nodes that are to invalid/empty files, - give error icon etc
                    addStyle(app.Tree, app.Style_NoHeadersNode, "node", app.FileNodes(i));
                else
                    %Check if the headers in each file are compatible with
                    %those already loaded - if not, grey out the node
                    if ~app.CheckMatchingHeaders(userData.FileHeaders, app.FileNodes(i).UserData.FileHeaders)
                        addStyle(app.Tree, app.Style_IncompatibleNode, "node", app.FileNodes(i));
                    end
                end
            end
        end

        %% UpdateLoadedData
        function UpdateLoadedData(app, pathsToLoad)
            %First, clear existing data for a clean slate
            app.LoadedData = [];

            %A check - if the paths are empty somehow, just clear and
            %return
            if isempty(pathsToLoad)
                app.ClearData();
                return;
            end

            for i = 1 : length(pathsToLoad)
                %Load the next file
                [headers, dataArray] = app.LoadDataArray(pathsToLoad(i));

                if i == 1   %If this is the first file, its headers are ok - we will compare subsequent ones to this.
                    mergedHeaders = headers; 
                    
                    if isempty(dataArray)
                            %Handle case of a file with 0 data lines (it
                            %happens - if instrument comms crashed on first
                            %loop iteration
                            Palladium.Utilities.GUIUtils.ShowWarning(app, "Data file " + string(pathsToLoad(i)) + " is empty.", "Empty data file");
                    else
                        app.LoadedData{1} = dataArray;
                    end
                else
                    %If the latest header row isn't the same as the
                    %first-loaded one, we cannot overplot these data (for
                    %now at least!) Stop loading in files (break) and show
                    %a warning dialogue box
                    headersOK = app.CheckMatchingHeaders(mergedHeaders, headers);
                    if ~headersOK
                        Palladium.Utilities.GUIUtils.ShowWarning(app, "Headers of data columns differ between files, cannot overplot.", "Headers do not match");
                        break;
                    else
                        if isempty(dataArray)
                            %Handle case of a file with 0 data lines (it
                            %happens - if instrument comms crashed on first
                            %loop iteration
                            Palladium.Utilities.GUIUtils.ShowWarning(app, "Data file " + string(pathsToLoad(i)) + " is empty.", "Empty data file");
                        else
                            %So only load data arrays of finite length.
                            app.LoadedData = [app.LoadedData, dataArray];   %Append, don't set ith value, as we may have skipped values of i if those data were empty
                        end
                    end
                end
            end

            %Tell the PlotterPanel what the data column headers are, to
            %populate its selection boxes
            app.PlotterPanel.UpdateVariables(headers);

            if isscalar(pathsToLoad)
                [~, fileNameNoExt, ~] = fileparts(pathsToLoad(1));
                app.SingleLoadedFileName = fileNameNoExt;
            else
                app.SingleLoadedFileName = [];
            end

        end

        %% UpdatePlot
        function UpdatePlot(app)
            %Clear existing data first, to prevent any messy interactions
            %between single and multi-plotting
            app.PlotterPanel.ClearData();

            %Return if there is no data to plot
            if isempty(app.LoadedData)
                return;
            end

            if isscalar(app.LoadedData)
                app.PlotterPanel.PlotData(app.LoadedData{1});
            else
                app.PlotterPanel.PlotMultipleData(app.LoadedData, app.DisplayNames);
            end
        end
        
        %% UpdateTree
        function UpdateTree(app)
            app.ClearTree();

            [~, dirName, ~] = fileparts(app.SelectedDir);

            %Add single parent node showing the directory we are in
            nodeStr = "📂 " + dirName;
            rootNode = uitreenode(app.Tree, "Text", nodeStr, "UserData", []);

            %Add folders and files in the starting root directory
            folderNodesToPopulate = app.AddNodesForFolderContents(rootNode, app.SelectedDir);

            %If there were folders in there, look inside them, and keep
            %looking in subfolders until there are no more folders left in
            %the heirachy
            while ~isempty(folderNodesToPopulate)

                %Pre-allocate for speed
                folderPathsToCheck = cell(length(folderNodesToPopulate), 1);
                folderNodes = cell(length(folderNodesToPopulate), 1);

                for i = 1 : length(folderNodesToPopulate)
                    path = fullfile(folderNodesToPopulate(i).UserData.folder, folderNodesToPopulate(i).UserData.name);
                    folderPathsToCheck{i} = path;
                    folderNodes{i} = folderNodesToPopulate(i);
                end

                folderNodesToPopulate = [];

                for i = 1 : length(folderNodes)
                    %Add folders and files in this directory being checked
                    folderNodesToPopulateInThisFolder = app.AddNodesForFolderContents(folderNodes{i}, folderPathsToCheck(i));
                    folderNodesToPopulate = [folderNodesToPopulate, folderNodesToPopulateInThisFolder]; %#ok<AGROW>
                end
            end

            % Expand the tree
            expand(app.Tree);

            %Set callback for selection and checkboxes change events
            app.Tree.SelectionChangedFcn      = Palladium.Core.ErrorGuard.Wrap(@app.NodeSelectionChange,   Context = "Error in NodeSelectionChange",    Standalone = true, Figure = app.DataViewerUIFigure);
            app.Tree.CheckedNodesChangedFcn   = Palladium.Core.ErrorGuard.Wrap(@app.NodeCheckboxesChange,  Context = "Error in NodeCheckboxesChange",   Standalone = true, Figure = app.DataViewerUIFigure);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Settings)
            arguments
                app;
                Settings.DefaultDir (1,1) string = "";
                Settings.FileExtensions = ".dat";
            end

            app.DefaultDir = Settings.DefaultDir;
            app.FileExtension = Settings.FileExtensions;

            %Construct a DataReader object to handle the nuts and bolts of
            %reading files (designed like this so we can easily extend to
            %different file encoding types later)
            app.DataReader = Palladium.DataWriting.DataReader();

            %Start in the default directory
            app.SelectedDir = app.DefaultDir;
            app.OnDirectorySelected;

            %Construct a DataWriter object - for saving figures
             %Assign into private property struct FileWriteDetails
            fileWriteDetails.Directory = Settings.DefaultDir;
            fileWriteDetails.FileName = "File Name";
            fileWriteDetails.DescriptionText = "Description";
            fileWriteDetails.FileExtension = Settings.FileExtensions(1);
            fileWriteDetails.SaveFile = true;
            fileWriteDetails.WriteMode = "Append To File";
            app.DataWriter = Palladium.DataWriting.DataWriter(fileWriteDetails);

            %Subscribe to PlotterPanel events
            app.PlotterPanel.AxesSelectionChangeFcn = Palladium.Core.ErrorGuard.Wrap(@(src, event)app.OnAxesSelectionChange(), Context = "Error in OnAxesSelectionChange", Standalone = true, Figure = app.DataViewerUIFigure);
            Palladium.Core.ErrorGuard.AddListener(app.PlotterPanel, 'SavePlot', @(src,evnt)app.SavePlot(src,evnt), Standalone = true, Figure = app.DataViewerUIFigure);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            try
                %Show dialogue to select new directory
                [dir] = uigetdir(app.DefaultDir, "📂 Select Directory");
                app.DataWriter.FileWriteDetails.Directory = dir;

                %Bring this GUI back to the front - Matlab has an annoying
                %habit of hiding it behind other windows when closing a modal
                %dialogue box
                app.SeizeFocus();

                %Handle case of cancelled dialogue box
                if dir == 0
                    return;
                end

                %Set the selected directory to the chosen one
                app.SelectedDir = dir;

                %Update the GUI
                app.OnDirectorySelected();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in BrowseButtonPushed", err, Standalone=true, Figure = app.DataViewerUIFigure);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            try
                %Update the GUI
                app.OnDirectorySelected();
            catch err
                Palladium.Core.ErrorGuard.HandleError("Error in RefreshButtonPushed", err, Standalone=true, Figure = app.DataViewerUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataViewerUIFigure = uifigure('Visible', 'off');
            app.DataViewerUIFigure.Name = 'Data Viewer';
            app.DataViewerUIFigure.Icon = fullfile(pathToMLAPP, '+Palladium', '', '+Components', 'Graphics', 'PalladiumDAQIcon.png');
            app.GridLayout = uigridlayout(app.DataViewerUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BrowseButton = uibutton(app.GridLayout3, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = '📂 Browse';
            app.TextArea = uitextarea(app.GridLayout3);
            app.DirectoryLabel = uilabel(app.GridLayout3);
            app.DirectoryLabel.Text = 'Directory';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = '🔁 Refresh';
            app.PlotterPanel = Palladium.Components.PlotterPanel(app.GridLayout);
            % Show the figure after all components are created
            app.DataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataViewerUIFigure)
        end
    end
end